classdef GAITANV3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        LOADFILESTab_2              matlab.ui.container.Tab
        GridLayout32_2              matlab.ui.container.GridLayout
        LOADFILESButton             matlab.ui.control.StateButton
        Lamp_4                      matlab.ui.control.Lamp
        Lamp_5                      matlab.ui.control.Lamp
        Lamp_6                      matlab.ui.control.Lamp
        RESETALLButton_2            matlab.ui.control.Button
        VICONTab                    matlab.ui.container.Tab
        GridLayout                  matlab.ui.container.GridLayout
        MATLABALGORITHMLabel        matlab.ui.control.Label
        LEGEND1                     matlab.ui.control.Label
        VICONNEXUSLabel             matlab.ui.control.Label
        HOME2                       matlab.ui.control.UIAxes
        HOME3                       matlab.ui.control.UIAxes
        HOME4                       matlab.ui.control.UIAxes
        HOME5                       matlab.ui.control.UIAxes
        HOME6                       matlab.ui.control.UIAxes
        HOME1                       matlab.ui.control.UIAxes
        BUGSTab                     matlab.ui.container.Tab
        GridLayout42                matlab.ui.container.GridLayout
        GridLayout3                 matlab.ui.container.GridLayout
        BUGSLEFT                    matlab.ui.control.UIAxes
        BUGSRIGHT                   matlab.ui.control.UIAxes
        BUGST                       matlab.ui.control.UIAxes
        BUGSH                       matlab.ui.control.UIAxes
        PARAMETERSTab               matlab.ui.container.Tab
        PARAM                       matlab.ui.container.GridLayout
        TA                          matlab.ui.control.Table
        PAR1                        matlab.ui.control.UIAxes
        PAR2                        matlab.ui.control.UIAxes
        PAR3                        matlab.ui.control.UIAxes
        PAR4                        matlab.ui.control.UIAxes
        PAR5                        matlab.ui.control.UIAxes
        PAR6                        matlab.ui.control.UIAxes
        ACCVICONTab                 matlab.ui.container.Tab
        GridLayout2                 matlab.ui.container.GridLayout
        TabGroup2                   matlab.ui.container.TabGroup
        ACCELERATIONTab             matlab.ui.container.Tab
        GridLayout18                matlab.ui.container.GridLayout
        TabGroup5                   matlab.ui.container.TabGroup
        LINEALACCELERATIONTab       matlab.ui.container.Tab
        GridLayout20                matlab.ui.container.GridLayout
        ACC1                        matlab.ui.control.UIAxes
        ACC2                        matlab.ui.control.UIAxes
        ACC3                        matlab.ui.control.UIAxes
        ACC4                        matlab.ui.control.UIAxes
        ACC5                        matlab.ui.control.UIAxes
        ACC6                        matlab.ui.control.UIAxes
        ANGULARACCELERATIONTab      matlab.ui.container.Tab
        GridLayout19                matlab.ui.container.GridLayout
        ACC1a                       matlab.ui.control.UIAxes
        ACC2a                       matlab.ui.control.UIAxes
        ACC3a                       matlab.ui.control.UIAxes
        ACC4a                       matlab.ui.control.UIAxes
        ACC5a                       matlab.ui.control.UIAxes
        ACC6a                       matlab.ui.control.UIAxes
        JERKTab_3                   matlab.ui.container.Tab
        GridLayout22                matlab.ui.container.GridLayout
        TabGroup5_2                 matlab.ui.container.TabGroup
        LINEALACCELERATIONTab_2     matlab.ui.container.Tab
        GridLayout20_2              matlab.ui.container.GridLayout
        JERK1                       matlab.ui.control.UIAxes
        JERK2                       matlab.ui.control.UIAxes
        JERK3                       matlab.ui.control.UIAxes
        JERK4                       matlab.ui.control.UIAxes
        JERK5                       matlab.ui.control.UIAxes
        JERK6                       matlab.ui.control.UIAxes
        ANGULARACCELERATIONTab_2    matlab.ui.container.Tab
        GridLayout19_2              matlab.ui.container.GridLayout
        JERK1a                      matlab.ui.control.UIAxes
        JERK2a                      matlab.ui.control.UIAxes
        JERK3a                      matlab.ui.control.UIAxes
        JERK4a                      matlab.ui.control.UIAxes
        JERK5a                      matlab.ui.control.UIAxes
        JERK6a                      matlab.ui.control.UIAxes
        DOUBLEJERKTab_3             matlab.ui.container.Tab
        GridLayout21                matlab.ui.container.GridLayout
        TabGroup5_3                 matlab.ui.container.TabGroup
        LINEALACCELERATIONTab_3     matlab.ui.container.Tab
        GridLayout20_3              matlab.ui.container.GridLayout
        DJ1                         matlab.ui.control.UIAxes
        DJ2                         matlab.ui.control.UIAxes
        DJ3                         matlab.ui.control.UIAxes
        DJ4                         matlab.ui.control.UIAxes
        DJ5                         matlab.ui.control.UIAxes
        DJ6                         matlab.ui.control.UIAxes
        ANGULARACCELERATIONTab_3    matlab.ui.container.Tab
        GridLayout19_3              matlab.ui.container.GridLayout
        DJ1a                        matlab.ui.control.UIAxes
        DJ2a                        matlab.ui.control.UIAxes
        DJ3a                        matlab.ui.control.UIAxes
        DJ5a                        matlab.ui.control.UIAxes
        DJ4a                        matlab.ui.control.UIAxes
        DJ6a                        matlab.ui.control.UIAxes
        VICONSXSENSTab              matlab.ui.container.Tab
        GridLayout13                matlab.ui.container.GridLayout
        GridLayout33                matlab.ui.container.GridLayout
        GridLayout16                matlab.ui.container.GridLayout
        VICONLampLabel              matlab.ui.control.Label
        VICONLamp                   matlab.ui.control.Lamp
        XSENSLampLabel              matlab.ui.control.Label
        XSENSLamp                   matlab.ui.control.Lamp
        GridLayout31                matlab.ui.container.GridLayout
        GRAPHButton                 matlab.ui.control.Button
        DIFFERENCEINSECONDSEditFieldLabel  matlab.ui.control.Label
        DIFFERENCEINSECONDS         matlab.ui.control.EditField
        COMPARETHETWOSIGNALSButton  matlab.ui.control.Button
        GridLayout40                matlab.ui.container.GridLayout
        GridLayout41                matlab.ui.container.GridLayout
        ACCELERATIONButtonGroup_2   matlab.ui.container.ButtonGroup
        LINEALB                     matlab.ui.control.RadioButton
        ANGULARB                    matlab.ui.control.RadioButton
        AXISButtonGroup_2           matlab.ui.container.ButtonGroup
        RESULTANTB                  matlab.ui.control.RadioButton
        XB                          matlab.ui.control.RadioButton
        YB                          matlab.ui.control.RadioButton
        ZB                          matlab.ui.control.RadioButton
        VS1                         matlab.ui.control.UIAxes
        VS2                         matlab.ui.control.UIAxes
        VS3                         matlab.ui.control.UIAxes
        VS4                         matlab.ui.control.UIAxes
        XSENSTab                    matlab.ui.container.Tab
        GridLayout37                matlab.ui.container.GridLayout
        GridLayout38                matlab.ui.container.GridLayout
        TIMESliderLabel             matlab.ui.control.Label
        TIMESlider                  matlab.ui.control.Slider
        EditField                   matlab.ui.control.NumericEditField
        EVENTSVICONButtonGroup      matlab.ui.container.ButtonGroup
        YESButton                   matlab.ui.control.RadioButton
        NOButton                    matlab.ui.control.RadioButton
        GridLayout39                matlab.ui.container.GridLayout
        PXS1                        matlab.ui.control.UIAxes
        PXS2                        matlab.ui.control.UIAxes
        PXS3                        matlab.ui.control.UIAxes
        PXS4                        matlab.ui.control.UIAxes
        PXS5                        matlab.ui.control.UIAxes
        PXS6                        matlab.ui.control.UIAxes
        PXS7                        matlab.ui.control.UIAxes
        ACCELERATIONButtonGroup     matlab.ui.container.ButtonGroup
        LINEALButton                matlab.ui.control.RadioButton
        ANGULARButton               matlab.ui.control.RadioButton
        DEVButtonGroup              matlab.ui.container.ButtonGroup
        NORMALButton                matlab.ui.control.RadioButton
        JERKButton                  matlab.ui.control.RadioButton
        JERK2Button                 matlab.ui.control.RadioButton
        AXISButtonGroup             matlab.ui.container.ButtonGroup
        XButton                     matlab.ui.control.RadioButton
        YButton                     matlab.ui.control.RadioButton
        ZButton                     matlab.ui.control.RadioButton
        RESULTANTButton             matlab.ui.control.RadioButton
        GRAPH                       matlab.ui.control.Button
        EVENTSXSENSTab              matlab.ui.container.Tab
        GridLayout44                matlab.ui.container.GridLayout
        UIAV                        matlab.ui.control.UIAxes
        GRAPHICButton               matlab.ui.control.Button
        UILA                        matlab.ui.control.UIAxes
        FOOTListBoxLabel            matlab.ui.control.Label
        FOOTListBox                 matlab.ui.control.ListBox
        COMPARTATIONTab             matlab.ui.container.Tab
        GridLayout45                matlab.ui.container.GridLayout
        ResultRigth                 matlab.ui.control.UIAxes
        COMPAREButton               matlab.ui.control.Button
        ResultLeft                  matlab.ui.control.UIAxes
        PERCENTAGEERRORRIGTHFOOTEditFieldLabel  matlab.ui.control.Label
        PERCENTAGEERRORRIGTHFOOTEditField  matlab.ui.control.EditField
        PERCENTAGEERRORLEFTFOOTEditFieldLabel  matlab.ui.control.Label
        PERCENTAGEERRORLEFTFOOTEditField  matlab.ui.control.EditField
    end

    
    properties (Access = private)
        h2 = 0; % Description
        verticalLineHandle % Description
        verticalLineHandle2 % Description
        verticalLineHandle3
        verticalLineHandle4
        verticalLineHandle5
        verticalLineHandle6
        verticalLineHandle7
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
%             app.UIFigure.AutoResizeChildren = 'off';
              movegui(app.UIFigure,"center");
              screenSize = get(groot,'ScreenSize');
              screenWidth = screenSize(3);
              screenHeight = screenSize(4);
              left = screenWidth*0.1;
              bottom = screenHeight*0.1;
              width = screenWidth*0.8;
              height = screenHeight*0.8;
%               drawnow;
              
           app.UIFigure.Position = [0 0 screenWidth screenHeight];
            drawnow;

            
        end

        % Callback function
        function BUGFIXERValueChanged(app, event)
            value = app.BUGFIXER.Value;
                    
    %% BUG 2
    
global cutRo
global cutR
global cutR2o
global cutR2
global cutIo
global cutI
global cutI2o
global cutI2
global lh_z
global rh_z
global lt_z2
global rt_z2
global timea
global lt_z
global rt_z
global lstep
global rstep
global lstepo
global rstepo
global slh_z


plot(app.BUGSLEFT,timea,lh_z,'r')
ylim(app.BUGSLEFT,[-.05 max(lh_z)+3])
xlim(app.BUGSLEFT,[timea(1) timea(slh_z)])
title(app.BUGSLEFT,'AXIS Z: Foot Strike (Heel)')
hold (app.BUGSLEFT, 'on')
plot(app.BUGSLEFT,timea,rh_z,'b')
plot(app.BUGSLEFT,timea(cutI),lh_z(cutI),'k+')
plot(app.BUGSLEFT,timea(lstep),lh_z(lstep),'kx')
plot(app.BUGSLEFT,timea(rstep),rh_z(rstep),'bx')
plot(app.BUGSLEFT,timea(cutR),rh_z(cutR),'b+')
legend (app.BUGSLEFT,{'Left' 'Right' 'Foot strike VICON' 'Foot strike MATLAB'}, 'Location','northwest','NumColumns',2)
ylabel(app.BUGSLEFT,'Height in cm') 
xlabel(app.BUGSLEFT,'Time in secs') 

plot(app.BUGSRIGHT,timea,lt_z,'r')
ylim(app.BUGSRIGHT,[-.05 max(lt_z)+3])
xlim(app.BUGSRIGHT,[timea(1) timea(slh_z)])
title(app.BUGSRIGHT,'AXIS Z: Foot Off (Toe)')
hold (app.BUGSRIGHT, 'on')
plot(app.BUGSRIGHT,timea,rt_z,'b')
plot(app.BUGSRIGHT,timea(cutIo),lt_z(cutIo),'ks')
plot(app.BUGSRIGHT,timea(lstepo),lt_z(lstepo),'ko')
plot(app.BUGSRIGHT,timea(rstepo),rt_z(rstepo),'ko')
plot(app.BUGSRIGHT,timea(cutRo),rt_z(cutRo),'ks')
legend (app.BUGSRIGHT,{'Left' 'Right' 'Foot off VICON' 'Foot off MATLAB'}, 'Location','northwest','NumColumns',2)
ylabel(app.BUGSRIGHT,'Height in cm') 
xlabel(app.BUGSRIGHT,'Time in secs') 


try
catch
    warning('Error en BUGs');
    a = 0;
    uiwait(msgbox('Error en BUGs','ERROR','modal'));
end
        end

        % Button pushed function: GRAPH
        function GRAPHButtonPushed2(app, event)
    
global ahip %cadera
global althigh %muslo izquierdo
global arthigh % muslo derecho
global alleg %pierna izquierda
global arleg %pierna derecha
global alft %pie izquierdo
global arft %pie derecho
global rahip %cadera
global ralthigh %muslo izquierdo
global rarthigh % muslo derecho
global ralleg %pierna izquierda
global rarleg %pierna derecha
global ralft %pie izquierdo
global rarft %pie derecho

global ahipj %cadera
global althighj %muslo izquierdo
global arthighj % muslo derecho
global allegj %pierna izquierda
global arlegj %pierna derecha
global alftj %pie izquierdo
global arftj %pie derecho
global rahipj %cadera
global ralthighj %muslo izquierdo
global rarthighj % muslo derecho
global rallegj %pierna izquierda
global rarlegj %pierna derecha
global ralftj %pie izquierdo
global rarftj %pie derecho
global timeNj

global ahipjj %cadera
global althighjj %muslo izquierdo
global arthighjj % muslo derecho
global allegjj %pierna izquierda
global arlegjj %pierna derecha
global alftjj %pie izquierdo
global arftjj %pie derecho
global rahipjj %cadera
global ralthighjj %muslo izquierdo
global rarthighjj % muslo derecho
global rallegjj %pierna izquierda
global rarlegjj %pierna derecha
global ralftjj %pie izquierdo
global rarftjj %pie derecho
global timeNjj

global ghip %cadera
global glthigh %muslo izquierdo
global grthigh % muslo derecho
global glleg %pierna izquierda
global grleg %pierna derecha
global glft %pie izquierdo
global grft %pie derecho
global rghip %cadera
global rglthigh %muslo izquierdo
global rgrthigh % muslo derecho
global rglleg %pierna izquierda
global rgrleg %pierna derecha
global rglft %pie izquierdo
global rgrft %pie derecho

global ghipj %cadera
global glthighj %muslo izquierdo
global grthighj % muslo derecho
global gllegj %pierna izquierda
global grlegj %pierna derecha
global glftj %pie izquierdo
global grftj %pie derecho
global rghipj %cadera
global rglthighj %muslo izquierdo
global rgrthighj % muslo derecho
global rgllegj %pierna izquierda
global rgrlegj %pierna derecha
global rglftj %pie izquierdo
global rgrftj %pie derecho

global ghipjj %cadera
global glthighjj %muslo izquierdo
global grthighjj % muslo derecho
global gllegjj %pierna izquierda
global grlegjj %pierna derecha
global glftjj %pie izquierdo
global grftjj %pie derecho
global rghipjj %cadera
global rglthighjj %muslo izquierdo
global rgrthighjj % muslo derecho
global rgllegjj %pierna izquierda
global rgrlegjj %pierna derecha
global rglftjj %pie izquierdo
global rgrftjj %pie derecho

global timeaa
global timeN
global T
global cutIa
global cutRa
global cutI2a
global cutR2a
global cutI2oa
global cutR2oa
global cutIoa
global cutRoa  

global h2
            
            cla(app.PXS1)
            cla(app.PXS2)
            cla(app.PXS3)
            cla(app.PXS4)
            cla(app.PXS5)
            cla(app.PXS6)
            cla(app.PXS7)
            
            global timeG
            
            timeG=timeN;
if app.LINEALButton.Value == 1
    if app.NORMALButton.Value == 1
        if app.RESULTANTButton.Value == 1
            timeN=(0:length(rahip)-1)*T;           
        x=1;
            plot(app.PXS1,timeN,rahip(:,x),'k')
            title(app.PXS1,'RESULTANT')
            xlim(app.PXS1,[0 timeN(end)]);
            ylim(app.PXS1,[min(rahip(:,x))*1.3 max(rahip(:,x))*1.3]); 
            
            plot(app.PXS2,timeN,ralthigh(:,x),'b')
            xlim(app.PXS2,[0 timeN(end)]);
            ylim(app.PXS2,[min(ralthigh(:,x))*1.3 max(ralthigh(:,x))*1.3]); 
            
            plot(app.PXS3,timeN,rarthigh(:,x),'r')
            xlim(app.PXS3,[0 timeN(end)]);
            ylim(app.PXS3,[min(rarthigh(:,x))*1.3 max(rarthigh(:,x))*1.3]); 
            
            plot(app.PXS4,timeN,ralleg(:,x),'b')
            xlim(app.PXS4,[0 timeN(end)]);
            ylim(app.PXS4,[min(ralleg(:,x))*1.3 max(ralleg(:,x))*1.3]); 
            
            plot(app.PXS5,timeN,rarleg(:,x),'r')
            xlim(app.PXS5,[0 timeN(end)]);
            ylim(app.PXS5,[min(rarleg(:,x))*1.3 max(rarleg(:,x))*1.3]); 
            
            plot(app.PXS6,timeN,ralft(:,x),'b')
            xlim(app.PXS6,[0 timeN(end)]);
            ylim(app.PXS6,[min(ralft(:,x))*1.3 max(ralft(:,x))*1.3]); 
            
            plot(app.PXS7,timeN,rarft(:,x),'r')
            xlim(app.PXS7,[0 timeN(end)]);
            ylim(app.PXS7,[min(rarft(:,x))*1.3 max(rarft(:,x))*1.3]); 
        end
        if app.XButton.Value == 1
        x=1;
            plot(app.PXS1,timeN,ahip(:,x),'k')
            title(app.PXS1,'X')
            xlim(app.PXS1,[0 timeN(end)]);
            ylim(app.PXS1,[min(ahip(:,x))*1.3 max(ahip(:,x))*1.3]); 
            
            plot(app.PXS2,timeN,althigh(:,x),'b')
            xlim(app.PXS2,[0 timeN(end)]);
            ylim(app.PXS2,[min(althigh(:,x))*1.3 max(althigh(:,x))*1.3]); 
            
            plot(app.PXS3,timeN,arthigh(:,x),'r')
            xlim(app.PXS3,[0 timeN(end)]);
            ylim(app.PXS3,[min(arthigh(:,x))*1.3 max(arthigh(:,x))*1.3]); 
            
            plot(app.PXS4,timeN,alleg(:,x),'b')
            xlim(app.PXS4,[0 timeN(end)]);
            ylim(app.PXS4,[min(alleg(:,x))*1.3 max(alleg(:,x))*1.3]); 
            
            plot(app.PXS5,timeN,arleg(:,x),'r')
            xlim(app.PXS5,[0 timeN(end)]);
            ylim(app.PXS5,[min(arleg(:,x))*1.3 max(arleg(:,x))*1.3]); 
            
            plot(app.PXS6,timeN,alft(:,x),'b')
            xlim(app.PXS6,[0 timeN(end)]);
            ylim(app.PXS6,[min(alft(:,x))*1.3 max(alft(:,x))*1.3]); 
            
            plot(app.PXS7,timeN,arft(:,x),'r')
            xlim(app.PXS7,[0 timeN(end)]);
            ylim(app.PXS7,[min(arft(:,x))*1.3 max(arft(:,x))*1.3]); 
        end
        if app.YButton.Value == 1
            x=2;
            plot(app.PXS1,timeN,ahip(:,x),'k')
            title(app.PXS1,'Y')
            xlim(app.PXS1,[0 timeN(end)]);
            ylim(app.PXS1,[min(ahip(:,x))*1.3 max(ahip(:,x))*1.3]); 
            
            plot(app.PXS2,timeN,althigh(:,x),'b')
            xlim(app.PXS2,[0 timeN(end)]);
            ylim(app.PXS2,[min(althigh(:,x))*1.3 max(althigh(:,x))*1.3]); 
            
            plot(app.PXS3,timeN,arthigh(:,x),'r')
            xlim(app.PXS3,[0 timeN(end)]);
            ylim(app.PXS3,[min(arthigh(:,x))*1.3 max(arthigh(:,x))*1.3]); 
            
            plot(app.PXS4,timeN,alleg(:,x),'b')
            xlim(app.PXS4,[0 timeN(end)]);
            ylim(app.PXS4,[min(alleg(:,x))*1.3 max(alleg(:,x))*1.3]); 
            
            plot(app.PXS5,timeN,arleg(:,x),'r')
            xlim(app.PXS5,[0 timeN(end)]);
            ylim(app.PXS5,[min(arleg(:,x))*1.3 max(arleg(:,x))*1.3]); 
            
            plot(app.PXS6,timeN,alft(:,x),'b')
            xlim(app.PXS6,[0 timeN(end)]);
            ylim(app.PXS6,[min(alft(:,x))*1.3 max(alft(:,x))*1.3]); 
            
            plot(app.PXS7,timeN,arft(:,x),'r')
            xlim(app.PXS7,[0 timeN(end)]);
            ylim(app.PXS7,[min(arft(:,x))*1.3 max(arft(:,x))*1.3]); 
        
        end
        if app.ZButton.Value == 1
            x=3;
            plot(app.PXS1,timeN,ahip(:,x),'k')
            title(app.PXS1,'Z')
            xlim(app.PXS1,[0 timeN(end)]);
            ylim(app.PXS1,[min(ahip(:,x))*1.3 max(ahip(:,x))*1.3]); 
            
            plot(app.PXS2,timeN,althigh(:,x),'b')
            xlim(app.PXS2,[0 timeN(end)]);
            ylim(app.PXS2,[min(althigh(:,x))*1.3 max(althigh(:,x))*1.3]); 
            
            plot(app.PXS3,timeN,arthigh(:,x),'r')
            xlim(app.PXS3,[0 timeN(end)]);
            ylim(app.PXS3,[min(arthigh(:,x))*1.3 max(arthigh(:,x))*1.3]); 
            
            plot(app.PXS4,timeN,alleg(:,x),'b')
            xlim(app.PXS4,[0 timeN(end)]);
            ylim(app.PXS4,[min(alleg(:,x))*1.3 max(alleg(:,x))*1.3]); 
            
            plot(app.PXS5,timeN,arleg(:,x),'r')
            xlim(app.PXS5,[0 timeN(end)]);
            ylim(app.PXS5,[min(arleg(:,x))*1.3 max(arleg(:,x))*1.3]); 
            
            plot(app.PXS6,timeN,alft(:,x),'b')
            xlim(app.PXS6,[0 timeN(end)]);
            ylim(app.PXS6,[min(alft(:,x))*1.3 max(alft(:,x))*1.3]); 
            
            plot(app.PXS7,timeN,arft(:,x),'r')
            xlim(app.PXS7,[0 timeN(end)]);
            ylim(app.PXS7,[min(arft(:,x))*1.3 max(arft(:,x))*1.3]); 
        
        end
    
    end
    if app.JERKButton.Value == 1
        if app.RESULTANTButton.Value == 1
        
            x=1;
            plot(app.PXS1,timeNj,rahipj(:,x),'k')
            title(app.PXS1,'JERK RESULTANT')
            xlim(app.PXS1,[0 timeNj(end)]);
            ylim(app.PXS1,[min(rahipj(:,x))*1.3 max(rahipj(:,x))*1.3]); 
            
            plot(app.PXS2,timeNj,ralthighj(:,x),'b')
            xlim(app.PXS2,[0 timeNj(end)]);
            ylim(app.PXS2,[min(ralthighj(:,x))*1.3 max(ralthighj(:,x))*1.3]); 
            
            plot(app.PXS3,timeNj,rarthighj(:,x),'r')
            xlim(app.PXS3,[0 timeNj(end)]);
            ylim(app.PXS3,[min(rarthighj(:,x))*1.3 max(rarthighj(:,x))*1.3]); 
            
            plot(app.PXS4,timeNj,rallegj(:,x),'b')
            xlim(app.PXS4,[0 timeNj(end)]);
            ylim(app.PXS4,[min(rallegj(:,x))*1.3 max(rallegj(:,x))*1.3]); 
            
            plot(app.PXS5,timeNj,rarlegj(:,x),'r')
            xlim(app.PXS5,[0 timeNj(end)]);
            ylim(app.PXS5,[min(rarlegj(:,x))*1.3 max(rarlegj(:,x))*1.3]); 
            
            plot(app.PXS6,timeNj,ralftj(:,x),'b')
            xlim(app.PXS6,[0 timeNj(end)]);
            ylim(app.PXS6,[min(ralftj(:,x))*1.3 max(ralftj(:,x))*1.3]); 
            
            plot(app.PXS7,timeNj,rarftj(:,x),'r')
            xlim(app.PXS7,[0 timeNj(end)]);
            ylim(app.PXS7,[min(rarftj(:,x))*1.3 max(rarftj(:,x))*1.3]); 
            end
            
            if app.XButton.Value == 1
            
            x=1;
            plot(app.PXS1,timeNj,ahipj(:,x),'k')
            title(app.PXS1,'JERK X')
            xlim(app.PXS1,[0 timeNj(end)]);
            ylim(app.PXS1,[min(ahipj(:,x))*1.3 max(ahipj(:,x))*1.3]); 
            
            plot(app.PXS2,timeNj,althighj(:,x),'b')
            xlim(app.PXS2,[0 timeNj(end)]);
            ylim(app.PXS2,[min(althighj(:,x))*1.3 max(althighj(:,x))*1.3]); 
            
            plot(app.PXS3,timeNj,arthighj(:,x),'r')
            xlim(app.PXS3,[0 timeNj(end)]);
            ylim(app.PXS3,[min(arthighj(:,x))*1.3 max(arthighj(:,x))*1.3]); 
            
            plot(app.PXS4,timeNj,allegj(:,x),'b')
            xlim(app.PXS4,[0 timeNj(end)]);
            ylim(app.PXS4,[min(allegj(:,x))*1.3 max(allegj(:,x))*1.3]); 
            
            plot(app.PXS5,timeNj,arlegj(:,x),'r')
            xlim(app.PXS5,[0 timeNj(end)]);
            ylim(app.PXS5,[min(arlegj(:,x))*1.3 max(arlegj(:,x))*1.3]); 
            
            plot(app.PXS6,timeNj,alftj(:,x),'b')
            xlim(app.PXS6,[0 timeNj(end)]);
            ylim(app.PXS6,[min(alftj(:,x))*1.3 max(alftj(:,x))*1.3]); 
            
            plot(app.PXS7,timeNj,arftj(:,x),'r')
            xlim(app.PXS7,[0 timeNj(end)]);
            ylim(app.PXS7,[min(arftj(:,x))*1.3 max(arftj(:,x))*1.3]); 
            end
            if app.YButton.Value == 1
            
            x=2;
            plot(app.PXS1,timeNj,ahipj(:,x),'k')
            title(app.PXS1,'JERK Y')
            xlim(app.PXS1,[0 timeNj(end)]);
            ylim(app.PXS1,[min(ahipj(:,x))*1.3 max(ahipj(:,x))*1.3]); 
            
            plot(app.PXS2,timeNj,althighj(:,x),'b')
            xlim(app.PXS2,[0 timeNj(end)]);
            ylim(app.PXS2,[min(althighj(:,x))*1.3 max(althighj(:,x))*1.3]); 
            
            plot(app.PXS3,timeNj,arthighj(:,x),'r')
            xlim(app.PXS3,[0 timeNj(end)]);
            ylim(app.PXS3,[min(arthighj(:,x))*1.3 max(arthighj(:,x))*1.3]); 
            
            plot(app.PXS4,timeNj,allegj(:,x),'b')
            xlim(app.PXS4,[0 timeNj(end)]);
            ylim(app.PXS4,[min(allegj(:,x))*1.3 max(allegj(:,x))*1.3]); 
            
            plot(app.PXS5,timeNj,arlegj(:,x),'r')
            xlim(app.PXS5,[0 timeNj(end)]);
            ylim(app.PXS5,[min(arlegj(:,x))*1.3 max(arlegj(:,x))*1.3]); 
            
            plot(app.PXS6,timeNj,alftj(:,x),'b')
            xlim(app.PXS6,[0 timeNj(end)]);
            ylim(app.PXS6,[min(alftj(:,x))*1.3 max(alftj(:,x))*1.3]); 
            
            plot(app.PXS7,timeNj,arftj(:,x),'r')
            xlim(app.PXS7,[0 timeNj(end)]);
            ylim(app.PXS7,[min(arftj(:,x))*1.3 max(arftj(:,x))*1.3]); 
            end
            if app.ZButton.Value == 1
            
            x=3;
            plot(app.PXS1,timeNj,ahipj(:,x),'k')
            title(app.PXS1,'JERK Z')
            xlim(app.PXS1,[0 timeNj(end)]);
            ylim(app.PXS1,[min(ahipj(:,x))*1.3 max(ahipj(:,x))*1.3]); 
            
            plot(app.PXS2,timeNj,althighj(:,x),'b')
            xlim(app.PXS2,[0 timeNj(end)]);
            ylim(app.PXS2,[min(althighj(:,x))*1.3 max(althighj(:,x))*1.3]); 
            
            plot(app.PXS3,timeNj,arthighj(:,x),'r')
            xlim(app.PXS3,[0 timeNj(end)]);
            ylim(app.PXS3,[min(arthighj(:,x))*1.3 max(arthighj(:,x))*1.3]); 
            
            plot(app.PXS4,timeNj,allegj(:,x),'b')
            xlim(app.PXS4,[0 timeNj(end)]);
            ylim(app.PXS4,[min(allegj(:,x))*1.3 max(allegj(:,x))*1.3]); 
            
            plot(app.PXS5,timeNj,arlegj(:,x),'r')
            xlim(app.PXS5,[0 timeNj(end)]);
            ylim(app.PXS5,[min(arlegj(:,x))*1.3 max(arlegj(:,x))*1.3]); 
            
            plot(app.PXS6,timeNj,alftj(:,x),'b')
            xlim(app.PXS6,[0 timeNj(end)]);
            ylim(app.PXS6,[min(alftj(:,x))*1.3 max(alftj(:,x))*1.3]); 
            
            plot(app.PXS7,timeNj,arftj(:,x),'r')
            xlim(app.PXS7,[0 timeNj(end)]);
            ylim(app.PXS7,[min(arftj(:,x))*1.3 max(arftj(:,x))*1.3]); 
            
        end
        
        
    end
    
    if app.JERK2Button.Value == 1
        if app.RESULTANTButton.Value == 1
            
            x=1;
            plot(app.PXS1,timeNjj,rahipjj(:,x),'k')
            title(app.PXS1,'DOUBLE JERK RESULTANT')
            xlim(app.PXS1,[0 timeNjj(end)]);
            ylim(app.PXS1,[min(rahipjj(:,x))*1.3 max(rahipjj(:,x))*1.3]); 
            
            plot(app.PXS2,timeNjj,ralthighjj(:,x),'b')
            xlim(app.PXS2,[0 timeNjj(end)]);
            ylim(app.PXS2,[min(ralthighjj(:,x))*1.3 max(ralthighjj(:,x))*1.3]); 
            
            plot(app.PXS3,timeNjj,rarthighjj(:,x),'r')
            xlim(app.PXS3,[0 timeNjj(end)]);
            ylim(app.PXS3,[min(rarthighjj(:,x))*1.3 max(rarthighjj(:,x))*1.3]); 
            
            plot(app.PXS4,timeNjj,rallegjj(:,x),'b')
            xlim(app.PXS4,[0 timeNjj(end)]);
            ylim(app.PXS4,[min(rallegjj(:,x))*1.3 max(rallegjj(:,x))*1.3]); 
            
            plot(app.PXS5,timeNjj,rarlegjj(:,x),'r')
            xlim(app.PXS5,[0 timeNjj(end)]);
            ylim(app.PXS5,[min(rarlegjj(:,x))*1.3 max(rarlegjj(:,x))*1.3]); 
            
            plot(app.PXS6,timeNjj,ralftjj(:,x),'b')
            xlim(app.PXS6,[0 timeNjj(end)]);
            ylim(app.PXS6,[min(ralftjj(:,x))*1.3 max(ralftjj(:,x))*1.3]); 
            
            plot(app.PXS7,timeNjj,rarftjj(:,x),'r')
            xlim(app.PXS7,[0 timeNjj(end)]);
            ylim(app.PXS7,[min(rarftjj(:,x))*1.3 max(rarftjj(:,x))*1.3]); 
            end
            
            if app.XButton.Value == 1
            
            x=1;
            plot(app.PXS1,timeNjj,ahipjj(:,x),'k')
            title(app.PXS1,'DOUBLE JERK X')
            xlim(app.PXS1,[0 timeNjj(end)]);
            ylim(app.PXS1,[min(ahipjj(:,x))*1.3 max(ahipjj(:,x))*1.3]); 
            
            plot(app.PXS2,timeNjj,althighjj(:,x),'b')
            xlim(app.PXS2,[0 timeNjj(end)]);
            ylim(app.PXS2,[min(althighjj(:,x))*1.3 max(althighjj(:,x))*1.3]); 
            
            plot(app.PXS3,timeNjj,arthighjj(:,x),'r')
            xlim(app.PXS3,[0 timeNjj(end)]);
            ylim(app.PXS3,[min(arthighjj(:,x))*1.3 max(arthighjj(:,x))*1.3]); 
            
            plot(app.PXS4,timeNjj,allegjj(:,x),'b')
            xlim(app.PXS4,[0 timeNjj(end)]);
            ylim(app.PXS4,[min(allegjj(:,x))*1.3 max(allegjj(:,x))*1.3]); 
            
            plot(app.PXS5,timeNjj,arlegjj(:,x),'r')
            xlim(app.PXS5,[0 timeNjj(end)]);
            ylim(app.PXS5,[min(arlegjj(:,x))*1.3 max(arlegjj(:,x))*1.3]); 
            
            plot(app.PXS6,timeNjj,alftjj(:,x),'b')
            xlim(app.PXS6,[0 timeNjj(end)]);
            ylim(app.PXS6,[min(alftjj(:,x))*1.3 max(alftjj(:,x))*1.3]); 
            
            plot(app.PXS7,timeNjj,arftjj(:,x),'r')
            xlim(app.PXS7,[0 timeNjj(end)]);
            ylim(app.PXS7,[min(arftjj(:,x))*1.3 max(arftjj(:,x))*1.3]); 
            end
            if app.YButton.Value == 1
            
            x=2;
            plot(app.PXS1,timeNjj,ahipjj(:,x),'k')
            title(app.PXS1,'DOUBLE JERK Y')
            xlim(app.PXS1,[0 timeNjj(end)]);
            ylim(app.PXS1,[min(ahipjj(:,x))*1.3 max(ahipjj(:,x))*1.3]); 
            
            plot(app.PXS2,timeNjj,althighjj(:,x),'b')
            xlim(app.PXS2,[0 timeNjj(end)]);
            ylim(app.PXS2,[min(althighjj(:,x))*1.3 max(althighjj(:,x))*1.3]); 
            
            plot(app.PXS3,timeNjj,arthighjj(:,x),'r')
            xlim(app.PXS3,[0 timeNjj(end)]);
            ylim(app.PXS3,[min(arthighjj(:,x))*1.3 max(arthighjj(:,x))*1.3]); 
            
            plot(app.PXS4,timeNjj,allegjj(:,x),'b')
            xlim(app.PXS4,[0 timeNjj(end)]);
            ylim(app.PXS4,[min(allegjj(:,x))*1.3 max(allegjj(:,x))*1.3]); 
            
            plot(app.PXS5,timeNjj,arlegjj(:,x),'r')
            xlim(app.PXS5,[0 timeNjj(end)]);
            ylim(app.PXS5,[min(arlegjj(:,x))*1.3 max(arlegjj(:,x))*1.3]); 
            
            plot(app.PXS6,timeNjj,alftjj(:,x),'b')
            xlim(app.PXS6,[0 timeNjj(end)]);
            ylim(app.PXS6,[min(alftjj(:,x))*1.3 max(alftjj(:,x))*1.3]); 
            
            plot(app.PXS7,timeNjj,arftjj(:,x),'r')
            xlim(app.PXS7,[0 timeNjj(end)]);
            ylim(app.PXS7,[min(arftjj(:,x))*1.3 max(arftjj(:,x))*1.3]); 
            end
            if app.ZButton.Value == 1
            
            x=3;
            plot(app.PXS1,timeNjj,ahipjj(:,x),'k')
            title(app.PXS1,'DOUBLE JERK Z')
            xlim(app.PXS1,[0 timeNjj(end)]);
            ylim(app.PXS1,[min(ahipjj(:,x))*1.3 max(ahipjj(:,x))*1.3]); 
            
            plot(app.PXS2,timeNjj,althighjj(:,x),'b')
            xlim(app.PXS2,[0 timeNjj(end)]);
            ylim(app.PXS2,[min(althighjj(:,x))*1.3 max(althighjj(:,x))*1.3]); 
            
            plot(app.PXS3,timeNjj,arthighjj(:,x),'r')
            xlim(app.PXS3,[0 timeNjj(end)]);
            ylim(app.PXS3,[min(arthighjj(:,x))*1.3 max(arthighjj(:,x))*1.3]); 
            
            plot(app.PXS4,timeNjj,allegjj(:,x),'b')
            xlim(app.PXS4,[0 timeNjj(end)]);
            ylim(app.PXS4,[min(allegjj(:,x))*1.3 max(allegjj(:,x))*1.3]); 
            
            plot(app.PXS5,timeNjj,arlegjj(:,x),'r')
            xlim(app.PXS5,[0 timeNjj(end)]);
            ylim(app.PXS5,[min(arlegjj(:,x))*1.3 max(arlegjj(:,x))*1.3]); 
            
            plot(app.PXS6,timeNjj,alftjj(:,x),'b')
            xlim(app.PXS6,[0 timeNjj(end)]);
            ylim(app.PXS6,[min(alftjj(:,x))*1.3 max(alftjj(:,x))*1.3]); 
            
            plot(app.PXS7,timeNjj,arftjj(:,x),'r')
            xlim(app.PXS7,[0 timeNjj(end)]);
            ylim(app.PXS7,[min(arftjj(:,x))*1.3 max(arftjj(:,x))*1.3]); 
        end
        
        
    end
    else
        if app.NORMALButton.Value == 1
        if app.RESULTANTButton.Value == 1
        x=1;
            plot(app.PXS1,timeN,rghip(:,x),'k')
            title(app.PXS1,'RESULTANT')
            xlim(app.PXS1,[0 timeN(end)]);
            ylim(app.PXS1,[min(rghip(:,x))*1.3 max(rghip(:,x))*1.3]); 
            
            plot(app.PXS2,timeN,rglthigh(:,x),'b')
            xlim(app.PXS2,[0 timeN(end)]);
            ylim(app.PXS2,[min(rglthigh(:,x))*1.3 max(rglthigh(:,x))*1.3]); 
            
            plot(app.PXS3,timeN,rgrthigh(:,x),'r')
            xlim(app.PXS3,[0 timeN(end)]);
            ylim(app.PXS3,[min(rgrthigh(:,x))*1.3 max(rgrthigh(:,x))*1.3]); 
            
            plot(app.PXS4,timeN,rglleg(:,x),'b')
            xlim(app.PXS4,[0 timeN(end)]);
            ylim(app.PXS4,[min(rglleg(:,x))*1.3 max(rglleg(:,x))*1.3]); 
            
            plot(app.PXS5,timeN,rgrleg(:,x),'r')
            xlim(app.PXS5,[0 timeN(end)]);
            ylim(app.PXS5,[min(rgrleg(:,x))*1.3 max(rgrleg(:,x))*1.3]); 
            
            plot(app.PXS6,timeN,rglft(:,x),'b')
            xlim(app.PXS6,[0 timeN(end)]);
            ylim(app.PXS6,[min(rglft(:,x))*1.3 max(rglft(:,x))*1.3]); 
            
            plot(app.PXS7,timeN,rgrft(:,x),'r')
            xlim(app.PXS7,[0 timeN(end)]);
            ylim(app.PXS7,[min(rgrft(:,x))*1.3 max(rgrft(:,x))*1.3]); 
        end
        if app.XButton.Value == 1
        x=1;
            plot(app.PXS1,timeN,ghip(:,x),'k')
            title(app.PXS1,'X')
            xlim(app.PXS1,[0 timeN(end)]);
            ylim(app.PXS1,[min(ghip(:,x))*1.3 max(ghip(:,x))*1.3]); 
            
            plot(app.PXS2,timeN,glthigh(:,x),'b')
            xlim(app.PXS2,[0 timeN(end)]);
            ylim(app.PXS2,[min(glthigh(:,x))*1.3 max(glthigh(:,x))*1.3]); 
            
            plot(app.PXS3,timeN,grthigh(:,x),'r')
            xlim(app.PXS3,[0 timeN(end)]);
            ylim(app.PXS3,[min(grthigh(:,x))*1.3 max(grthigh(:,x))*1.3]); 
            
            plot(app.PXS4,timeN,glleg(:,x),'b')
            xlim(app.PXS4,[0 timeN(end)]);
            ylim(app.PXS4,[min(glleg(:,x))*1.3 max(glleg(:,x))*1.3]); 
            
            plot(app.PXS5,timeN,grleg(:,x),'r')
            xlim(app.PXS5,[0 timeN(end)]);
            ylim(app.PXS5,[min(grleg(:,x))*1.3 max(grleg(:,x))*1.3]); 
            
            plot(app.PXS6,timeN,glft(:,x),'b')
            xlim(app.PXS6,[0 timeN(end)]);
            ylim(app.PXS6,[min(glft(:,x))*1.3 max(glft(:,x))*1.3]); 
            
            plot(app.PXS7,timeN,grft(:,x),'r')
            xlim(app.PXS7,[0 timeN(end)]);
            ylim(app.PXS7,[min(grft(:,x))*1.3 max(grft(:,x))*1.3]); 
        end
        if app.YButton.Value == 1
            x=2;
            plot(app.PXS1,timeN,ghip(:,x),'k')
            title(app.PXS1,'Y')
            xlim(app.PXS1,[0 timeN(end)]);
            ylim(app.PXS1,[min(ghip(:,x))*1.3 max(ghip(:,x))*1.3]); 
            
            plot(app.PXS2,timeN,glthigh(:,x),'b')
            xlim(app.PXS2,[0 timeN(end)]);
            ylim(app.PXS2,[min(glthigh(:,x))*1.3 max(glthigh(:,x))*1.3]); 
            
            plot(app.PXS3,timeN,grthigh(:,x),'r')
            xlim(app.PXS3,[0 timeN(end)]);
            ylim(app.PXS3,[min(grthigh(:,x))*1.3 max(grthigh(:,x))*1.3]); 
            
            plot(app.PXS4,timeN,glleg(:,x),'b')
            xlim(app.PXS4,[0 timeN(end)]);
            ylim(app.PXS4,[min(glleg(:,x))*1.3 max(glleg(:,x))*1.3]); 
            
            plot(app.PXS5,timeN,grleg(:,x),'r')
            xlim(app.PXS5,[0 timeN(end)]);
            ylim(app.PXS5,[min(grleg(:,x))*1.3 max(grleg(:,x))*1.3]); 
            
            plot(app.PXS6,timeN,glft(:,x),'b')
            xlim(app.PXS6,[0 timeN(end)]);
            ylim(app.PXS6,[min(glft(:,x))*1.3 max(glft(:,x))*1.3]); 
            
            plot(app.PXS7,timeN,grft(:,x),'r')
            xlim(app.PXS7,[0 timeN(end)]);
            ylim(app.PXS7,[min(grft(:,x))*1.3 max(grft(:,x))*1.3]); 
        
        end
        if app.ZButton.Value == 1
            x=3;
            plot(app.PXS1,timeN,ghip(:,x),'k')
            title(app.PXS1,'Z')
            xlim(app.PXS1,[0 timeN(end)]);
            ylim(app.PXS1,[min(ghip(:,x))*1.3 max(ghip(:,x))*1.3]); 
            
            plot(app.PXS2,timeN,glthigh(:,x),'b')
            xlim(app.PXS2,[0 timeN(end)]);
            ylim(app.PXS2,[min(glthigh(:,x))*1.3 max(glthigh(:,x))*1.3]); 
            
            plot(app.PXS3,timeN,grthigh(:,x),'r')
            xlim(app.PXS3,[0 timeN(end)]);
            ylim(app.PXS3,[min(grthigh(:,x))*1.3 max(grthigh(:,x))*1.3]); 
            
            plot(app.PXS4,timeN,glleg(:,x),'b')
            xlim(app.PXS4,[0 timeN(end)]);
            ylim(app.PXS4,[min(glleg(:,x))*1.3 max(glleg(:,x))*1.3]); 
            
            plot(app.PXS5,timeN,grleg(:,x),'r')
            xlim(app.PXS5,[0 timeN(end)]);
            ylim(app.PXS5,[min(grleg(:,x))*1.3 max(grleg(:,x))*1.3]); 
            
            plot(app.PXS6,timeN,glft(:,x),'b')
            xlim(app.PXS6,[0 timeN(end)]);
            ylim(app.PXS6,[min(glft(:,x))*1.3 max(glft(:,x))*1.3]); 
            
            plot(app.PXS7,timeN,grft(:,x),'r')
            xlim(app.PXS7,[0 timeN(end)]);
            ylim(app.PXS7,[min(grft(:,x))*1.3 max(grft(:,x))*1.3]); 
        
        end
    
    end
    if app.JERKButton.Value == 1
        if app.RESULTANTButton.Value == 1
        
            x=1;
            plot(app.PXS1,timeNj,rghipj(:,x),'k')
            title(app.PXS1,'JERK RESULTANT')
            xlim(app.PXS1,[0 timeNj(end)]);
            ylim(app.PXS1,[min(rghipj(:,x))*1.3 max(rghipj(:,x))*1.3]); 
            
            plot(app.PXS2,timeNj,rglthighj(:,x),'b')
            xlim(app.PXS2,[0 timeNj(end)]);
            ylim(app.PXS2,[min(rglthighj(:,x))*1.3 max(rglthighj(:,x))*1.3]); 
            
            plot(app.PXS3,timeNj,rgrthighj(:,x),'r')
            xlim(app.PXS3,[0 timeNj(end)]);
            ylim(app.PXS3,[min(rgrthighj(:,x))*1.3 max(rgrthighj(:,x))*1.3]); 
            
            plot(app.PXS4,timeNj,rgllegj(:,x),'b')
            xlim(app.PXS4,[0 timeNj(end)]);
            ylim(app.PXS4,[min(rgllegj(:,x))*1.3 max(rgllegj(:,x))*1.3]); 
            
            plot(app.PXS5,timeNj,rgrlegj(:,x),'r')
            xlim(app.PXS5,[0 timeNj(end)]);
            ylim(app.PXS5,[min(rgrlegj(:,x))*1.3 max(rgrlegj(:,x))*1.3]); 
            
            plot(app.PXS6,timeNj,rglftj(:,x),'b')
            xlim(app.PXS6,[0 timeNj(end)]);
            ylim(app.PXS6,[min(rglftj(:,x))*1.3 max(rglftj(:,x))*1.3]); 
            
            plot(app.PXS7,timeNj,rgrftj(:,x),'r')
            xlim(app.PXS7,[0 timeNj(end)]);
            ylim(app.PXS7,[min(rgrftj(:,x))*1.3 max(rgrftj(:,x))*1.3]); 
            end
            
            if app.XButton.Value == 1
            
            x=1;
            plot(app.PXS1,timeNj,ghipj(:,x),'k')
            title(app.PXS1,'JERK X')
            xlim(app.PXS1,[0 timeNj(end)]);
            ylim(app.PXS1,[min(ghipj(:,x))*1.3 max(ghipj(:,x))*1.3]); 
            
            plot(app.PXS2,timeNj,glthighj(:,x),'b')
            xlim(app.PXS2,[0 timeNj(end)]);
            ylim(app.PXS2,[min(glthighj(:,x))*1.3 max(glthighj(:,x))*1.3]); 
            
            plot(app.PXS3,timeNj,grthighj(:,x),'r')
            xlim(app.PXS3,[0 timeNj(end)]);
            ylim(app.PXS3,[min(grthighj(:,x))*1.3 max(grthighj(:,x))*1.3]); 
            
            plot(app.PXS4,timeNj,gllegj(:,x),'b')
            xlim(app.PXS4,[0 timeNj(end)]);
            ylim(app.PXS4,[min(gllegj(:,x))*1.3 max(gllegj(:,x))*1.3]); 
            
            plot(app.PXS5,timeNj,grlegj(:,x),'r')
            xlim(app.PXS5,[0 timeNj(end)]);
            ylim(app.PXS5,[min(grlegj(:,x))*1.3 max(grlegj(:,x))*1.3]); 
            
            plot(app.PXS6,timeNj,glftj(:,x),'b')
            xlim(app.PXS6,[0 timeNj(end)]);
            ylim(app.PXS6,[min(glftj(:,x))*1.3 max(glftj(:,x))*1.3]); 
            
            plot(app.PXS7,timeNj,grftj(:,x),'r')
            xlim(app.PXS7,[0 timeNj(end)]);
            ylim(app.PXS7,[min(grftj(:,x))*1.3 max(grftj(:,x))*1.3]); 
            end
            if app.YButton.Value == 1
            
            x=2;
            plot(app.PXS1,timeNj,ghipj(:,x),'k')
            title(app.PXS1,'JERK Y')
            xlim(app.PXS1,[0 timeNj(end)]);
            ylim(app.PXS1,[min(ghipj(:,x))*1.3 max(ghipj(:,x))*1.3]); 
            
            plot(app.PXS2,timeNj,glthighj(:,x),'b')
            xlim(app.PXS2,[0 timeNj(end)]);
            ylim(app.PXS2,[min(glthighj(:,x))*1.3 max(glthighj(:,x))*1.3]); 
            
            plot(app.PXS3,timeNj,grthighj(:,x),'r')
            xlim(app.PXS3,[0 timeNj(end)]);
            ylim(app.PXS3,[min(grthighj(:,x))*1.3 max(grthighj(:,x))*1.3]); 
            
            plot(app.PXS4,timeNj,gllegj(:,x),'b')
            xlim(app.PXS4,[0 timeNj(end)]);
            ylim(app.PXS4,[min(gllegj(:,x))*1.3 max(gllegj(:,x))*1.3]); 
            
            plot(app.PXS5,timeNj,grlegj(:,x),'r')
            xlim(app.PXS5,[0 timeNj(end)]);
            ylim(app.PXS5,[min(grlegj(:,x))*1.3 max(grlegj(:,x))*1.3]); 
            
            plot(app.PXS6,timeNj,glftj(:,x),'b')
            xlim(app.PXS6,[0 timeNj(end)]);
            ylim(app.PXS6,[min(glftj(:,x))*1.3 max(glftj(:,x))*1.3]); 
            
            plot(app.PXS7,timeNj,grftj(:,x),'r')
            xlim(app.PXS7,[0 timeNj(end)]);
            ylim(app.PXS7,[min(grftj(:,x))*1.3 max(grftj(:,x))*1.3]); 
            end
            if app.ZButton.Value == 1
            
            x=3;
            plot(app.PXS1,timeNj,ghipj(:,x),'k')
            title(app.PXS1,'JERK Z')
            xlim(app.PXS1,[0 timeNj(end)]);
            ylim(app.PXS1,[min(ghipj(:,x))*1.3 max(ghipj(:,x))*1.3]); 
            
            plot(app.PXS2,timeNj,glthighj(:,x),'b')
            xlim(app.PXS2,[0 timeNj(end)]);
            ylim(app.PXS2,[min(glthighj(:,x))*1.3 max(glthighj(:,x))*1.3]); 
            
            plot(app.PXS3,timeNj,grthighj(:,x),'r')
            xlim(app.PXS3,[0 timeNj(end)]);
            ylim(app.PXS3,[min(grthighj(:,x))*1.3 max(grthighj(:,x))*1.3]); 
            
            plot(app.PXS4,timeNj,gllegj(:,x),'b')
            xlim(app.PXS4,[0 timeNj(end)]);
            ylim(app.PXS4,[min(gllegj(:,x))*1.3 max(gllegj(:,x))*1.3]); 
            
            plot(app.PXS5,timeNj,grlegj(:,x),'r')
            xlim(app.PXS5,[0 timeNj(end)]);
            ylim(app.PXS5,[min(grlegj(:,x))*1.3 max(grlegj(:,x))*1.3]); 
            
            plot(app.PXS6,timeNj,glftj(:,x),'b')
            xlim(app.PXS6,[0 timeNj(end)]);
            ylim(app.PXS6,[min(glftj(:,x))*1.3 max(glftj(:,x))*1.3]); 
            
            plot(app.PXS7,timeNj,grftj(:,x),'r')
            xlim(app.PXS7,[0 timeNj(end)]);
            ylim(app.PXS7,[min(grftj(:,x))*1.3 max(grftj(:,x))*1.3]); 
            
        end
        
        
    end
    
    if app.JERK2Button.Value == 1
        if app.RESULTANTButton.Value == 1
            
            x=1;
            plot(app.PXS1,timeNjj,rghipjj(:,x),'k')
            title(app.PXS1,'DOUBLE JERK RESULTANT')
            xlim(app.PXS1,[0 timeNjj(end)]);
            ylim(app.PXS1,[min(rghipjj(:,x))*1.3 max(rghipjj(:,x))*1.3]); 
            
            plot(app.PXS2,timeNjj,rglthighjj(:,x),'b')
            xlim(app.PXS2,[0 timeNjj(end)]);
            ylim(app.PXS2,[min(rglthighjj(:,x))*1.3 max(rglthighjj(:,x))*1.3]); 
            
            plot(app.PXS3,timeNjj,rgrthighjj(:,x),'r')
            xlim(app.PXS3,[0 timeNjj(end)]);
            ylim(app.PXS3,[min(rgrthighjj(:,x))*1.3 max(rgrthighjj(:,x))*1.3]); 
            
            plot(app.PXS4,timeNjj,rgllegjj(:,x),'b')
            xlim(app.PXS4,[0 timeNjj(end)]);
            ylim(app.PXS4,[min(rgllegjj(:,x))*1.3 max(rgllegjj(:,x))*1.3]); 
            
            plot(app.PXS5,timeNjj,rgrlegjj(:,x),'r')
            xlim(app.PXS5,[0 timeNjj(end)]);
            ylim(app.PXS5,[min(rgrlegjj(:,x))*1.3 max(rgrlegjj(:,x))*1.3]); 
            
            plot(app.PXS6,timeNjj,rglftjj(:,x),'b')
            xlim(app.PXS6,[0 timeNjj(end)]);
            ylim(app.PXS6,[min(rglftjj(:,x))*1.3 max(rglftjj(:,x))*1.3]); 
            
            plot(app.PXS7,timeNjj,rgrftjj(:,x),'r')
            xlim(app.PXS7,[0 timeNjj(end)]);
            ylim(app.PXS7,[min(rgrftjj(:,x))*1.3 max(rgrftjj(:,x))*1.3]); 
            end
            
            if app.XButton.Value == 1
            
            x=1;
            plot(app.PXS1,timeNjj,ghipjj(:,x),'k')
            title(app.PXS1,'DOUBLE JERK X')
            xlim(app.PXS1,[0 timeNjj(end)]);
            ylim(app.PXS1,[min(ghipjj(:,x))*1.3 max(ghipjj(:,x))*1.3]); 
            
            plot(app.PXS2,timeNjj,glthighjj(:,x),'b')
            xlim(app.PXS2,[0 timeNjj(end)]);
            ylim(app.PXS2,[min(glthighjj(:,x))*1.3 max(glthighjj(:,x))*1.3]); 
            
            plot(app.PXS3,timeNjj,grthighjj(:,x),'r')
            xlim(app.PXS3,[0 timeNjj(end)]);
            ylim(app.PXS3,[min(grthighjj(:,x))*1.3 max(grthighjj(:,x))*1.3]); 
            
            plot(app.PXS4,timeNjj,gllegjj(:,x),'b')
            xlim(app.PXS4,[0 timeNjj(end)]);
            ylim(app.PXS4,[min(gllegjj(:,x))*1.3 max(gllegjj(:,x))*1.3]); 
            
            plot(app.PXS5,timeNjj,grlegjj(:,x),'r')
            xlim(app.PXS5,[0 timeNjj(end)]);
            ylim(app.PXS5,[min(grlegjj(:,x))*1.3 max(grlegjj(:,x))*1.3]); 
            
            plot(app.PXS6,timeNjj,glftjj(:,x),'b')
            xlim(app.PXS6,[0 timeNjj(end)]);
            ylim(app.PXS6,[min(glftjj(:,x))*1.3 max(glftjj(:,x))*1.3]); 
            
            plot(app.PXS7,timeNjj,grftjj(:,x),'r')
            xlim(app.PXS7,[0 timeNjj(end)]);
            ylim(app.PXS7,[min(grftjj(:,x))*1.3 max(grftjj(:,x))*1.3]); 
            end
            if app.YButton.Value == 1
            
            x=2;
            plot(app.PXS1,timeNjj,ghipjj(:,x),'k')
            title(app.PXS1,'DOUBLE JERK Y')
            xlim(app.PXS1,[0 timeNjj(end)]);
            ylim(app.PXS1,[min(ghipjj(:,x))*1.3 max(ghipjj(:,x))*1.3]); 
            
            plot(app.PXS2,timeNjj,glthighjj(:,x),'b')
            xlim(app.PXS2,[0 timeNjj(end)]);
            ylim(app.PXS2,[min(glthighjj(:,x))*1.3 max(glthighjj(:,x))*1.3]); 
            
            plot(app.PXS3,timeNjj,grthighjj(:,x),'r')
            xlim(app.PXS3,[0 timeNjj(end)]);
            ylim(app.PXS3,[min(grthighjj(:,x))*1.3 max(grthighjj(:,x))*1.3]); 
            
            plot(app.PXS4,timeNjj,gllegjj(:,x),'b')
            xlim(app.PXS4,[0 timeNjj(end)]);
            ylim(app.PXS4,[min(gllegjj(:,x))*1.3 max(gllegjj(:,x))*1.3]); 
            
            plot(app.PXS5,timeNjj,grlegjj(:,x),'r')
            xlim(app.PXS5,[0 timeNjj(end)]);
            ylim(app.PXS5,[min(grlegjj(:,x))*1.3 max(grlegjj(:,x))*1.3]); 
            
            plot(app.PXS6,timeNjj,glftjj(:,x),'b')
            xlim(app.PXS6,[0 timeNjj(end)]);
            ylim(app.PXS6,[min(glftjj(:,x))*1.3 max(glftjj(:,x))*1.3]); 
            
            plot(app.PXS7,timeNjj,grftjj(:,x),'r')
            xlim(app.PXS7,[0 timeNjj(end)]);
            ylim(app.PXS7,[min(grftjj(:,x))*1.3 max(grftjj(:,x))*1.3]); 
            end
            if app.ZButton.Value == 1
            
            x=3;
            plot(app.PXS1,timeNjj,ghipjj(:,x),'k')
            title(app.PXS1,'DOUBLE JERK Z')
            xlim(app.PXS1,[0 timeNjj(end)]);
            ylim(app.PXS1,[min(ghipjj(:,x))*1.3 max(ghipjj(:,x))*1.3]); 
            
            plot(app.PXS2,timeNjj,glthighjj(:,x),'b')
            xlim(app.PXS2,[0 timeNjj(end)]);
            ylim(app.PXS2,[min(glthighjj(:,x))*1.3 max(glthighjj(:,x))*1.3]); 
            
            plot(app.PXS3,timeNjj,grthighjj(:,x),'r')
            xlim(app.PXS3,[0 timeNjj(end)]);
            ylim(app.PXS3,[min(grthighjj(:,x))*1.3 max(grthighjj(:,x))*1.3]); 
            
            plot(app.PXS4,timeNjj,gllegjj(:,x),'b')
            xlim(app.PXS4,[0 timeNjj(end)]);
            ylim(app.PXS4,[min(gllegjj(:,x))*1.3 max(gllegjj(:,x))*1.3]); 
            
            plot(app.PXS5,timeNjj,grlegjj(:,x),'r')
            xlim(app.PXS5,[0 timeNjj(end)]);
            ylim(app.PXS5,[min(grlegjj(:,x))*1.3 max(grlegjj(:,x))*1.3]); 
            
            plot(app.PXS6,timeNjj,glftjj(:,x),'b')
            xlim(app.PXS6,[0 timeNjj(end)]);
            ylim(app.PXS6,[min(glftjj(:,x))*1.3 max(glftjj(:,x))*1.3]); 
            
            plot(app.PXS7,timeNjj,grftjj(:,x),'r')
            xlim(app.PXS7,[0 timeNjj(end)]);
            ylim(app.PXS7,[min(grftjj(:,x))*1.3 max(grftjj(:,x))*1.3]); 
        end
        
        
    end
    
    end
    
    
    global checkvicon
    
    desf= str2num(app.DIFFERENCEINSECONDS.Value);


    if app.YESButton.Value == 1
        if checkvicon ==1      


[ncti,pp]=size(cutIa);
for nlin=1:ncti
    xline(app.PXS1,timeaa(cutIa(nlin,1))+desf,'-b')
end
[ncti,pp]=size(cutIoa);
for nlin=1:ncti
    xline(app.PXS1,timeaa(cutIoa(nlin,1))+desf,'--b')
end
[ncti,pp]=size(cutI2a);
for nlin=1:ncti
    xline(app.PXS1,timeaa(cutI2a(nlin,1))+desf,'-b')
end

[ncti,pp]=size(cutI2oa);
for nlin=1:ncti
    xline(app.PXS1,timeaa(cutI2oa(nlin,1))+desf,'--b')
end
[ncti,pp]=size(cutRa);
for nlin=1:ncti
    xline(app.PXS1,timeaa(cutRa(nlin,1))+desf,'-r')
end
[ncti,pp]=size(cutRoa);
for nlin=1:ncti
    xline(app.PXS1,timeaa(cutRoa(nlin,1))+desf,'--r')
end
[ncti,pp]=size(cutR2a);
for nlin=1:ncti
    xline(app.PXS1,timeaa(cutR2a(nlin,1))+desf,'-r')
end
[ncti,pp]=size(cutR2oa);

for nlin=1:ncti
    xline(app.PXS1,timeaa(cutR2oa(nlin,1))+desf,'--r')
end
            
   
[ncti,pp]=size(cutIa);
for nlin=1:ncti
    xline(app.PXS2,timeaa(cutIa(nlin,1))+desf,'-k')
end
[ncti,pp]=size(cutIoa);
for nlin=1:ncti
    xline(app.PXS2,timeaa(cutIoa(nlin,1))+desf,'--k')
end
[ncti,pp]=size(cutI2a);
for nlin=1:ncti
    xline(app.PXS2,timeaa(cutI2a(nlin,1))+desf,'-k')
end

[ncti,pp]=size(cutI2oa);
for nlin=1:ncti
    xline(app.PXS2,timeaa(cutI2oa(nlin,1))+desf,'--k')
end

[ncti,pp]=size(cutIa);
for nlin=1:ncti
    xline(app.PXS4,timeaa(cutIa(nlin,1))+desf,'-k')
end
[ncti,pp]=size(cutIoa);
for nlin=1:ncti
    xline(app.PXS4,timeaa(cutIoa(nlin,1))+desf,'--k')
end
[ncti,pp]=size(cutI2a);
for nlin=1:ncti
    xline(app.PXS4,timeaa(cutI2a(nlin,1))+desf,'-k')
end

[ncti,pp]=size(cutI2oa);
for nlin=1:ncti
    xline(app.PXS4,timeaa(cutI2oa(nlin,1))+desf,'--k')
end

[ncti,pp]=size(cutIa);
for nlin=1:ncti
    xline(app.PXS6,timeaa(cutIa(nlin,1))+desf,'-k')
end
[ncti,pp]=size(cutIoa);
for nlin=1:ncti
    xline(app.PXS6,timeaa(cutIoa(nlin,1))+desf,'--k')
end
[ncti,pp]=size(cutI2a);
for nlin=1:ncti
    xline(app.PXS6,timeaa(cutI2a(nlin,1))+desf,'-k')
end

[ncti,pp]=size(cutI2oa);
for nlin=1:ncti
    xline(app.PXS6,timeaa(cutI2oa(nlin,1))+desf,'--k')
end


[ncti,pp]=size(cutRa);
for nlin=1:ncti
    xline(app.PXS3,timeaa(cutRa(nlin,1))+desf,'-k')
end
[ncti,pp]=size(cutRoa);

for nlin=1:ncti
    xline(app.PXS3,timeaa(cutRoa(nlin,1))+desf,'--k')
end
[ncti,pp]=size(cutR2a);
for nlin=1:ncti
    xline(app.PXS3,timeaa(cutR2a(nlin,1))+desf,'-k')
end
[ncti,pp]=size(cutR2oa);
for nlin=1:ncti
    xline(app.PXS3,timeaa(cutR2oa(nlin,1))+desf,'--k')
end


[ncti,pp]=size(cutRa);
for nlin=1:ncti
    xline(app.PXS5,timeaa(cutRa(nlin,1))+desf,'-k')
end
[ncti,pp]=size(cutRoa);
for nlin=1:ncti
    xline(app.PXS5,timeaa(cutRoa(nlin,1))+desf,'--k')
end
[ncti,pp]=size(cutR2a);
for nlin=1:ncti
    xline(app.PXS5,timeaa(cutR2a(nlin,1))+desf,'-k')
end
[ncti,pp]=size(cutR2oa);
for nlin=1:ncti
    xline(app.PXS5,timeaa(cutR2oa(nlin,1))+desf,'--k')
end


[ncti,pp]=size(cutRa);
for nlin=1:ncti
    xline(app.PXS7,timeaa(cutRa(nlin,1))+desf,'-k')
end
[ncti,pp]=size(cutRoa);
for nlin=1:ncti
    xline(app.PXS7,timeaa(cutRoa(nlin,1))+desf,'--k')
end
[ncti,pp]=size(cutR2a);
for nlin=1:ncti
    xline(app.PXS7,timeaa(cutR2a(nlin,1))+desf,'-k')
end
[ncti,pp]=size(cutR2oa);
for nlin=1:ncti
    xline(app.PXS7,timeaa(cutR2oa(nlin,1))+desf,'--k')
end


        end  
    end

            delete(app.verticalLineHandle)
            delete(app.verticalLineHandle2)
            delete(app.verticalLineHandle3)
            delete(app.verticalLineHandle4)
            delete(app.verticalLineHandle5)
            delete(app.verticalLineHandle6)
            delete(app.verticalLineHandle7)
            
            h2 = app.TIMESlider.Value;
            
            app.verticalLineHandle = xline(app.PXS1, h2,'k:');
            app.verticalLineHandle2 = xline(app.PXS2, h2,'k:');
            app.verticalLineHandle3 = xline(app.PXS3, h2,'k:');
            app.verticalLineHandle4 = xline(app.PXS4, h2,'k:');
            app.verticalLineHandle5 = xline(app.PXS5, h2,'k:');
            app.verticalLineHandle6 = xline(app.PXS6, h2,'k:');
            app.verticalLineHandle7 = xline(app.PXS7, h2,'k:');     


        end

        % Button pushed function: COMPARETHETWOSIGNALSButton
        function COMPARETHETWOSIGNALSButtonPushed(app, event)
            %% resultantes


        global checkxsens
        global checkvicon
         
global timeaa
global T
global timeNN
global rfoota
global lfoota
global cutIa
global cutRa
global cutI2a
global cutR2a
global cutI2oa
global cutR2oa
global cutIoa
global cutRoa  

global rarft
global ralft    

global timeG

timeNN = timeG;
if checkxsens ==1 && checkvicon ==1
    
    cla(app.VS1)
    cla(app.VS2)
    cla(app.VS3)
    cla(app.VS4)


timeNN=(0:length(rarft)-1)*T;
% vicon
zvrafacc7=(lfoota);
zvrafacc3=(rfoota);

accx7v = zvrafacc7(:,4) ;   
accy7v = zvrafacc7(:,5) ;   
accz7v = zvrafacc7(:,6) ;  
R7v = sqrt(accx7v.^2+accy7v.^2+accz7v.^2); 

accx3v = zvrafacc3(:,4) ;   
accy3v = zvrafacc3(:,5) ;   
accz3v = zvrafacc3(:,6) ;  
R3v = sqrt(accx3v.^2+accy3v.^2+accz3v.^2); 
%% resultantes gráficas

% 7 - R
% 3 - I
global desf
desf=str2num(app.DIFFERENCEINSECONDS.Value); %-----------------
    
plot(app.VS1,timeNN,ralft,'b');
xlim(app.VS1,[timeNN(1) timeNN(end)])
ylim(app.VS1, "auto")
title(app.VS1,'LEFT-Resultant: lineal acceleration XSENS')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS1,'Acceleration m/s^{2}') 
xlabel(app.VS1,'Time in secs') 
hold (app.VS1,"on")
[ncti,pp]=size(cutIa);
for i=1:ncti
    xline(app.VS1,timeaa(cutIa(i))+desf,'-b')
end
[ncti,pp]=size(cutIoa);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutIoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutI2a);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutI2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutI2oa);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutI2oa(nlin))+desf,'--r')
end


plot(app.VS2,timeNN,rarft,'r');
hold (app.VS2,"on")

[ncti,pp]=size(cutRa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutRa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutRoa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutRoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutR2a);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutR2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutR2oa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutR2oa(nlin))+desf,'--r')
end

xlim(app.VS2,[timeNN(1) timeNN(end)])
ylim(app.VS2, "auto")
title(app.VS2,'RIGHT-Resultant: lineal acceleration XSENS')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS2,'Acceleration m/s^{2}') 
xlabel(app.VS2,'Time in secs') 

plot(app.VS3,timeaa+desf,R7v,'b');
hold (app.VS3,"on")

[ncti,pp]=size(cutIa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutIa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutIoa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutIoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutI2a);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutI2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutI2oa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutI2oa(nlin))+desf,'--r')
end

xlim(app.VS3,[timeNN(1) timeNN(end)])
ylim(app.VS3, "auto")
title(app.VS3,'LEFT-Resultant: lineal acceleration VICON')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS3,'Acceleration m/s^{2}') 
xlabel(app.VS3,'Time in secs') 

plot(app.VS4,timeaa+desf,R3v,'-r');
hold (app.VS4,"on")

[ncti,pp]=size(cutRa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutRa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutRoa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutRoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutR2a);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutR2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutR2oa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutR2oa(nlin,1))+desf,'--r')
end

xlim(app.VS4,[timeNN(1) timeNN(end)])
ylim(app.VS4, "auto")
title(app.VS4,'RIGHT-Resultant: lineal acceleration VICON')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS4,'Acceleration m/s^{2}') 
xlabel(app.VS4,'Time in secs') 

end

        end

        % Button pushed function: GRAPHButton
        function GRAPHButtonPushed(app, event)
            global afacc1

            global alft
            global arft
            global glft
            global grft

global timeaa
global rfoota
global lfoota
global cutIa
global cutRa
global cutI2a
global cutR2a
global cutI2oa
global cutR2oa
global cutIoa
global cutRoa  

global lstep
global lstepo
global rstep
global rstepo
global lgstep
global lgstepo
global rgstep
global rgstepo
global timeNN
global timeG

   global checkxsens
        global checkvicon

%%timeNN = timeG;
if checkxsens ==1 && checkvicon ==1
            
    cla(app.VS1)
    cla(app.VS2)
    cla(app.VS3)
    cla(app.VS4)
%% Lineal------------------------------------------------------------------
% xsens
zrafacc7=(arft);
zrafacc3=(alft);

accx7 = zrafacc7(:,1) ;   
accy7 = zrafacc7(:,2) ;   
accz7 = zrafacc7(:,3) ;  
R7 = sqrt(accx7.^2+accy7.^2+accz7.^2) ;

accx3 = zrafacc3(:,1) ;   
accy3 = zrafacc3(:,2) ;   
accz3 = zrafacc3(:,3) ;  
VS3 = sqrt(accx3.^2+accy3.^2+accz3.^2) ;

% vicon
zvrafacc7=lfoota;
zvrafacc3=rfoota;

accx7v = zvrafacc7(:,4) ;   
accy7v = zvrafacc7(:,5) ;   
accz7v = zvrafacc7(:,6) ;  
R7v = sqrt(accx7v.^2+accy7v.^2+accz7v.^2); 

accx3v = zvrafacc3(:,4) ;   
accy3v = zvrafacc3(:,5) ;   
accz3v = zvrafacc3(:,6) ;  
R3v = sqrt(accx3v.^2+accy3v.^2+accz3v.^2) ;
sepmin = 50;
lstepo = islocalmin(R7v,'MinSeparation',sepmin,'MinProminence',2);
rstepo = islocalmin(R3v,'MinSeparation',sepmin,'MinProminence',2);

sepmin = 50;
lwar=islocalmax(R7v,'MinSeparation',sepmin,'MinProminence',4);
rwar=islocalmax(R3v,'MinSeparation',sepmin,'MinProminence',4);
%% Angular------------------------------------------------------------------
% xsens
zrgfacc7=(grft);
zrgfacc3=(glft);

gccx7 = zrgfacc7(:,1) ;   
gccy7 = zrgfacc7(:,2) ;   
gccz7 = zrgfacc7(:,3) ;  
Rg7 = sqrt(gccx7.^2+gccy7.^2+gccz7.^2) ;

gccx3 = zrgfacc3(:,1) ;   
gccy3 = zrgfacc3(:,2) ;   
gccz3 = zrgfacc3(:,3) ;  
VSg3 = sqrt(gccx3.^2+gccy3.^2+gccz3.^2) ;

% vicon
zvrafacc7=lfoota;
zvrafacc3=rfoota;

gccx7v = zvrafacc7(:,1) ;   
gccy7v = zvrafacc7(:,2) ;   
gccz7v = zvrafacc7(:,3) ;  
Rg7v = sqrt(gccx7v.^2+gccy7v.^2+gccz7v.^2); 

gccx3v = zvrafacc3(:,1) ;   
gccy3v = zvrafacc3(:,2) ;   
gccz3v = zvrafacc3(:,3) ;  
Rg3v = sqrt(gccx3v.^2+gccy3v.^2+gccz3v.^2) ;

sepmin = 50;
lgstepo = islocalmin(Rg7v,'MinSeparation',sepmin,'MinProminence',2);
rgstepo = islocalmin(Rg3v,'MinSeparation',sepmin,'MinProminence',2);

sepmin = 50;
lgwar=islocalmax(Rg7v,'MinSeparation',sepmin,'MinProminence',4);
rgwar=islocalmax(Rg3v,'MinSeparation',sepmin,'MinProminence',4);
%% resultantes gráficas

% 7 - R
% 3 - I
global desf
global T
% desf=app.DIFFERENCEINSECONDS.Value; %-------------------------------------------------------------------
% desf=str2num(desf);
%% Cálculo desfase
RrVn=R3v/max(abs(R3v));
RlVn=R7v/max(abs(R7v));
RlXn=VS3/max(abs(VS3));
RrXn=R7/max(abs(R7));

[crV,lagsV] = xcorr(RrXn,RrVn);

desf=(find(crV/max(crV)==1)+lagsV(1))*T;
if desf<0
    desf=-desf;
end
desf=desf-timeaa(1);
app.DIFFERENCEINSECONDS.Value=num2str(desf);
if app.LINEALB.Value==1
if app.RESULTANTB.Value == 1
    cla(app.VS1)
    cla(app.VS2)
    cla(app.VS3)
    cla(app.VS4)

plot(app.VS1,timeNN,VS3,'b');

hold (app.VS1,"on")

[ncti,pp]=size(cutIa);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutIa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutIoa);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutIoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutI2a);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutI2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutI2oa);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutI2oa(nlin))+desf,'--r')
end

xlim(app.VS1,[timeNN(1) timeNN(end)])
title(app.VS1,'LEFT-Resultant: lineal acceleration XSENS')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS1,'Acceleration m/s^{2}') 
xlabel(app.VS1,'Time in secs') 

plot(app.VS2,timeNN,R7,'r');
hold (app.VS2,"on")
[ncti,pp]=size(cutRa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutRa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutRoa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutRoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutR2a);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutR2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutR2oa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutR2oa(nlin))+desf,'--r')
end

xlim(app.VS2,[timeNN(1) timeNN(end)])
title(app.VS2,'RIGHT-Resultant: lineal acceleration XSENS')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS2,'Acceleration m/s^{2}') 
xlabel(app.VS2,'Time in secs') 

plot(app.VS3,timeaa+desf,R7v,'b');
hold (app.VS3,"on")

[ncti,pp]=size(cutIa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutIa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutIoa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutIoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutI2a);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutI2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutI2oa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutI2oa(nlin))+desf,'--r')
end

xlim(app.VS3,[timeNN(1) timeNN(end)])
title(app.VS3,'LEFT-Resultant: lineal acceleration VICON')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS3,'Acceleration m/s^{2}') 
xlabel(app.VS3,'Time in secs') 

plot(app.VS4,timeaa+desf,R3v,'-r');
hold (app.VS4,"on")

[ncti,pp]=size(cutRa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutRa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutRoa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutRoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutR2a);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutR2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutR2oa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutR2oa(nlin))+desf,'--r')
end

xlim(app.VS4,[timeNN(1) timeNN(end)])
title(app.VS4,'RIGHT-Resultant: lineal acceleration VICON')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS4,'Acceleration m/s^{2}') 
xlabel(app.VS4,'Time in secs') 
end
if app.XB.Value == 1
    cla(app.VS1)
    cla(app.VS2)
    cla(app.VS3)
    cla(app.VS4)
%  X ACC
%  -------------------------------------------------------------------------------------------------------------------------------

plot(app.VS1,timeNN,accx7,'b');
hold (app.VS1,"on")

[ncti,pp]=size(cutIa);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutIa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutIoa);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutIoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutI2a);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutI2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutI2oa);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutI2oa(nlin))+desf,'--r')
end

xlim(app.VS1,[timeNN(1) timeNN(end)])
title(app.VS1,'LEFT-X: lineal acceleration XSENS')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS1,'Acceleration m/s^{2}') 
xlabel(app.VS1,'Time in secs') 

plot(app.VS2,timeNN,accx3,'r');
hold (app.VS2,"on")

[ncti,pp]=size(cutRa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutRa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutRoa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutRoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutR2a);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutR2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutR2oa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutR2oa(nlin))+desf,'--r')
end

xlim(app.VS2,[timeNN(1) timeNN(end)])
title(app.VS2,'RIGHT-X: lineal acceleration XSENS')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS2,'Acceleration m/s^{2}') 
xlabel(app.VS2,'Time in secs') 

plot(app.VS3,timeaa+desf,accx7v,'b');
hold (app.VS3,"on")

[ncti,pp]=size(cutIa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutIa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutIoa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutIoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutI2a);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutI2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutI2oa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutI2oa(nlin))+desf,'--r')
end

xlim(app.VS3,[timeNN(1) timeNN(end)])
title(app.VS3,'LEFT-X: lineal acceleration VICON')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS3,'Acceleration m/s^{2}') 
xlabel(app.VS3,'Time in secs') 

plot(app.VS4,timeaa+desf,accx3v,'-r');
hold (app.VS4,"on")

[ncti,pp]=size(cutRa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutRa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutRoa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutRoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutR2a);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutR2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutR2oa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutR2oa(nlin))+desf,'--r')
end

xlim(app.VS4,[timeNN(1) timeNN(end)])
title(app.VS4,'RIGHT-X: lineal acceleration VICON')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS4,'Acceleration m/s^{2}') 
xlabel(app.VS4,'Time in secs') 
end

if app.YB.Value == 1
    cla(app.VS1)
    cla(app.VS2)
    cla(app.VS3)
    cla(app.VS4)
% Y ACC
% ----------------------------------------------------------------------------------------------------------------------------------------
plot(app.VS1,timeNN,accy7,'b');
hold (app.VS1,"on")

[ncti,pp]=size(cutIa);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutIa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutIoa);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutIoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutI2a);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutI2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutI2oa);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutI2oa(nlin))+desf,'--r')
end

xlim(app.VS1,[timeNN(1) timeNN(end)])
title(app.VS1,'LEFT-Y: lineal acceleration XSENS')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS1,'Acceleration m/s^{2}') 
xlabel(app.VS1,'Time in secs') 

plot(app.VS2,timeNN,accy3,'r');
hold (app.VS2,"on")

[ncti,pp]=size(cutRa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutRa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutRoa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutRoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutR2a);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutR2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutR2oa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutR2oa(nlin,1))+desf,'--r')
end

xlim(app.VS2,[timeNN(1) timeNN(end)])
title(app.VS2,'RIGHT-Y: lineal acceleration XSENS')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS2,'Acceleration m/s^{2}') 
xlabel(app.VS2,'Time in secs') 

plot(app.VS3,timeaa+desf,accy7v,'b');
hold (app.VS3,"on")

[ncti,pp]=size(cutIa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutIa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutIoa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutIoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutI2a);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutI2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutI2oa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutI2oa(nlin))+desf,'--r')
end

xlim(app.VS3,[timeNN(1) timeNN(end)])
title(app.VS3,'LEFT-Y: lineal acceleration VICON')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS3,'Acceleration m/s^{2}') 
xlabel(app.VS3,'Time in secs') 

plot(app.VS4,timeaa+desf,accy3v,'-r');
hold (app.VS4,"on")

[ncti,pp]=size(cutRa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutRa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutRoa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutRoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutR2a);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutR2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutR2oa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutR2oa(nlin))+desf,'--r')
end

xlim(app.VS4,[timeNN(1) timeNN(end)])
title(app.VS4,'RIGHT-Y: lineal acceleration VICON')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS4,'Acceleration m/s^{2}')
xlabel(app.VS4,'Time in secs')
end

% Z ACC
% ----------------------------------------------------------------------------------------------------------------------------------------
if app.ZB.Value == 1
    cla(app.VS1)
    cla(app.VS2)
    cla(app.VS3)
    cla(app.VS4)

plot(app.VS1,timeNN,accz7,'b');
hold (app.VS1,"on")

[ncti,pp]=size(cutIa);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutIa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutIoa);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutIoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutI2a);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutI2a(nlin,1))+desf,'-r')
end
[ncti,pp]=size(cutI2oa);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutI2oa(nlin))+desf,'--r')
end

xlim(app.VS1,[timeNN(1) timeNN(end)])
title(app.VS1,'LEFT-Z: lineal acceleration XSENS')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS1,'Acceleration m/s^{2}') 
xlabel(app.VS1,'Time in secs') 

plot(app.VS2,timeNN,accz3,'r');
hold (app.VS2,"on")

[ncti,pp]=size(cutRa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutRa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutRoa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutRoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutR2a);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutR2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutR2oa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutR2oa(nlin))+desf,'--r')
end

xlim(app.VS2,[timeNN(1) timeNN(end)])
title(app.VS2,'RIGHT-Z: lineal acceleration XSENS')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS2,'Acceleration m/s^{2}') 
xlabel(app.VS2,'Time in secs') 

plot(app.VS3,timeaa+desf,accz7v,'b');
hold (app.VS3,"on")

[ncti,pp]=size(cutIa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutIa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutIoa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutIoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutI2a);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutI2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutI2oa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutI2oa(nlin))+desf,'--r')
end

xlim(app.VS3,[timeNN(1) timeNN(end)])
title(app.VS3,'LEFT-Z: lineal acceleration VICON')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS3,'Acceleration m/s^{2}') 
xlabel(app.VS3,'Time in secs') 

plot(app.VS4,timeaa+desf,accz3v,'-r');
hold (app.VS4,"on")

[ncti,pp]=size(cutRa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutRa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutRoa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutRoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutR2a);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutR2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutR2oa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutR2oa(nlin))+desf,'--r')
end

xlim(app.VS4,[timeNN(1) timeNN(end)])
title(app.VS4,'RIGHT-Z: lineal acceleration VICON')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS4,'Acceleration m/s^{2}')
xlabel(app.VS4,'Time in secs')
end 
else
  if app.RESULTANTB.Value == 1
    cla(app.VS1)
    cla(app.VS2)
    cla(app.VS3)
    cla(app.VS4)

plot(app.VS1,timeNN,VSg3,'b');

hold (app.VS1,"on")

[ncti,pp]=size(cutIa);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutIa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutIoa);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutIoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutI2a);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutI2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutI2oa);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutI2oa(nlin))+desf,'--r')
end

xlim(app.VS1,[timeNN(1) timeNN(end)])
title(app.VS1,'LEFT-Resultant: angular velocity XSENS')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS1,'Velocity deg/s') 
xlabel(app.VS1,'Time in secs') 

plot(app.VS2,timeNN,Rg7,'r');
hold (app.VS2,"on")
[ncti,pp]=size(cutRa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutRa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutRoa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutRoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutR2a);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutR2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutR2oa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutR2oa(nlin))+desf,'--r')
end

xlim(app.VS2,[timeNN(1) timeNN(end)])
title(app.VS2,'RIGHT-Resultant: angular velocity XSENS')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS2,'Velocity deg/s') 
xlabel(app.VS2,'Time in secs') 

plot(app.VS3,timeaa+desf,Rg7v,'b');
hold (app.VS3,"on")

[ncti,pp]=size(cutIa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutIa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutIoa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutIoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutI2a);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutI2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutI2oa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutI2oa(nlin))+desf,'--r')
end

xlim(app.VS3,[timeNN(1) timeNN(end)])
title(app.VS3,'LEFT-Resultant: angular velocity VICON')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS3,'Velocity deg/s') 
xlabel(app.VS3,'Time in secs') 

plot(app.VS4,timeaa+desf,Rg3v,'-r');
hold (app.VS4,"on")

[ncti,pp]=size(cutRa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutRa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutRoa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutRoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutR2a);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutR2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutR2oa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutR2oa(nlin))+desf,'--r')
end

xlim(app.VS4,[timeNN(1) timeNN(end)])
title(app.VS4,'RIGHT-Resultant: angular velocity VICON')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS4,'Velocity deg/s') 
xlabel(app.VS4,'Time in secs') 
end
if app.XB.Value == 1
    cla(app.VS1)
    cla(app.VS2)
    cla(app.VS3)
    cla(app.VS4)
%  X ACC
%  -------------------------------------------------------------------------------------------------------------------------------

plot(app.VS1,timeNN,gccx7,'b');
hold (app.VS1,"on")

[ncti,pp]=size(cutIa);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutIa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutIoa);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutIoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutI2a);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutI2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutI2oa);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutI2oa(nlin))+desf,'--r')
end

xlim(app.VS1,[timeNN(1) timeNN(end)])
title(app.VS1,'LEFT-X: angular velocity XSENS')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS1,'Velocity deg/s') 
xlabel(app.VS1,'Time in secs') 

plot(app.VS2,timeNN,gccx3,'r');
hold (app.VS2,"on")

[ncti,pp]=size(cutRa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutRa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutRoa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutRoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutR2a);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutR2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutR2oa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutR2oa(nlin))+desf,'--r')
end

xlim(app.VS2,[timeNN(1) timeNN(end)])
title(app.VS2,'RIGHT-X: angular velocity XSENS')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS2,'Velocity deg/s') 
xlabel(app.VS2,'Time in secs') 

plot(app.VS3,timeaa+desf,gccx7v,'b');
hold (app.VS3,"on")

[ncti,pp]=size(cutIa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutIa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutIoa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutIoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutI2a);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutI2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutI2oa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutI2oa(nlin))+desf,'--r')
end

xlim(app.VS3,[timeNN(1) timeNN(end)])
title(app.VS3,'LEFT-X: angular velocity VICON')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS3,'Velocity deg/s') 
xlabel(app.VS3,'Time in secs') 

plot(app.VS4,timeaa+desf,gccx3v,'-r');
hold (app.VS4,"on")

[ncti,pp]=size(cutRa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutRa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutRoa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutRoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutR2a);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutR2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutR2oa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutR2oa(nlin))+desf,'--r')
end

xlim(app.VS4,[timeNN(1) timeNN(end)])
title(app.VS4,'RIGHT-X: angular velocity VICON')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS4,'Velocity deg/s') 
xlabel(app.VS4,'Time in secs') 
end

if app.YB.Value == 1
    cla(app.VS1)
    cla(app.VS2)
    cla(app.VS3)
    cla(app.VS4)
% Y ACC
% ----------------------------------------------------------------------------------------------------------------------------------------
plot(app.VS1,timeNN,gccy7,'b');
hold (app.VS1,"on")

[ncti,pp]=size(cutIa);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutIa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutIoa);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutIoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutI2a);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutI2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutI2oa);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutI2oa(nlin))+desf,'--r')
end

xlim(app.VS1,[timeNN(1) timeNN(end)])
title(app.VS1,'LEFT-Y: angular velocity XSENS')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS1,'Velocity deg/s') 
xlabel(app.VS1,'Time in secs') 

plot(app.VS2,timeNN,gccy3,'r');
hold (app.VS2,"on")

[ncti,pp]=size(cutRa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutRa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutRoa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutRoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutR2a);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutR2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutR2oa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutR2oa(nlin,1))+desf,'--r')
end

xlim(app.VS2,[timeNN(1) timeNN(end)])
title(app.VS2,'RIGHT-Y: angular velocity XSENS')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS2,'Velocity deg/s') 
xlabel(app.VS2,'Time in secs') 

plot(app.VS3,timeaa+desf,gccy7v,'b');
hold (app.VS3,"on")

[ncti,pp]=size(cutIa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutIa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutIoa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutIoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutI2a);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutI2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutI2oa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutI2oa(nlin))+desf,'--r')
end

xlim(app.VS3,[timeNN(1) timeNN(end)])
title(app.VS3,'LEFT-Y: angular velocity VICON')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS3,'Velocity deg/s') 
xlabel(app.VS3,'Time in secs') 

plot(app.VS4,timeaa+desf,gccy3v,'-r');
hold (app.VS4,"on")

[ncti,pp]=size(cutRa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutRa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutRoa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutRoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutR2a);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutR2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutR2oa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutR2oa(nlin))+desf,'--r')
end

xlim(app.VS4,[timeNN(1) timeNN(end)])
title(app.VS4,'RIGHT-Y: angular velocity VICON')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS4,'Velocity deg/s')
xlabel(app.VS4,'Time in secs')
end

% Z ACC
% ----------------------------------------------------------------------------------------------------------------------------------------
if app.ZB.Value == 1
    cla(app.VS1)
    cla(app.VS2)
    cla(app.VS3)
    cla(app.VS4)

plot(app.VS1,timeNN,gccz7,'b');
hold (app.VS1,"on")

[ncti,pp]=size(cutIa);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutIa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutIoa);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutIoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutI2a);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutI2a(nlin,1))+desf,'-r')
end
[ncti,pp]=size(cutI2oa);
for nlin=1:ncti
    xline(app.VS1,timeaa(cutI2oa(nlin))+desf,'--r')
end

xlim(app.VS1,[timeNN(1) timeNN(end)])
title(app.VS1,'LEFT-Z: angular velocity XSENS')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS1,'Velocity deg/s') 
xlabel(app.VS1,'Time in secs') 

plot(app.VS2,timeNN,gccz3,'r');
hold (app.VS2,"on")

[ncti,pp]=size(cutRa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutRa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutRoa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutRoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutR2a);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutR2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutR2oa);
for nlin=1:ncti
    xline(app.VS2,timeaa(cutR2oa(nlin))+desf,'--r')
end

xlim(app.VS2,[timeNN(1) timeNN(end)])
title(app.VS2,'RIGHT-Z: angular velocity XSENS')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS2,'Velocity deg/s') 
xlabel(app.VS2,'Time in secs') 

plot(app.VS3,timeaa+desf,gccz7v,'b');
hold (app.VS3,"on")

[ncti,pp]=size(cutIa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutIa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutIoa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutIoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutI2a);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutI2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutI2oa);
for nlin=1:ncti
    xline(app.VS3,timeaa(cutI2oa(nlin))+desf,'--r')
end

xlim(app.VS3,[timeNN(1) timeNN(end)])
title(app.VS3,'LEFT-Z: angular velocity VICON')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS3,'Velocity deg/s') 
xlabel(app.VS3,'Time in secs') 

plot(app.VS4,timeaa+desf,gccz3v,'-r');
hold (app.VS4,"on")

[ncti,pp]=size(cutRa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutRa(nlin))+desf,'-b')
end
[ncti,pp]=size(cutRoa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutRoa(nlin))+desf,'--b')
end
[ncti,pp]=size(cutR2a);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutR2a(nlin))+desf,'-r')
end
[ncti,pp]=size(cutR2oa);
for nlin=1:ncti
    xline(app.VS4,timeaa(cutR2oa(nlin))+desf,'--r')
end

xlim(app.VS4,[timeNN(1) timeNN(end)])
title(app.VS4,'RIGHT-Z: angular velocity VICON')
% legend ({'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.VS4,'Velocity deg/s')
xlabel(app.VS4,'Time in secs')  
end
end
end
        end

        % Display data changed function: TA
        function TADisplayDataChanged(app, event)
            newDisplayData = app.TA.DisplayData;
            
        end

        % Callback function: TIMESlider, TIMESlider
        function TIMESliderValueChanged2(app, event)
            value = app.TIMESlider.Value;
            
            
            global timeN
            
            
            h2 = value;
            
            delete(app.verticalLineHandle)
            delete(app.verticalLineHandle2)
            delete(app.verticalLineHandle3)
            delete(app.verticalLineHandle4)
            delete(app.verticalLineHandle5)
            delete(app.verticalLineHandle6)
            delete(app.verticalLineHandle7)


            % acc
            app.verticalLineHandle = xline(app.PXS1, h2,'k:');
            app.verticalLineHandle2 = xline(app.PXS2, h2,'k:');
            app.verticalLineHandle3 = xline(app.PXS3, h2,'k:');
            app.verticalLineHandle4 = xline(app.PXS4, h2,'k:');
            app.verticalLineHandle5 = xline(app.PXS5, h2,'k:');
            app.verticalLineHandle6 = xline(app.PXS6, h2,'k:');
            app.verticalLineHandle7 = xline(app.PXS7, h2,'k:');


            app.EditField.Value=h2;  
            
         global h2
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged2(app, event)
             global h2 
            
           h2 = app.EditField.Value; 
           app.TIMESlider.Value=h2;
           
          delete(app.verticalLineHandle)
            delete(app.verticalLineHandle2)
            delete(app.verticalLineHandle3)
            delete(app.verticalLineHandle4)
            delete(app.verticalLineHandle5)
            delete(app.verticalLineHandle6)
            delete(app.verticalLineHandle7)


            % acc
            app.verticalLineHandle = xline(app.PXS1, h2,'k:');
            app.verticalLineHandle2 = xline(app.PXS2, h2,'k:');
            app.verticalLineHandle3 = xline(app.PXS3, h2,'k:');
            app.verticalLineHandle4 = xline(app.PXS4, h2,'k:');
            app.verticalLineHandle5 = xline(app.PXS5, h2,'k:');
            app.verticalLineHandle6 = xline(app.PXS6, h2,'k:');
            app.verticalLineHandle7 = xline(app.PXS7, h2,'k:');


        end

        % Button pushed function: GRAPHICButton
        function GRAPHICButtonPushed(app, event)
            global fV
            global cutRX
            global cutRXo
            global cutIX
            global cutIXo
            global timeN
            global T
            global grft
            global arft
            global glft
            global alft


            %Eventos pie derecho
            
         %Despegue de dedos
            [~,lcs] = findpeaks(grft(:,2));
            short = mean(diff(lcs));
            [pk,lc] = findpeaks(grft(:,2)/max(grft(:,2)), ...
                'MinPeakDistance',ceil(short/100)*.75*fV,'MinPeakheight',0.3);
            cutRXo=lc;
            %Choque de talón
            [pks,lcs] = findpeaks(arft(:,2));
            short = mean(diff(lcs));
            [pk,lc] = findpeaks(-arft(:,2)/max(-arft(:,2)), ...
                'MinPeakDistance',ceil(short/100)*.75*fV,'MinPeakheight',0.3);
            cutRX=lc;
            while cutRX(1)<cutRXo(1)
                cutRX=cutRX(2:end);
            end
            while cutRX(end)<cutRXo(end)
                cutRXo=cutRXo(1:end-1);
            end
            if  length(cutRXo)>1
                for i=1:length(cutRXo)-1
                    if cutRX(i)>cutRXo(i+1)
                        cutRXo(i)=[];
                    end
                    if cutRXo(i)==cutRXo(end)
                        break;
                    end
                    if cutRXo(i+1)==cutRXo(end)
                        break;
                    end
                end
            end
            if length(cutRX)>length(cutRXo)
                for i=1:length(cutRXo)
                    if cutRX(i)<cutRXo(i)
                        cutRX(i)=[];
                    end
                end
            end
              if length(cutRX)>length(cutRXo)
                while cutRX(end-1)>cutRXo(end)
                    cutRX(end)=[];
                end
              end
                
               %Eventos pie izquierdo
            
         %Despegue de dedos
            [~,lcs] = findpeaks(glft(:,2));
            short = mean(diff(lcs));
            [pk,lc] = findpeaks(glft(:,2)/max(glft(:,2)), ...
                'MinPeakDistance',ceil(short/100)*.75*fV,'MinPeakheight',0.3);
            cutIXo=lc;
            %Choque de talón
            [pks,lcs] = findpeaks(alft(:,2));
            short = mean(diff(lcs));
            [pk,lc] = findpeaks(-alft(:,2)/max(-alft(:,2)), ...
                'MinPeakDistance',ceil(short/100)*.75*fV,'MinPeakheight',0.3);
            cutIX=lc;
            while cutIX(1)<cutIXo(1)
                cutIX=cutIX(2:end);
            end
            while cutIX(end)<cutIXo(end)
                cutIXo=cutIXo(1:end-1);
            end
            if  length(cutIXo)>1
                for i=1:length(cutIXo)-1
                    if cutIX(i)>cutIXo(i+1)
                        cutIXo(i)=[];
                    end
                    if cutIXo(i)==cutIXo(end)
                        break;
                    end
                    if cutIXo(i+1)==cutIXo(end)
                        break;
                    end
                end
            end
            if length(cutIX)>length(cutIXo)
                for i=1:length(cutIXo)
                    if cutIX(i)<cutIXo(i)
                        cutIX(i)=[];
                    end
                end
            end
              if length(cutIX)>length(cutIXo)
                while cutIX(end-1)>cutIXo(end)
                    cutIX(end)=[];
                end
              end
            timeN=(0:length(grft)-1)*T;  
            value = app.FOOTListBox.Value;
            if value=="RIGTH"
                plot(app.UIAV,timeN,grft(:,2),'k',"LineWidth",1.5)
                plot(app.UILA,timeN,-arft(:,2),'k',"LineWidth",1.5)
                for i=1:length(cutRX)
                    xline(app.UIAV,timeN(cutRX(i)),'b--',"LineWidth",1.5)
                    xline(app.UIAV,timeN(cutRXo(i)),'r--',"LineWidth",1.5)
                end
                for i=1:length(cutRX)
                    xline(app.UILA,timeN(cutRX(i)),'b--',"LineWidth",1.5)
                    xline(app.UILA,timeN(cutRXo(i)),'r--',"LineWidth",1.5)
                end
            end
            if value=="LEFT"
                plot(app.UIAV,timeN,glft(:,2),'k',"LineWidth",1.5)
                plot(app.UILA,timeN,-alft(:,2),'k',"LineWidth",1.5)
                for i=1:length(cutIX)
                    xline(app.UIAV,timeN(cutIX(i)),'b--',"LineWidth",1.5)
                    xline(app.UIAV,timeN(cutIXo(i)),'r--',"LineWidth",1.5)
                end
                for i=1:length(cutIX)
                    xline(app.UILA,timeN(cutIX(i)),'b--',"LineWidth",1.5)
                    xline(app.UILA,timeN(cutIXo(i)),'r--',"LineWidth",1.5)
                end
            end
            
            
        end

        % Value changed function: LOADFILESButton
        function LOADFILESButtonValueChanged(app, event)
            value = app.LOADFILESButton.Value;
global fV
global T
global timea
global cutRo
global cutR
global cutR2o
global cutR2
global cutIo
global cutI
global cutI2o
global cutI2
global lh_z
global rh_z
global lt_z2
global rt_z2
global time
global lt_z
global rt_z
global lstep
global rstep
global lstepo
global rstepo
global slh_z
global oname
global events
            
            try
oname= dir;                
dname = uigetdir('default','Files');
cd(dname)
listing = dir('*.xlsx');
FE=find(contains(lower({listing.name}),'events'));
datasete=readtable(fullfile(listing(FE(1)).name));
eventst=datasete(:,{'Context','Name','Time_s_'});
eventst=table2cell(eventst);
eventst=sortrows(eventst,3);
events=xlsread(fullfile(listing(FE(1)).name));
[sevents,m]=size(events);
events=sortrows(events);
%Read and store variables (time, acceleration and gyroscope from IMU)

FT=find(contains(lower({listing.name}),'trajectories'));
dataset = xlsread(fullfile(listing(FT(1)).name));

asize = size(listing(FT(1)).name);
anum = asize(1,2)-8;
aname = listing(FT(1)).name;

time=dataset(:,1);
% timea=dataset(3:end,1);
timea=time/100; % Cambiar la columna 1 por la de los tiempos nuevos y ya no dividir entre 1000
if timea(1)>events(1)
    timea=timea/2;
end
T=mean(diff(timea));
fV=1/T;

global la_x
la_x = dataset(:,3)/10;
global la_y
la_y = dataset(:,4)/1000;
global la_z
la_z = dataset(:,5)/10;
global lh_x
lh_x = dataset(:,6)/10+58;
global lh_y
lh_y = dataset(:,7)/1000;
global lh_z
lh_z = dataset(:,8)/10;
global lt_x
lt_x = dataset(:,9)/10;
global lt_y
lt_y = dataset(:,10)/1000;
global lt_z
lt_z = dataset(:,11)/10;

global ra_x
ra_x = dataset(:,12)/10;
global ra_y
ra_y = dataset(:,13)/1000;
global ra_z
ra_z = dataset(:,14)/10;
global rh_x
rh_x = dataset(:,15)/10+58;
global rh_y
rh_y = dataset(:,16)/1000;
global rh_z
rh_z = dataset(:,17)/10;
global rt_x
rt_x = dataset(:,18)/10;
global rt_y
rt_y = dataset(:,19)/1000;
global rt_z
rt_z = dataset(:,20)/10;

global stimea

[stimea,nm]=size(timea);


catch
    warning('Error en el archivo de EVENTOS');
    a = 0;
    uiwait(msgbox('Error en el archivo de EVENTOS','ERROR','modal'));
end
                  
         
    %% IDENTIFICACIÓN DE EVENTOS
id=1;
id2=1;
ii=1;
ii2=1;
ip=1;
events=round(events,2);
s=string(eventst);

%CHOQUE--------------------------------------------------------------------
a="Right";
% a=string(a);
b="Left";
% b=string(b);
c="Foot Strike";
% c=string(c);

for m=1:stimea
    for n=1:sevents
        if events(n,1) == timea(m,1)
            if isequal(s(n,1),b)
                if isequal(s(n,2),c)
            cutI(id,1)=m;
            id=id+1;
                else
            cutIo(id2,1)=m;
            id2=id2+1;
                end
            elseif isequal(s(n,1),a)
                if isequal(s(n,2),c)
            cutR(ii,1)=m;
            ii=ii+1;
                else
                cutRo(ii2,1)=m;
            ii2=ii2+1;    
            end
            end
        end
    end
end
[sizeci,xs]=size(cutI);
[sizecd,xs]=size(cutR);

if min(cutI) < min(cutR)
    orderstep = 1; %empezo con izq
else
    orderstep = 2;
end

    
 [sizecio,xs]=size(cutIo);
 [sizecdo,xs]=size(cutRo);


%--------------------------------------------------------------------------
lh_z2=(lh_z)-min(lh_z);
tr=5;
for m=1:size(lh_z,1)
    if lh_z2(m,1) >= tr
        lh_z2(m,1)=tr;
    end
end
rh_z2=(rh_z)-min(rh_z);
for m=1:size(rh_z,1)
    if rh_z2(m,1) >= tr
        rh_z2(m,1)=tr;
    end
end


%--------------------------------------------------------------------------
lt_z2=(lt_z)-min(lt_z);

tr=2;
for m=1:size(lt_z,1)
    
    if lt_z2(m,1) >= tr
        lt_z2(m,1)=tr;
    end
end


rt_z2=(rt_z)-min(rt_z);
for m=1:size(rt_z,1)
    
    if rt_z2(m,1) >= tr
        rt_z2(m,1)=tr;
    end
end

[slh_z,mm] = size(lh_z);


sepmin = 75
lstep = islocalmin(lh_z2,'MinSeparation',sepmin);
rstep = islocalmin(rh_z2,'MinSeparation',sepmin);


minssl=80
minssr=80

lstepo = islocalmin(lt_z2,'MinSeparation',minssl);
rstepo = islocalmin(rt_z2,'MinSeparation',minssr);


cutI2=find(lstep==1); %foot strike x matlab
cutR2=find(rstep==1); %foot strike x matlab
cutI2o=find(lstepo==1); %foot off x matlab
cutR2o=find(rstepo==1); %foot off x matlab

cla(app.BUGSH)
cla(app.BUGST)

plot(app.BUGSLEFT,timea,lh_z,'r')
ylim(app.BUGSLEFT,[-.05 max(lh_z)+3])
xlim(app.BUGSLEFT,[timea(1) timea(slh_z)])
title(app.BUGSLEFT,'AXIS Z: Foot Strike (Heel)')
hold (app.BUGSLEFT, 'on')
plot(app.BUGSLEFT,timea,rh_z,'b')
plot(app.BUGSLEFT,timea(cutI),lh_z(cutI),'k+')
plot(app.BUGSLEFT,timea(lstep),lh_z(lstep),'kx')
plot(app.BUGSLEFT,timea(rstep),rh_z(rstep),'bx')
plot(app.BUGSLEFT,timea(cutR),rh_z(cutR),'b+')
legend (app.BUGSLEFT,{'Left' 'Right' 'Foot strike VICON' 'Foot strike MATLAB'}, 'Location','northwest','NumColumns',2)
ylabel(app.BUGSLEFT,'Height in cm') 
xlabel(app.BUGSLEFT,'Time in secs') 

plot(app.BUGSRIGHT,timea,lt_z,'r')
ylim(app.BUGSRIGHT,[-.05 max(lt_z)+3])
xlim(app.BUGSRIGHT,[timea(1) timea(slh_z)])
title(app.BUGSRIGHT,'AXIS Z: Foot Off (Toe)')
hold (app.BUGSRIGHT, 'on')
plot(app.BUGSRIGHT,timea,rt_z,'b')
plot(app.BUGSRIGHT,timea(cutIo),lt_z(cutIo),'ks')
plot(app.BUGSRIGHT,timea(lstepo),lt_z(lstepo),'ko')
plot(app.BUGSRIGHT,timea(rstepo),rt_z(rstepo),'ko')
plot(app.BUGSRIGHT,timea(cutRo),rt_z(cutRo),'ks')
legend (app.BUGSRIGHT,{'Left' 'Right' 'Foot off VICON' 'Foot off MATLAB'}, 'Location','northwest','NumColumns',2)
ylabel(app.BUGSRIGHT,'Height in cm') 
xlabel(app.BUGSRIGHT,'Time in secs') 

plot(app.BUGSH,timea,lh_z2,'r')
ylim(app.BUGSH,[-.05 7])
xlim(app.BUGSH,[timea(1) timea(slh_z)])
title(app.BUGSH,'HEEL')
hold (app.BUGSH, 'on')
plot(app.BUGSH,timea,rh_z2,'b')
plot(app.BUGSH,timea(cutI),lh_z2(cutI),'k+')
plot(app.BUGSH,timea(lstep),lh_z2(lstep),'kx')
plot(app.BUGSH,timea(rstep),rh_z2(rstep),'bx')
plot(app.BUGSH,timea(cutR),rh_z2(cutR),'b+')
legend (app.BUGSH,{'Left' 'Right' 'Foot strike VICON' 'Foot strike MATLAB'}, 'Location','northwest','NumColumns',2)
ylabel(app.BUGSH,'Height in cm') 
xlabel(app.BUGSH,'Time in secs') 

plot(app.BUGST,timea,lt_z2,'r')
ylim(app.BUGST,[-.05 3])
xlim(app.BUGST,[timea(1) timea(slh_z)])
title(app.BUGST,'TOE')
hold (app.BUGST, 'on')
plot(app.BUGST,timea,rt_z2,'b')
plot(app.BUGST,timea(cutIo),lt_z2(cutIo),'ks')
plot(app.BUGST,timea(lstepo),lt_z2(lstepo),'ko')
plot(app.BUGST,timea(rstepo),rt_z2(rstepo),'ko')
plot(app.BUGST,timea(cutRo),rt_z2(cutRo),'ks')
legend (app.BUGST,{'Left' 'Right' 'Foot off VICON' 'Foot off MATLAB'}, 'Location','northwest','NumColumns',2)
ylabel(app.BUGST,'Height in cm') 
xlabel(app.BUGST,'Time in secs') 

[comp1, xx] = size(cutI);
[comp11, xx] = size(cutI2);
[comp2, xx] = size(cutR);
[comp22, xx] = size(cutR2);
[comp3, xx] = size(cutIo);
[comp33, xx] = size(cutI2o);
[comp4, xx] = size(cutRo);
[comp44, xx] = size(cutR2o);

if comp1 ~= comp11 || comp2 ~= comp22 || comp3 ~= comp33 || comp4 ~= comp44
    uiwait(msgbox('Error identificando eventos','ERROR','modal'));
    uiwait(msgbox('Ve al BUGS','ERROR','modal'));
else
end


%% IMPRESIÓN DE GRÁFICAS

try
plot(app.HOME1,timea,lh_z,'r',timea(cutI),lh_z(cutI),'k*')
ylim(app.HOME1,[-.05 max(lh_z)+3])
xlim(app.HOME1,[timea(1) timea(slh_z)])
title(app.HOME1,'AXIS Z')
hold(app.HOME1,"on")
plot(app.HOME1,timea,rh_z,'b',timea(cutRo),rh_z(cutRo),'ko')
plot(app.HOME1,timea,rh_z,'b',timea(cutR),rh_z(cutR),'k*')
plot(app.HOME1,timea,lh_z,'r',timea(cutIo),lh_z(cutIo),'ko')
ylabel(app.HOME1,'Height in cm') 
xlabel(app.HOME1,'Time in secs') 

minlhy=min(lh_y);

minrhy=min(rh_y);

if minlhy < minrhy
    lh_y=lh_y-minlhy;
    rh_y=rh_y-minlhy;
else
   lh_y=lh_y-minrhy;
   rh_y=rh_y-minrhy;
end

plot(app.HOME2,timea,lh_y,'r',timea(cutI),lh_y(cutI),'k*')
ylim(app.HOME2,[-.05 max(lh_y)+.5])
xlim(app.HOME2,[timea(1) timea(slh_z)])
title(app.HOME2,'AXIS Y')
hold(app.HOME2,"on")
plot(app.HOME2,timea,rh_y,'b',timea(cutRo),rh_y(cutRo),'ko')
plot(app.HOME2,timea,rh_y,'b',timea(cutR),rh_y(cutR),'k*')
plot(app.HOME2,timea,lh_y,'r',timea(cutIo),lh_y(cutIo),'ko')
legend (app.HOME2,{'Left' 'Foot strike' 'Right' 'Foot off'}, 'Location','northwest','NumColumns',2)
ylabel(app.HOME2,'Distance in m') 
xlabel(app.HOME2,'Time in secs') 

if min(lh_x) <= min(rh_x)
   liminfejex = min(lh_x);
else
   liminfejex = min(rh_x);
end

if max(lh_x) <= max(rh_x)
   limsupejex = max(rh_x);
else
   limsupejex = max(lh_x);
end

plot(app.HOME3,timea,lh_x,'r',timea(cutI),lh_x(cutI),'r*')
ylim(app.HOME3,[liminfejex-3 limsupejex+3])
xlim(app.HOME3,[timea(1) timea(slh_z)])
title(app.HOME3,'AXIS X')
hold(app.HOME3,"on")
plot(app.HOME3,timea,rh_x,'b',timea(cutRo),rh_x(cutRo),'ko')
plot(app.HOME3,timea,rh_x,'b',timea(cutR),rh_x(cutR),'k*')
plot(app.HOME3,timea,lh_x,'r',timea(cutIo),lh_x(cutIo),'ko')
ylabel(app.HOME3,'Height in mm') 
xlabel(app.HOME3,'Time in secs') 


% matlab

plot(app.HOME4,timea,lh_z,'r',timea(lstep),lh_z(lstep),'r*')
ylim(app.HOME4,[-.05 max(lh_z)+3])
xlim(app.HOME4,[timea(1) timea(slh_z)])
title(app.HOME4,'AXIS Z')
hold(app.HOME4,"on")
plot(app.HOME4,timea,rh_z,'b',timea(rstepo),rh_z(rstepo),'ko')
plot(app.HOME4,timea,rh_z,'b',timea(rstep),rh_z(rstep),'k*')
plot(app.HOME4,timea,lh_z,'r',timea(lstepo),lh_z(lstepo),'ko')
ylabel(app.HOME4,'Height in cm') 
xlabel(app.HOME4,'Time in secs') 

plot(app.HOME5,timea,lh_y,'r',timea(lstep),lh_y(lstep),'r*')
ylim(app.HOME5,[-.05 max(lh_y)+.5])
xlim(app.HOME5,[timea(1) timea(slh_z)])
title(app.HOME5,'AXIS Y')
hold(app.HOME5,"on")
plot(app.HOME5,timea,rh_y,'b',timea(rstepo),rh_y(rstepo),'ko')
plot(app.HOME5,timea,rh_y,'b',timea(rstep),rh_y(rstep),'k*')
plot(app.HOME5,timea,lh_y,'r',timea(lstepo),lh_y(lstepo),'ko')
ylabel(app.HOME5,'Distance in m') 
xlabel(app.HOME5,'Time in secs') 

plot(app.HOME6,timea,lh_x,'r',timea(lstep),lh_x(lstep),'r*')
ylim(app.HOME6,[liminfejex-3 limsupejex+3])
xlim(app.HOME6,[timea(1) timea(slh_z)])
title(app.HOME6,'AXIS X')
hold(app.HOME6,"on")
plot(app.HOME6,timea,rh_x,'b',timea(rstepo),rh_x(rstepo),'ko')
plot(app.HOME6,timea,rh_x,'b',timea(rstep),rh_x(rstep),'k*')
plot(app.HOME6,timea,lh_x,'r',timea(lstepo),lh_x(lstepo),'ko')
ylabel(app.HOME6,'Height in mm') 
xlabel(app.HOME6,'Time in secs') 
catch
    warning('Error identificando eventos');
    a = 0;
    uiwait(msgbox('Error identificando eventos','ERROR','modal'));
end  
try   
%% CÁLCULO DE PARÁMETROS DE LA MARCHA

%STRIDE--------------------------------------------------------------------
n=1;
xn=1;
c=1;
while n<= slh_z
    
    if n == cutI(xn,1) && xn+1 <= length(cutI)
        left_stride(c,1)=lh_y(cutI(xn+1,1))-lh_y(cutI(xn,1));
        left_stride(c,2)=timea(cutI(xn+1,1))-timea(cutI(xn,1));
        c=c+1;
        xn=xn+1;
    end
    
    n=n+1;
end

[srh_z,mm] = size(rh_z);

n=1;
xn=1;
c=1;
while n<= srh_z
    
    if n == cutR(xn,1) && xn+1 <= length(cutR)
        right_stride(c,1)=rh_y(cutR(xn+1,1))-rh_y(cutR(xn,1));
        right_stride(c,2)=timea(cutR(xn+1,1))-timea(cutR(xn,1));
        c=c+1;
        xn=xn+1;
    end
    
    n=n+1;
end
%[left_stride,right_stride] = stride(cutI,cutR,timea,slh_z,rh_z,lh_y,rh_y);
if size(left_stride,1)>1
avg_left_stride=mean(left_stride);
else
avg_left_stride=left_stride;
end

if size(right_stride,1)>1
avg_right_stride=mean(right_stride);
else
avg_right_stride=right_stride;
end
%--------------------------------------------------------------------------
%STEP----------------------------------------------------------------------
steps=cat(1,cutI,cutR);
steps=sortrows(steps);
n=1;
mi=1;
mr=1;
ar=1;
al=1;
while n <= length(steps) 

    if mi <= length(cutI)
        if steps(n,1) == cutI(mi,1) && n+1 <= length(steps)
      right_step(ar,1)= rh_y(steps(n+1,1))-lh_y(steps(n,1));
      right_step(ar,2)= timea(steps(n+1,1))-timea(steps(n,1));
      ar=ar+1;
      mi=mi+1;
        end
    end
    if mr <= length(cutR)
        if steps(n,1) == cutR(mr,1) && n+1 <= length(steps)
      left_step(al,1)= lh_y(steps(n+1,1))-rh_y(steps(n,1));
      left_step(al,2)= timea(steps(n+1,1))-timea(steps(n,1));
      al=al+1;
      mr=mr+1;
        end
    end
    n=n+1;
end

%[left_step,right_step] = step(steps,timea,cutI,cutR,lh_y,rh_y);

left_step(:,1)=left_step(:,1);
right_step(:,1)=right_step(:,1);

[tam1,tam2]=size(left_step);
if tam1 > 1
    avg_left_step=mean(left_step);
else
    avg_left_step=(left_step);
end

[tam1,tam2]=size(right_step);
if tam1 > 1
    avg_right_step=mean(right_step);
else
    avg_right_step=(right_step);
end
%--------------------------------------------------------------------------
%WIDTH----------------------------------------------------------------------
n=1;
mi=1;
mr=1;
ar=1;
al=1;
while n <= length(steps) 

    if mi <= length(cutI)
        if steps(n,1) == cutI(mi,1)
      left_width(ar,1)= abs(rh_x(cutI(mi,1))-lh_x(cutI(mi,1)));
      ar=ar+1;
      mi=mi+1;
    end
    end
    if mr <= length(cutR)
        if steps(n,1) == cutR(mr,1)
      right_width(al,1)= abs(lh_x(cutR(mr,1))-rh_x(cutR(mr,1)));
      al=al+1;
      mr=mr+1;
        end
    end
    n=n+1;
end
%[left_width,right_width] = width(steps,timea,cutI,cutR,lh_x,rh_x);

avg_left_width=mean(left_width);
avg_right_width=mean(right_width);
%--------------------------------------------------------------------------
%SDSS----------------------------------------------------------------------

% [stance_right,stance_left,swing_right,swing_left,ss_left_time,ss_right_time,ds_left_time,ds_right_time] = GC(slh_z,cutI,cutR,cutIo,cutRo,timea);
%% 
icut=sort(cat(1,cutI,cutIo));
dcut=sort(cat(1,cutR,cutRo));

posi_left= zeros(slh_z,1);
posi_right= zeros(slh_z,1);

%% left
iinicio=lh_z(icut(1))-lh_z(icut(2));
if iinicio < 0
   %empezó con foot strike
   i_start = 2;
else
    %empezó con foot off
   i_start = 1;
end

if i_start == 1
n=1;
m=2;
posi_left(1:icut(1,1),1)=0;
while n <= size(icut,1)
    if m<=size(icut,1);
posi_left(icut(n,1):icut(m,1),1)=1;
n=n+2;
m=m+2;
    else
posi_left(icut(n,1):slh_z,1)=1;  
n=n+2;
m=m+2;
    end
end
end

if i_start == 2
posi_left(:,1)=1;
n=1;
m=2;
posi_left(1:icut(1,1),1)=1;
while n <= size(icut,1)
    if m<=size(icut,1);
posi_left(icut(n,1):icut(m,1),1)=0;
n=n+2;
m=m+2;
    else
posi_left(icut(n,1):slh_z,1)=0;  
n=n+2;
m=m+2;
    end
end
end

%% BUG
plot(app.PAR1,timea,posi_left,'r')
ylim(app.PAR1,[-.05 1.05])
xlim(app.PAR1,[timea(1) timea(slh_z)])
title(app.PAR1,'Stance-Swing Left')
ylabel(app.PAR1,'Position') 
xlabel(app.PAR1,'Time in secs') 
yticks(app.PAR1,[0 1])
yticklabels(app.PAR1,{'Stance','Swing'})

%% right

dinicio=rh_z(dcut(1))-rh_z(dcut(2));
if dinicio < 0
   %empezó con foot strike
   r_start = 2;
else
    %empezó con foot off
   r_start = 1;
end

if r_start == 1
n=1;
m=2;
posi_right(1:dcut(1,1),1)=0;
while n <= size(dcut,1)
if m<=size(dcut,1);
posi_right(dcut(n,1):dcut(m,1),1)=1;
n=n+2;
m=m+2;
    else
posi_right(dcut(n,1):slh_z,1)=1;  
n=n+2;
m=m+2;
    end
end
end

if r_start == 2
posi_right(:,1)=1;
n=1;
m=2;
posi_right(1:dcut(1,1),1)=1;
while n <= size(dcut,1)
    if m<=size(dcut,1);
posi_right(dcut(n,1):dcut(m,1),1)=0;
n=n+2;
m=m+2;
    else
posi_right(dcut(n,1):slh_z,1)=0;  
n=n+2;
m=m+2;
    end
end
end

plot(app.PAR2,timea,posi_right,'b')
ylim(app.PAR2,[-.05 1.05])
xlim(app.PAR2,[timea(1) timea(slh_z)])
title(app.PAR2,'Stance-Swing right')
ylabel(app.PAR2,'Position') 
xlabel(app.PAR2,'Time in secs') 
yticks(app.PAR2,[0 1])
yticklabels(app.PAR2,{'Stance','Swing'})

%% single support
sin_support_left=zeros(slh_z,1);
sin_support_right=zeros(slh_z,1);

%left

sin_support_left(1:slh_z,1)=1;
for lnn=1:slh_z
if posi_left(lnn,1) == 0 && posi_right(lnn,1) == 1
    
    sin_support_left(lnn,1) = 0;
    
end
end

plot(app.PAR3,timea,sin_support_left,'r')
ylim(app.PAR3,[-.05 1.05])
xlim(app.PAR3,[timea(1) timea(slh_z)])
title(app.PAR3,'Single Support Left')
ylabel(app.PAR3,'Position') 
xlabel(app.PAR3,'Time in secs') 
yticks(app.PAR3,[0 1])
yticklabels(app.PAR3,{'Stance','Swing'})

%right
sin_support_right(1:slh_z,1)=1;
for rnn=1:slh_z
if posi_left(rnn,1) == 1 && posi_right(rnn,1) == 0
    
    sin_support_right(rnn,1) = 0;
    
end
end

plot(app.PAR4,timea,sin_support_right,'b')
ylim(app.PAR4,[-.05 1.05])
xlim(app.PAR4,[timea(1) timea(slh_z)])
title(app.PAR4,'Single Support Right')
ylabel(app.PAR4,'Position') 
xlabel(app.PAR4,'Time in secs') 
yticks(app.PAR4,[0 1])
yticklabels(app.PAR4,{'Stance','Swing'})

%% double support

dou_support_left=zeros(slh_z,1);
dou_support_right=zeros(slh_z,1);

%left
dou_support_left(1:slh_z,1)=1;
for lnn=1:slh_z
if posi_left(lnn,1) == 0 && posi_right(lnn,1) == 0
    
    dou_support_left(lnn,1) = 0;
    
end
end

plot(app.PAR5,timea,dou_support_left,'r')
ylim(app.PAR5,[-.05 1.05])
xlim(app.PAR5,[timea(1) timea(slh_z)])
title(app.PAR5,'Double Support Left')
ylabel(app.PAR5,'Position') 
xlabel(app.PAR5,'Time in secs') 
yticks(app.PAR5,[0 1])
yticklabels(app.PAR5,{'Stance','Swing'})

%right
dou_support_right(1:slh_z,1)=1;
for rnn=1:slh_z
if posi_left(rnn,1) == 0 && posi_right(rnn,1) == 0
    
    dou_support_right(rnn,1) = 0;
    
end
end

plot(app.PAR6,timea,dou_support_right,'b')
ylim(app.PAR6,[-.05 1.05])
xlim(app.PAR6,[timea(1) timea(slh_z)])
title(app.PAR6,'Double Support Right')
ylabel(app.PAR6,'Position') 
xlabel(app.PAR6,'Time in secs') 
yticks(app.PAR6,[0 1])
yticklabels(app.PAR6,{'Stance','Swing'})

%% calculos

all_ev=sort(cat(1,icut,dcut));
all_time=timea(all_ev(end,1))-timea(all_ev(1,1));

l_time=timea(cutI(end,1))-timea(cutI(1,1));

time_posi_left=find(posi_left(all_ev(1,1):all_ev(end,1)-1,1)==1);
swing_left=size(time_posi_left,1)/all_time;
time_posi_left=find(posi_left(all_ev(1,1):all_ev(end,1)-1,1)==0);
stance_left=size(time_posi_left,1)/all_time;

r_time=timea(cutR(end,1))-timea(cutR(1,1));

time_posi_right=find(posi_right(all_ev(1,1):all_ev(end,1)-1,1)==1);
swing_right=size(time_posi_right,1)/all_time;
time_posi_right=find(posi_right(all_ev(1,1):all_ev(end,1)-1,1)==0);
stance_right=size(time_posi_right,1)/all_time;

l_time=timea(icut(end,1))-timea(icut(1,1));

time_sin_support_left=find(sin_support_left(all_ev(1,1):all_ev(end,1)-1,1)==0);
t_sin_support_left=size(time_sin_support_left,1)/all_time;

r_time=timea(dcut(end,1))-timea(dcut(1,1));

time_sin_support_right=find(sin_support_right(all_ev(1,1):all_ev(end,1)-1,1)==0);
t_sin_support_right=size(time_sin_support_right,1)/all_time;

time_dou_support_left=find(dou_support_left(all_ev(1,1):all_ev(end,1)-1,1)==0);
t_dou_support=size(time_dou_support_left,1)/all_time;

%--------------------------------------------------------------------------


speed(1,1) = mean(left_stride(1,1))/left_stride(1,2);
speed(1,2) = mean(right_stride(1,1))/right_stride(1,2);
speed(2,1) = mean(left_step(1,1))/left_step(1,2);
speed(2,2) = mean(right_step(1,1))/right_step(1,2);

speed;

Billateral_parameters = ["Step Time (sec)";"Cycle Time (sec)";"Step Lenght (cm)";"Stride Lenght (cm)";"H-H Base Support (cm)";"Single Support (%GC)";"Double Support (%GC)";"Swing (%GC)";"Stance (%GC)"];
left_parameters(1,1) = (avg_left_step(1,2));
left_parameters(2,1) = (avg_left_stride(1,2));
left_parameters(3,1) = (avg_left_step(1,1)*100);
left_parameters(4,1) = (avg_left_stride(1,1)*100);
left_parameters(5,1) = (avg_left_width(1,1)*1);
left_parameters(6,1) = (t_sin_support_left(1,1)*1);
left_parameters(7,1) = (t_dou_support(1,1)*1);
left_parameters(8,1) = (swing_left(1,1)*1);
left_parameters(9,1) = (stance_left(1,1)*1);

right_parameters(1,1) = (avg_right_step(1,2));
right_parameters(2,1) = (avg_right_stride(1,2));
right_parameters(3,1) = (avg_right_step(1,1)*100);
right_parameters(4,1) = (avg_right_stride(1,1)*100);
right_parameters(5,1) = (avg_right_width(1,1)*1);
right_parameters(6,1) = (t_sin_support_right(1,1)*1);
right_parameters(7,1) = (t_dou_support(1,1)*1);
right_parameters(8,1) = (swing_right(1,1)*1);
right_parameters(9,1) = (stance_right(1,1)*1);

left_parameters;
right_parameters;

ta=table(Billateral_parameters,left_parameters,right_parameters)
app.TA.Data=ta;

app.Lamp_4.Color=[0 1 0]
catch
    warning('Error cálculo de la marcha');
    a = 0;
    uiwait(msgbox('Error cálculo de la marcha','ERROR','modal'));
end  
      
global events    
global cutRo
global cutR
global cutR2o
global cutR2
global cutIo
global cutI
global cutI2o
global cutI2
global lh_z
global rh_z
global lt_z2
global rt_z2
global timea
global timeaa
global timeaj
global timeajj
global lt_z
global rt_z
global lstep
global rstep
global lstepo
global rstepo
global slh_z
global lfoota
global rfoota
global lfootj
global rfootj
global lfootjj
global rfootjj

try
%% TRAYECTORIAS CON VICON
%Carga de datos
FA=find(contains(lower({listing.name}),'velacc'));
datasetacc = readtable(fullfile(listing(FA).name));


%%

lfoota=table2array(datasetacc(3:end,117:122))/1000;
rfoota=table2array(datasetacc(3:end,141:146))/1000;

timeaa=table2array(datasetacc(3:end,1));
timeaa=timeaa/100; % Cambiar la columna 1 por la de los tiempos nuevos y ya no dividir entre 1000
if timeaa(1)>events(1)
    timeaa=timeaa/2;
end
timeaj=timeaa(2:end,1);
timeajj=timeaa(3:end,1);


% primer der
lfootj=diff(lfoota);
rfootj=diff(rfoota);
% segunda der
lfootjj=diff(lfootj);
rfootjj=diff(rfootj);

%filtro
fc=5;
wn=fc/(fV/2);
ord=4;
[b,a]=butter(ord,wn);
lfootj=filtfilt(b,a,lfootj);
rfootj=filtfilt(b,a,rfootj);

lfootjj=filtfilt(b,a,lfootjj);
rfootjj=filtfilt(b,a,rfootjj);
catch
    warning('Error en LOADING VICON');
    a = 0;
    uiwait(msgbox('Error en LOADING VICON','ERROR','modal'));
end

try   

global cutRoa
global cutRa
global cutIoa
global cutIa
global cutR2oa
global cutR2a
global cutI2oa
global cutI2a

cutIa=cutI-2;
if cutIa(1)<=0
    cutIa=cutIa(2:end);
end
cutRa=cutR-2;
if cutRa(1)<=0
    cutRa=cutRa(2:end);
end

cutI2a=cutI2-2;
if isempty(cutI2)~=1
    if cutI2a(1)<=0
        cutI2a=cutI2a(2:end);
    end
end
cutR2a=cutR2-2;
if isempty(cutR2)~=1
    if cutR2a(1)<=0
        cutR2a=cutR2a(2:end);
    end
end

cutIoa=cutIo-2;
if cutIoa(1)<=0
    cutIoa=cutIoa(2:end);
end
cutRoa=cutRo-2;
if cutRoa(1)<=0
    cutRoa=cutRoa(2:end);
end

cutI2oa=cutI2o-2;
if isempty(cutI2o)~=1
    if cutI2oa(1)<=0
        cutI2oa=cutI2oa(2:end);
    end
end
cutR2oa=cutR2o-2;
if isempty(cutR2o)~=1
    if cutR2oa(1)<=0
        cutR2oa=cutR2oa(2:end);
    end
end

[ss,rss]=size(timeaa);
%% izq
%% izq
x=4; %degrees/s en x
plot(app.ACC1,timeaa(cutI2a),lfoota(cutI2a,x),'kx') %strike matlab 
hold (app.ACC1,"on")
grid (app.ACC1,"on")
plot(app.ACC1,timeaa(cutIa),lfoota(cutIa,x),'k+') %strike vicon
plot(app.ACC1,timeaa(cutI2oa),lfoota(cutI2oa,x),'ko') %off matlab
plot(app.ACC1,timeaa(cutIoa),lfoota(cutIoa,x),'ks') %off vicon
plot(app.ACC1,timeaa,lfoota(:,x),'b')
xlim(app.ACC1,[timeaa(1) timeaa(ss)])
yticks(app.ACC1,[mean(lfoota(:,x))])
title(app.ACC1,'AXIS X: lineal acceleration')
legend (app.ACC1,{'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.ACC1,'lineal acceleration mm/s^{2}') 
xlabel(app.ACC1,'Time in secs') 
ylim(app.ACC1,[min(lfoota(:,x))*1.3 max(lfoota(:,x))*1.3])
yticklabels(app.ACC1,{'LEFT'})
yticklabels(app.ACC4,{'RIGHT'})
xlim(app.ACC4,[timeaa(1) timeaa(ss)])
ylim(app.ACC4,[min(rfoota(:,x))*1.3 max(rfoota(:,x))*1.3])

hold (app.ACC4,"on")
grid (app.ACC4,"on")
yticks(app.ACC4,[mean(rfoota(:,x))])
plot(app.ACC4,timeaa(cutR2a),rfoota(cutR2a,x),'kx') %strike matlab 
plot(app.ACC4,timeaa(cutRa),rfoota(cutRa,x),'k+') %strike vicon
plot(app.ACC4,timeaa(cutR2oa),rfoota(cutR2oa,x),'ko') %off matlab
plot(app.ACC4,timeaa(cutRoa),rfoota(cutRoa,x),'ks') %off vicon
plot(app.ACC4,timeaa,rfoota(:,x),'r')
title(app.ACC4,'AXIS X: lineal acceleration')
legend (app.ACC4,{'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.ACC4,'lineal acceleration mm/s^{2}') 
xlabel(app.ACC4,'Time in secs') 


x=5; %degrees/s en y
plot(app.ACC2,timeaa(cutI2a),lfoota(cutI2a,x),'kx') %strike matlab 
hold (app.ACC2,"on")
grid (app.ACC2,"on")
plot(app.ACC2,timeaa(cutIa),lfoota(cutIa,x),'k+') %strike vicon
plot(app.ACC2,timeaa(cutI2oa),lfoota(cutI2oa,x),'ko') %off matlab
plot(app.ACC2,timeaa(cutIoa),lfoota(cutIoa,x),'ks') %off vicon
plot(app.ACC2,timeaa,lfoota(:,x),'b')
xlim(app.ACC2,[timeaa(1) timeaa(ss)])
yticks(app.ACC2,[mean(lfoota(:,x))])
title(app.ACC2,'AXIS Y: lineal acceleration')
ylabel(app.ACC2,'lineal acceleration mm/s^{2}') 
xlabel(app.ACC2,'Time in secs') 
ylim(app.ACC2,[min(lfoota(:,x))*1.3 max(lfoota(:,x))*1.3])
yticklabels(app.ACC2,{'LEFT'})
xlim(app.ACC5,[timeaa(1) timeaa(ss)])
yticklabels(app.ACC5,{'RIGHT'})
ylim(app.ACC5,[min(rfoota(:,x))*1.3 max(rfoota(:,x))*1.3])
plot(app.ACC5,timeaa,rfoota(:,x),'r')
hold (app.ACC5,"on")
grid (app.ACC5,"on")
plot(app.ACC5,timeaa(cutR2a),rfoota(cutR2a,x),'kx') %strike matlab 
plot(app.ACC5,timeaa(cutRa),rfoota(cutRa,x),'k+') %strike vicon
plot(app.ACC5,timeaa(cutR2oa),rfoota(cutR2oa,x),'ko') %off matlab
plot(app.ACC5,timeaa(cutRoa),rfoota(cutRoa,x),'ks') %off vicon
title(app.ACC5,'AXIS Y: lineal acceleration')
ylabel(app.ACC5,'lineal acceleration mm/s^{2}') 
xlabel(app.ACC5,'Time in secs') 

x=6; %degrees/s en z
plot(app.ACC3,timeaa(cutI2a),lfoota(cutI2a,x),'kx') %strike matlab 
hold (app.ACC3,"on")
grid (app.ACC3,"on")
plot(app.ACC3,timeaa(cutIa),lfoota(cutIa,x),'k+') %strike vicon
plot(app.ACC3,timeaa(cutI2oa),lfoota(cutI2oa,x),'ko') %off matlab
plot(app.ACC3,timeaa(cutIoa),lfoota(cutIoa,x),'ks') %off vicon
plot(app.ACC3,timeaa,lfoota(:,x),'b')
xlim(app.ACC3,[timeaa(1) timeaa(ss)])
yticks(app.ACC3,[mean(lfoota(:,x))])
yticks(app.ACC6,[mean(rfoota(:,x))])
title(app.ACC3,'AXIS Z: lineal acceleration')
ylabel(app.ACC3,'lineal acceleration mm/s^{2}') 
xlabel(app.ACC3,'Time in secs') 
ylim(app.ACC3,[min(lfoota(:,x))*1.3 max(lfoota(:,x))*1.3])
yticklabels(app.ACC3,{'LEFT'})
yticklabels(app.ACC6,{'RIGHT'})
ylim(app.ACC6,[min(rfoota(:,x))*1.3 max(rfoota(:,x))*1.3])
plot(app.ACC6,timeaa,rfoota(:,x),'r')
xlim(app.ACC6,[timeaa(1) timeaa(ss)])
hold (app.ACC6,"on")
grid (app.ACC6,"on")
plot(app.ACC6,timeaa(cutR2a),rfoota(cutR2a,x),'kx') %strike matlab 
plot(app.ACC6,timeaa(cutRa),rfoota(cutRa,x),'k+') %strike vicon
plot(app.ACC6,timeaa(cutR2oa),rfoota(cutR2oa,x),'ko') %off matlab
plot(app.ACC6,timeaa(cutRoa),rfoota(cutRoa,x),'ks') %off vicon
title(app.ACC6,'AXIS Z: lineal acceleration')
ylabel(app.ACC6,'lineal acceleration mm/s^{2}') 
xlabel(app.ACC6,'Time in secs') 


x=1; %degrees/s en x
plot(app.ACC1a,timeaa(cutI2a),lfoota(cutI2a,x),'kx') %strike matlab 
hold (app.ACC1a,"on")
grid (app.ACC1a,"on")
plot(app.ACC1a,timeaa(cutIa),lfoota(cutIa,x),'k+') %strike vicon
plot(app.ACC1a,timeaa(cutI2oa),lfoota(cutI2oa,x),'ko') %off matlab
plot(app.ACC1a,timeaa(cutIoa),lfoota(cutIoa,x),'ks') %off vicon
plot(app.ACC1a,timeaa,lfoota(:,x),'b')
xlim(app.ACC1a,[timeaa(1) timeaa(ss)])
yticks(app.ACC1a,[mean(lfoota(:,x))])
title(app.ACC1a,'AXIS X: angular acceleration')
legend (app.ACC1a,{'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.ACC1a,'angular acceleration deg/s^{2}') 
xlabel(app.ACC1a,'Time in secs') 
ylim(app.ACC1a,[min(lfoota(:,x))*1.3 max(lfoota(:,x))*1.3])
yticklabels(app.ACC1a,{'LEFT'})
yticklabels(app.ACC4a,{'RIGHT'})
xlim(app.ACC4a,[timeaa(1) timeaa(ss)])
ylim(app.ACC4a,[min(rfoota(:,x))*1.3 max(rfoota(:,x))*1.3])

hold (app.ACC4a,"on")
grid (app.ACC4a,"on")
yticks(app.ACC4a,[mean(rfoota(:,x))])
plot(app.ACC4a,timeaa(cutR2a),rfoota(cutR2a,x),'kx') %strike matlab 
plot(app.ACC4a,timeaa(cutRa),rfoota(cutRa,x),'k+') %strike vicon
plot(app.ACC4a,timeaa(cutR2oa),rfoota(cutR2oa,x),'ko') %off matlab
plot(app.ACC4a,timeaa(cutRoa),rfoota(cutRoa,x),'ks') %off vicon
plot(app.ACC4a,timeaa,rfoota(:,x),'r')
title(app.ACC4a,'AXIS X: angular acceleration')
legend (app.ACC4a,{'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.ACC4a,'angular acceleration deg/s^{2}') 
xlabel(app.ACC4a,'Time in secs') 


x=2; %degrees/s en y
plot(app.ACC2a,timeaa(cutI2a),lfoota(cutI2a,x),'kx') %strike matlab 
hold (app.ACC2a,"on")
grid (app.ACC2a,"on")
plot(app.ACC2a,timeaa(cutIa),lfoota(cutIa,x),'k+') %strike vicon
plot(app.ACC2a,timeaa(cutI2oa),lfoota(cutI2oa,x),'ko') %off matlab
plot(app.ACC2a,timeaa(cutIoa),lfoota(cutIoa,x),'ks') %off vicon
plot(app.ACC2a,timeaa,lfoota(:,x),'b')
xlim(app.ACC2a,[timeaa(1) timeaa(ss)])
yticks(app.ACC2a,[mean(lfoota(:,x))])
title(app.ACC2a,'AXIS Y: angular acceleration')
ylabel(app.ACC2a,'angular acceleration deg/s^{2}') 
xlabel(app.ACC2a,'Time in secs') 
ylim(app.ACC2a,[min(lfoota(:,x))*1.3 max(lfoota(:,x))*1.3])
yticklabels(app.ACC2a,{'LEFT'})
xlim(app.ACC5a,[timeaa(1) timeaa(ss)])
yticklabels(app.ACC5a,{'RIGHT'})
ylim(app.ACC5a,[min(rfoota(:,x))*1.3 max(rfoota(:,x))*1.3])
plot(app.ACC5a,timeaa,rfoota(:,x),'r')
hold (app.ACC5a,"on")
grid (app.ACC5a,"on")
plot(app.ACC5a,timeaa(cutR2a),rfoota(cutR2a,x),'kx') %strike matlab 
plot(app.ACC5a,timeaa(cutRa),rfoota(cutRa,x),'k+') %strike vicon
plot(app.ACC5a,timeaa(cutR2oa),rfoota(cutR2oa,x),'ko') %off matlab
plot(app.ACC5a,timeaa(cutRoa),rfoota(cutRoa,x),'ks') %off vicon
title(app.ACC5a,'AXIS Y: angular acceleration')
ylabel(app.ACC5a,'angular acceleration deg/s^{2}') 
xlabel(app.ACC5a,'Time in secs') 

x=3; %degrees/s en z
plot(app.ACC3a,timeaa(cutI2a),lfoota(cutI2a,x),'kx') %strike matlab 
hold (app.ACC3a,"on")
grid (app.ACC3a,"on")
plot(app.ACC3a,timeaa(cutIa),lfoota(cutIa,x),'k+') %strike vicon
plot(app.ACC3a,timeaa(cutI2oa),lfoota(cutI2oa,x),'ko') %off matlab
plot(app.ACC3a,timeaa(cutIoa),lfoota(cutIoa,x),'ks') %off vicon
plot(app.ACC3a,timeaa,lfoota(:,x),'b')
xlim(app.ACC3a,[timeaa(1) timeaa(ss)])
yticks(app.ACC3a,[mean(lfoota(:,x))])
yticks(app.ACC6a,[mean(rfoota(:,x))])
title(app.ACC3a,'AXIS Z: angular acceleration')
ylabel(app.ACC3a,'angular acceleration deg/s^{2}') 
xlabel(app.ACC3a,'Time in secs') 
ylim(app.ACC3a,[min(lfoota(:,x))*1.3 max(lfoota(:,x))*1.3])
yticklabels(app.ACC3a,{'LEFT'})
yticklabels(app.ACC6a,{'RIGHT'})
ylim(app.ACC6a,[min(rfoota(:,x))*1.3 max(rfoota(:,x))*1.3])
plot(app.ACC6a,timeaa,rfoota(:,x),'r')
xlim(app.ACC6a,[timeaa(1) timeaa(ss)])
hold (app.ACC6a,"on")
grid (app.ACC6a,"on")
plot(app.ACC6a,timeaa(cutR2a),rfoota(cutR2a,x),'kx') %strike matlab 
plot(app.ACC6a,timeaa(cutRa),rfoota(cutRa,x),'k+') %strike vicon
plot(app.ACC6a,timeaa(cutR2oa),rfoota(cutR2oa,x),'ko') %off matlab
plot(app.ACC6a,timeaa(cutRoa),rfoota(cutRoa,x),'ks') %off vicon
title(app.ACC6a,'AXIS Z: angular acceleration')
ylabel(app.ACC6a,'angular acceleration deg/s^{2}') 
xlabel(app.ACC6a,'Time in secs') 
            
catch
    warning('Error en ACCEL VICON');
    a = 0;
    uiwait(msgbox('Error en ACCEL VICON','ERROR','modal'));
end           
            
       
global timeaj 
global rfootj
global lfootj
global cutIj
global cutRj
global cutI2j
global cutR2j
global cutI2oj
global cutR2oj
global cutIoj
global cutRoj          
     
cutIj=cutI-3;
if cutIj(1)<1
    cutIj=cutIj(2:end);

end    
cutRj=cutR-3;
if cutRj(1)<1
    cutRj=cutRj(2:end);        
end
cutI2j=cutI2-3;
if isempty(cutI2)~=1
    if cutI2j(1)<1
        cutI2j=cutI2j(2:end);
    end
end
cutR2j=cutR2-3;
if isempty(cutR2)~=1
    if cutR2j(1)<1
        cutR2j=cutR2j(2:end);
    end
end

cutIoj=cutIo-3;
if cutIoj(1)<1
    cutIoj=cutIoj(2:end);
end
cutRoj=cutRo-3;
if cutRoj(1)<1
    cutRoj=cutRoj(2:end);
end

cutI2oj=cutI2o-3;
if isempty(cutI2o)~=1
    if cutI2oj(1)<1
        cutI2oj=cutI2oj(2:end);
    end
end
cutR2oj=cutR2o-3;
if isempty(cutR2o)~=1
    if cutR2oj(1)<1
        cutR2oj=cutR2oj(2:end);
    end
        end
[ssj,rssj]=size(timeaj);
%% izq
x=1; %degrees/s en x

plot(app.JERK1a,timeaj(cutI2j),lfootj(cutI2j,x),'kx') %strike matlab 
hold (app.JERK1a,"on")
hold (app.JERK4a,"on")
grid (app.JERK1a,"on")
grid (app.JERK4a,"on")
plot(app.JERK1a,timeaj(cutIj),lfootj(cutIj,x),'k+') %strike vicon
plot(app.JERK1a,timeaj(cutI2oj),lfootj(cutI2oj,x),'ko') %off matlab
plot(app.JERK1a,timeaj(cutIoj),lfootj(cutIoj,x),'ks') %off vicon
plot(app.JERK1a,timeaj,lfootj(:,x),'b')
xlim(app.JERK1a,[timeaj(1) timeaj(ssj)])
yticks(app.JERK1a,[mean(lfootj(:,x))])
yticklabels(app.JERK1a,{'LEFT'})
xlim(app.JERK4a,[timeaj(1) timeaj(ssj)])
yticks(app.JERK4a,[mean(rfootj(:,x))])
yticklabels(app.JERK4a,{'RIGHT'})
title(app.JERK1a,'AXIS X: angular acceleration')
legend (app.JERK1a,{'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.JERK1a,'Angular acceleration deg/s^{2}') 
xlabel(app.JERK1a,'Time in secs') 
ylim(app.JERK1a,[min(lfootj(:,x))*1.3 max(lfootj(:,x))*1.3])
ylim(app.JERK4a,[min(rfootj(:,x))*1.3 max(rfootj(:,x))*1.3])

plot(app.JERK4a,timeaj(cutR2j),rfootj(cutR2j,x),'kx') %strike matlab 
plot(app.JERK4a,timeaj(cutRj),rfootj(cutRj,x),'k+') %strike vicon
plot(app.JERK4a,timeaj(cutR2oj),rfootj(cutR2oj,x),'ko') %off matlab
plot(app.JERK4a,timeaj(cutRoj),rfootj(cutRoj,x),'ks') %off vicon
plot(app.JERK4a,timeaj,rfootj(:,x),'r')
title(app.JERK4a,'AXIS X: angular acceleration')
legend (app.JERK4a,{'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.JERK4a,'Angular acceleration deg/s^{2}') 
xlabel(app.JERK4a,'Time in secs') 


x=2; %degrees/s en y
plot(app.JERK2a,timeaj(cutI2j),lfootj(cutI2j,x),'kx') %strike matlab 
hold (app.JERK2a,"on")
hold (app.JERK5a,"on")
grid (app.JERK2a,"on")
grid (app.JERK5a,"on")
plot(app.JERK2a,timeaj(cutIj),lfootj(cutIj,x),'k+') %strike vicon
plot(app.JERK2a,timeaj(cutI2oj),lfootj(cutI2oj,x),'ko') %off matlab
plot(app.JERK2a,timeaj(cutIoj),lfootj(cutIoj,x),'ks') %off vicon
plot(app.JERK2a,timeaj,lfootj(:,x),'b')
xlim(app.JERK2a,[timeaj(1) timeaj(ssj)])
yticks(app.JERK2a,[mean(lfootj(:,x))])
yticklabels(app.JERK2a,{'LEFT'})
xlim(app.JERK5a,[timeaj(1) timeaj(ssj)])
yticks(app.JERK5a,[mean(rfootj(:,x))])
yticklabels(app.JERK5a,{'RIGHT'})
title(app.JERK2a,'AXIS Y: angular acceleration')
ylabel(app.JERK2a,'Angular acceleration deg/s^{2}') 
xlabel(app.JERK2a,'Time in secs') 
ylim(app.JERK2a,[min(lfootj(:,x))*1.3 max(lfootj(:,x))*1.3])
ylim(app.JERK5a,[min(rfootj(:,x))*1.3 max(rfootj(:,x))*1.3])
plot(app.JERK5a,timeaj,rfootj(:,x),'r')
plot(app.JERK5a,timeaj(cutR2j),rfootj(cutR2j,x),'kx') %strike matlab 
plot(app.JERK5a,timeaj(cutRj),rfootj(cutRj,x),'k+') %strike vicon
plot(app.JERK5a,timeaj(cutR2oj),rfootj(cutR2oj,x),'ko') %off matlab
plot(app.JERK5a,timeaj(cutRoj),rfootj(cutRoj,x),'ks') %off vicon
title(app.JERK5a,'AXIS Y: angular acceleration')
ylabel(app.JERK5a,'Angular acceleration deg/s^{2}') 
xlabel(app.JERK5a,'Time in secs') 

x=3; %degrees/s en z
plot(app.JERK3a,timeaj(cutI2j),lfootj(cutI2j,x),'kx') %strike matlab 
hold (app.JERK3a,"on")
hold (app.JERK6a,"on")
grid (app.JERK3a,"on")
grid (app.JERK6a,"on")
plot(app.JERK3a,timeaj(cutIj),lfootj(cutIj,x),'k+') %strike vicon
plot(app.JERK3a,timeaj(cutI2oj),lfootj(cutI2oj,x),'ko') %off matlab
plot(app.JERK3a,timeaj(cutIoj),lfootj(cutIoj,x),'ks') %off vicon
plot(app.JERK3a,timeaj,lfootj(:,x),'b')
xlim(app.JERK3a,[timeaj(1) timeaj(ssj)])
yticks(app.JERK3a,[mean(lfootj(:,x))])
yticklabels(app.JERK3a,{'LEFT'})
xlim(app.JERK6a,[timeaj(1) timeaj(ssj)])
yticks(app.JERK3a,[mean(rfootj(:,x))])
yticklabels(app.JERK6a,{'RIGHT'})
title(app.JERK3a,'AXIS Y: angular acceleration')
ylabel(app.JERK3a,'Angular acceleration deg/s^{2}') 
xlabel(app.JERK3a,'Time in secs') 
ylim(app.JERK3a,[min(lfootj(:,x))*1.3 max(lfootj(:,x))*1.3])
ylim(app.JERK6a,[min(rfootj(:,x))*1.3 max(rfootj(:,x))*1.3])
plot(app.JERK6a,timeaj,rfootj(:,x),'r')
plot(app.JERK6a,timeaj(cutR2j),rfootj(cutR2j,x),'kx') %strike matlab 
plot(app.JERK6a,timeaj(cutRj),rfootj(cutRj,x),'k+') %strike vicon
plot(app.JERK6a,timeaj(cutR2oj),rfootj(cutR2oj,x),'ko') %off matlab
plot(app.JERK6a,timeaj(cutRoj),rfootj(cutRoj,x),'ks') %off vicon
title(app.JERK6a,'AXIS Y: angular acceleration')
ylabel(app.JERK6a,'Angular acceleration deg/s^{2}') 
xlabel(app.JERK6a,'Time in secs') 

%-----------------------------
x=4; %degrees/s en x
plot(app.JERK1,timeaj(cutI2j),lfootj(cutI2j,x),'kx') %strike matlab 
hold (app.JERK1,"on")
hold (app.JERK4,"on")
grid (app.JERK1,"on")
grid (app.JERK4,"on")
plot(app.JERK1,timeaj(cutIj),lfootj(cutIj,x),'k+') %strike vicon
plot(app.JERK1,timeaj(cutI2oj),lfootj(cutI2oj,x),'ko') %off matlab
plot(app.JERK1,timeaj(cutIoj),lfootj(cutIoj,x),'ks') %off vicon
plot(app.JERK1,timeaj,lfootj(:,x),'b')
xlim(app.JERK1,[timeaj(1) timeaj(ssj)])
yticks(app.JERK1,[mean(lfootj(:,x))])
yticklabels(app.JERK1,{'LEFT'})
xlim(app.JERK4,[timeaj(1) timeaj(ssj)])
yticks(app.JERK4,[mean(rfootj(:,x))])
yticklabels(app.JERK4,{'RIGHT'})
title(app.JERK1,'AXIS X: lineal acceleration')
legend (app.JERK1,{'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.JERK1,'Lineal acceleration mm/s^{2}') 
xlabel(app.JERK1,'Time in secs') 
ylim(app.JERK1,[min(lfootj(:,x))*1.3 max(lfootj(:,x))*1.3])
ylim(app.JERK4,[min(rfootj(:,x))*1.3 max(rfootj(:,x))*1.3])

plot(app.JERK4,timeaj(cutR2j),rfootj(cutR2j,x),'kx') %strike matlab 
plot(app.JERK4,timeaj(cutRj),rfootj(cutRj,x),'k+') %strike vicon
plot(app.JERK4,timeaj(cutR2oj),rfootj(cutR2oj,x),'ko') %off matlab
plot(app.JERK4,timeaj(cutRoj),rfootj(cutRoj,x),'ks') %off vicon
plot(app.JERK4,timeaj,rfootj(:,x),'r')
title(app.JERK4,'AXIS X: lineal acceleration')
legend (app.JERK4,{'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.JERK4,'Lineal acceleration mm/s^{2}') 
xlabel(app.JERK4,'Time in secs') 


x=5; %degrees/s en y
plot(app.JERK2,timeaj(cutI2j),lfootj(cutI2j,x),'kx') %strike matlab 
hold (app.JERK2,"on")
hold (app.JERK5,"on")
grid (app.JERK2,"on")
grid (app.JERK5,"on")
plot(app.JERK2,timeaj(cutIj),lfootj(cutIj,x),'k+') %strike vicon
plot(app.JERK2,timeaj(cutI2oj),lfootj(cutI2oj,x),'ko') %off matlab
plot(app.JERK2,timeaj(cutIoj),lfootj(cutIoj,x),'ks') %off vicon
plot(app.JERK2,timeaj,lfootj(:,x),'b')
xlim(app.JERK2,[timeaj(1) timeaj(ssj)])
yticks(app.JERK2,[mean(lfootj(:,x))])
yticklabels(app.JERK2,{'LEFT'})
xlim(app.JERK5,[timeaj(1) timeaj(ssj)])
yticks(app.JERK5,[mean(rfootj(:,x))])
yticklabels(app.JERK5,{'RIGHT'})
title(app.JERK2,'AXIS Y: lineal acceleration')
ylabel(app.JERK2,'Lineal acceleration mm/s^{2}') 
xlabel(app.JERK2,'Time in secs') 
ylim(app.JERK2,[min(lfootj(:,x))*1.3 max(lfootj(:,x))*1.3])
ylim(app.JERK5,[min(rfootj(:,x))*1.3 max(rfootj(:,x))*1.3])
plot(app.JERK5,timeaj,rfootj(:,x),'r')
plot(app.JERK5,timeaj(cutR2j),rfootj(cutR2j,x),'kx') %strike matlab 
plot(app.JERK5,timeaj(cutRj),rfootj(cutRj,x),'k+') %strike vicon
plot(app.JERK5,timeaj(cutR2oj),rfootj(cutR2oj,x),'ko') %off matlab
plot(app.JERK5,timeaj(cutRoj),rfootj(cutRoj,x),'ks') %off vicon
title(app.JERK5,'AXIS Y: lineal acceleration')
ylabel(app.JERK5,'Lineal acceleration mm/s^{2}') 
xlabel(app.JERK5,'Time in secs') 

x=6; %degrees/s en z
plot(app.JERK3,timeaj(cutI2j),lfootj(cutI2j,x),'kx') %strike matlab 
hold (app.JERK3,"on")
hold (app.JERK6,"on")
grid (app.JERK3,"on")
grid (app.JERK6,"on")
plot(app.JERK3,timeaj(cutIj),lfootj(cutIj,x),'k+') %strike vicon
plot(app.JERK3,timeaj(cutI2oj),lfootj(cutI2oj,x),'ko') %off matlab
plot(app.JERK3,timeaj(cutIoj),lfootj(cutIoj,x),'ks') %off vicon
plot(app.JERK3,timeaj,lfootj(:,x),'b')
xlim(app.JERK3,[timeaj(1) timeaj(ssj)])
yticks(app.JERK3,[mean(lfootj(:,x))])
yticklabels(app.JERK3,{'LEFT'})
xlim(app.JERK6,[timeaj(1) timeaj(ssj)])
yticks(app.JERK3,[mean(rfootj(:,x))])
yticklabels(app.JERK6,{'RIGHT'})
title(app.JERK3,'AXIS Y: lineal acceleration')
ylabel(app.JERK3,'Lineal acceleration mm/s^{2}') 
xlabel(app.JERK3,'Time in secs') 
ylim(app.JERK3,[min(lfootj(:,x))*1.3 max(lfootj(:,x))*1.3])
ylim(app.JERK6,[min(rfootj(:,x))*1.3 max(rfootj(:,x))*1.3])
plot(app.JERK6,timeaj,rfootj(:,x),'r')
plot(app.JERK6,timeaj(cutR2j),rfootj(cutR2j,x),'kx') %strike matlab 
plot(app.JERK6,timeaj(cutRj),rfootj(cutRj,x),'k+') %strike vicon
plot(app.JERK6,timeaj(cutR2oj),rfootj(cutR2oj,x),'ko') %off matlab
plot(app.JERK6,timeaj(cutRoj),rfootj(cutRoj,x),'ks') %off vicon
title(app.JERK6,'AXIS Y: lineal acceleration')
ylabel(app.JERK6,'Lineal acceleration mm/s^{2}') 
xlabel(app.JERK6,'Time in secs') 
         
   try    
      catch
    warning('Error en JERK VICON');
    a = 0;
    uiwait(msgbox('Error en JERK VICON','ERROR','modal'));
end         
    

global timeajj 
global rfootjj
global lfootjj
global cutIjj
global cutRjj
global cutI2jj
global cutR2jj
global cutI2ojj
global cutR2ojj
global cutIojj
global cutRojj  

try

cutIjj=cutI-4;
if cutIjj(1)<1
    cutIjj=cutIjj(2:end);
end
cutRjj=cutR-4;
if cutRjj(1)<1
    cutRjj=cutRjj(2:end);
end

cutI2jj=cutI2-4;
if isempty(cutI2)~=1
    if cutI2jj(1)<1
        cutI2jj=cutI2jj(2:end);
    end
end
cutR2jj=cutR2-4;
if isempty(cutR2)~=1
    if cutR2jj(1)<1
        cutR2jj=cutR2jj(2:end);
    end
end
cutIojj=cutIo-4;
if cutIojj(1)<1
    cutIojj=cutIojj(2:end);
end
cutRojj=cutRo-4;
if cutRojj(1)<1
    cutRojj=cutRojj(2:end);
end

cutI2ojj=cutI2o-4;
if isempty(cutI2o)~=1
    if cutI2ojj(1)<1
        cutI2ojj=cutI2ojj(2:end);
    end
end
cutR2ojj=cutR2o-4;
if isempty(cutR2o)~=1
    if cutR2ojj(1)<1
        cutR2ojj=cutR2ojj(2:end);
    end
end

[ssjj,rssjj]=size(timeajj);

x=1; %degrees/s en x
plot(app.DJ1a,timeajj(cutI2jj),lfootjj(cutI2jj,x),'kx') %strike matlab 
hold (app.DJ1a,"on")
grid (app.DJ1a,"on")
plot(app.DJ1a,timeajj(cutIjj),lfootjj(cutIjj,x),'k+') %strike vicon
plot(app.DJ1a,timeajj(cutI2ojj),lfootjj(cutI2ojj,x),'ko') %off matlab
plot(app.DJ1a,timeajj(cutIojj),lfootjj(cutIojj,x),'ks') %off vicon
plot(app.DJ1a,timeajj,lfootjj(:,x),'b')
xlim(app.DJ1a,[timeajj(1) timeajj(ssjj)])
yticks(app.DJ1a,[mean(lfootjj(:,x))])
yticklabels(app.DJ1a,{'LEFT'})
ylim(app.DJ1a,[min(lfootjj(:,x))*1.3 max(lfootjj(:,x))*1.3])
title(app.DJ1a,'AXIS X: angular acceleration')
legend (app.DJ1a,{'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.DJ1a,'Angular acceleration deg/s^{2}') 
xlabel(app.DJ1a,'Time in secs') 

plot(app.DJ4a,timeajj,rfootjj(:,x),'r')
hold (app.DJ4a,"on")
grid (app.DJ4a,"on")
plot(app.DJ4a,timeajj(cutR2jj),rfootjj(cutR2jj,x),'kx') %strike matlab 
plot(app.DJ4a,timeajj(cutRjj),rfootjj(cutRjj,x),'k+') %strike vicon
plot(app.DJ4a,timeajj(cutR2ojj),rfootjj(cutR2ojj,x),'ko') %off matlab
plot(app.DJ4a,timeajj(cutRojj),rfootjj(cutRojj,x),'ks') %off vicon
xlim(app.DJ4a,[timeajj(1) timeajj(ssjj)])
yticks(app.DJ4a,[mean(rfootjj(:,x))])
yticklabels(app.DJ4a,{'RIGHT'})
ylim(app.DJ4a,[min(rfootjj(:,x))*1.3 max(rfootjj(:,x))*1.3])
title(app.DJ4a,'AXIS X: angular acceleration')
legend (app.DJ4a,{'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.DJ4a,'Angular acceleration deg/s^{2}') 
xlabel(app.DJ4a,'Time in secs') 

x=2; %degrees/s en y
plot(app.DJ2a,timeajj(cutI2jj),lfootjj(cutI2jj,x),'kx') %strike matlab 
hold (app.DJ2a,"on")
grid (app.DJ2a,"on")
plot(app.DJ2a,timeajj(cutIjj),lfootjj(cutIjj,x),'k+') %strike vicon
plot(app.DJ2a,timeajj(cutI2ojj),lfootjj(cutI2ojj,x),'ko') %off matlab
plot(app.DJ2a,timeajj(cutIojj),lfootjj(cutIojj,x),'ks') %off vicon
plot(app.DJ2a,timeajj,lfootjj(:,x),'b')
xlim(app.DJ2a,[timeajj(1) timeajj(ssjj)])
yticks(app.DJ2a,[mean(lfootjj(:,x))])
yticklabels(app.DJ2a,{'LEFT'})
ylim(app.DJ2a,[min(lfootjj(:,x))*1.3 max(lfootjj(:,x))*1.3])
title(app.DJ2a,'AXIS Y: angular acceleration')
ylabel(app.DJ2a,'Angular acceleration deg/s^{2}') 
xlabel(app.DJ2a,'Time in secs') 

plot(app.DJ5a,timeajj,rfootjj(:,x),'r')
hold (app.DJ5a,"on")
grid (app.DJ5a,"on")
plot(app.DJ5a,timeajj(cutR2jj),rfootjj(cutR2jj,x),'kx') %strike matlab 
plot(app.DJ5a,timeajj(cutRjj),rfootjj(cutRjj,x),'k+') %strike vicon
plot(app.DJ5a,timeajj(cutR2ojj),rfootjj(cutR2ojj,x),'ko') %off matlab
plot(app.DJ5a,timeajj(cutRojj),rfootjj(cutRojj,x),'ks') %off vicon
xlim(app.DJ5a,[timeajj(1) timeajj(ssjj)])
yticks(app.DJ5a,[mean(rfootjj(:,x))])
yticklabels(app.DJ5a,{'RIGHT'})
ylim(app.DJ5a,[min(rfootjj(:,x))*1.3 max(rfootjj(:,x))*1.3])
title(app.DJ5a,'AXIS Y: angular acceleration')
ylabel(app.DJ5a,'Angular acceleration deg/s^{2}') 
xlabel(app.DJ5a,'Time in secs') 

x=3; %degrees/s en z
plot(app.DJ3a,timeajj(cutI2jj),lfootjj(cutI2jj,x),'kx') %strike matlab 
hold (app.DJ3a,"on")
grid (app.DJ3a,"on")
plot(app.DJ3a,timeajj(cutIjj),lfootjj(cutIjj,x),'k+') %strike vicon
plot(app.DJ3a,timeajj(cutI2ojj),lfootjj(cutI2ojj,x),'ko') %off matlab
plot(app.DJ3a,timeajj(cutIojj),lfootjj(cutIojj,x),'ks') %off vicon
plot(app.DJ3a,timeajj,lfootjj(:,x),'b')
xlim(app.DJ3a,[timeajj(1) timeajj(ssjj)])
yticks(app.DJ3a,[mean(lfootjj(:,x))])
yticklabels(app.DJ3a,{'LEFT'})
ylim(app.DJ3a,[min(lfootjj(:,x))*1.3 max(lfootjj(:,x))*1.3])
title(app.DJ3a,'AXIS Z: angular acceleration')
ylabel(app.DJ3a,'Angular acceleration deg/s^{2}') 
xlabel(app.DJ3a,'Time in secs') 

plot(app.DJ6a,timeajj,rfootjj(:,x),'r')
hold (app.DJ6a,"on")
grid (app.DJ6a,"on")
plot(app.DJ6a,timeajj(cutR2jj),rfootjj(cutR2jj,x),'kx') %strike matlab 
plot(app.DJ6a,timeajj(cutRjj),rfootjj(cutRjj,x),'k+') %strike vicon
plot(app.DJ6a,timeajj(cutR2ojj),rfootjj(cutR2ojj,x),'ko') %off matlab
plot(app.DJ6a,timeajj(cutRojj),rfootjj(cutRojj,x),'ks') %off vicon
xlim(app.DJ6a,[timeajj(1) timeajj(ssjj)])
yticks(app.DJ6a,[mean(rfootjj(:,x))])
yticklabels(app.DJ6a,{'RIGHT'})
ylim(app.DJ6a,[min(rfootjj(:,x))*1.3 max(rfootjj(:,x))*1.3])
title(app.DJ6a,'AXIS Z: angular acceleration')
ylabel(app.DJ6a,'Angular acceleration deg/s^{2}') 
xlabel(app.DJ6a,'Time in secs') 

%-----------------------------
x=4; %degrees/s en x
plot(app.DJ1,timeajj(cutI2jj),lfootjj(cutI2jj,x),'kx') %strike matlab 
hold (app.DJ1,"on")
grid (app.DJ1,"on")
plot(app.DJ1,timeajj(cutIjj),lfootjj(cutIjj,x),'k+') %strike vicon
plot(app.DJ1,timeajj(cutI2ojj),lfootjj(cutI2ojj,x),'ko') %off matlab
plot(app.DJ1,timeajj(cutIojj),lfootjj(cutIojj,x),'ks') %off vicon
plot(app.DJ1,timeajj,lfootjj(:,x),'b')
xlim(app.DJ1,[timeajj(1) timeajj(ssjj)])
yticks(app.DJ1,[mean(lfootjj(:,x))])
yticklabels(app.DJ1,{'LEFT'})
ylim(app.DJ1,[min(lfootjj(:,x))*1.3 max(lfootjj(:,x))*1.3])
title(app.DJ1,'AXIS X: lineal acceleration')
legend (app.DJ1,{'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.DJ1,'Lineal acceleration mm/s^{2}') 
xlabel(app.DJ1,'Time in secs') 

plot(app.DJ4,timeajj,rfootjj(:,x),'r')
hold (app.DJ4,"on")
grid (app.DJ4,"on")
plot(app.DJ4,timeajj(cutR2jj),rfootjj(cutR2jj,x),'kx') %strike matlab 
plot(app.DJ4,timeajj(cutRjj),rfootjj(cutRjj,x),'k+') %strike vicon
plot(app.DJ4,timeajj(cutR2ojj),rfootjj(cutR2ojj,x),'ko') %off matlab
plot(app.DJ4,timeajj(cutRojj),rfootjj(cutRojj,x),'ks') %off vicon
xlim(app.DJ4,[timeajj(1) timeajj(ssjj)])
yticks(app.DJ4,[mean(rfootjj(:,x))])
yticklabels(app.DJ4,{'RIGHT'})
ylim(app.DJ4,[min(rfootjj(:,x))*1.3 max(rfootjj(:,x))*1.3])
title(app.DJ4,'AXIS X: lineal acceleration')
legend (app.DJ4,{'Foot strike MATLAB' 'Foot strike VICON' 'Foot off MATLAB' 'Foot off VICON' }, 'Location','northwest','NumColumns',2)
ylabel(app.DJ4,'Lineal acceleration mm/s^{2}') 
xlabel(app.DJ4,'Time in secs') 

x=5; %degrees/s en y
plot(app.DJ2,timeajj(cutI2jj),lfootjj(cutI2jj,x),'kx') %strike matlab 
hold (app.DJ2,"on")
grid (app.DJ2,"on")
plot(app.DJ2,timeajj(cutIjj),lfootjj(cutIjj,x),'k+') %strike vicon
plot(app.DJ2,timeajj(cutI2ojj),lfootjj(cutI2ojj,x),'ko') %off matlab
plot(app.DJ2,timeajj(cutIojj),lfootjj(cutIojj,x),'ks') %off vicon
plot(app.DJ2,timeajj,lfootjj(:,x),'b')
xlim(app.DJ2,[timeajj(1) timeajj(ssjj)])
yticks(app.DJ2,[mean(lfootjj(:,x))])
yticklabels(app.DJ2,{'LEFT'})
ylim(app.DJ2,[min(lfootjj(:,x))*1.3 max(lfootjj(:,x))*1.3])
title(app.DJ2,'AXIS Y: lineal acceleration')
ylabel(app.DJ2,'Lineal acceleration mm/s^{2}') 
xlabel(app.DJ2,'Time in secs') 

plot(app.DJ5,timeajj,rfootjj(:,x),'r')
hold (app.DJ5,"on")
grid (app.DJ5,"on")
plot(app.DJ5,timeajj(cutR2jj),rfootjj(cutR2jj,x),'kx') %strike matlab 
plot(app.DJ5,timeajj(cutRjj),rfootjj(cutRjj,x),'k+') %strike vicon
plot(app.DJ5,timeajj(cutR2ojj),rfootjj(cutR2ojj,x),'ko') %off matlab
plot(app.DJ5,timeajj(cutRojj),rfootjj(cutRojj,x),'ks') %off vicon
xlim(app.DJ5,[timeajj(1) timeajj(ssjj)])
yticks(app.DJ5,[mean(rfootjj(:,x))])
yticklabels(app.DJ5,{'RIGHT'})
ylim(app.DJ5,[min(rfootjj(:,x))*1.3 max(rfootjj(:,x))*1.3])
title(app.DJ5,'AXIS Y: lineal acceleration')
ylabel(app.DJ5,'Lineal acceleration mm/s^{2}') 
xlabel(app.DJ5,'Time in secs') 

x=6; %degrees/s en z
plot(app.DJ3,timeajj(cutI2jj),lfootjj(cutI2jj,x),'kx') %strike matlab 
hold (app.DJ3,"on")
grid (app.DJ3,"on")
plot(app.DJ3,timeajj(cutIjj),lfootjj(cutIjj,x),'k+') %strike vicon
plot(app.DJ3,timeajj(cutI2ojj),lfootjj(cutI2ojj,x),'ko') %off matlab
plot(app.DJ3,timeajj(cutIojj),lfootjj(cutIojj,x),'ks') %off vicon
plot(app.DJ3,timeajj,lfootjj(:,x),'b')
xlim(app.DJ3,[timeajj(1) timeajj(ssjj)])
yticks(app.DJ3,[mean(lfootjj(:,x))])
yticklabels(app.DJ3,{'LEFT'})
ylim(app.DJ3,[min(lfootjj(:,x))*1.3 max(lfootjj(:,x))*1.3])
title(app.DJ3,'AXIS Z: lineal acceleration')
ylabel(app.DJ3,'Lineal acceleration mm/s^{2}') 
xlabel(app.DJ3,'Time in secs') 

plot(app.DJ6,timeajj,rfootjj(:,x),'r')
hold (app.DJ6,"on")
grid (app.DJ6,"on")
plot(app.DJ6,timeajj(cutR2jj),rfootjj(cutR2jj,x),'kx') %strike matlab 
plot(app.DJ6,timeajj(cutRjj),rfootjj(cutRjj,x),'k+') %strike vicon
plot(app.DJ6,timeajj(cutR2ojj),rfootjj(cutR2ojj,x),'ko') %off matlab
plot(app.DJ6,timeajj(cutRojj),rfootjj(cutRojj,x),'ks') %off vicon
xlim(app.DJ6,[timeajj(1) timeajj(ssjj)])
yticks(app.DJ6,[mean(rfootjj(:,x))])
yticklabels(app.DJ6,{'RIGHT'})
ylim(app.DJ6,[min(rfootjj(:,x))*1.3 max(rfootjj(:,x))*1.3])
title(app.DJ6,'AXIS Z: lineal acceleration')
ylabel(app.DJ6,'Lineal acceleration mm/s^{2}') 
xlabel(app.DJ6,'Time in secs') 


app.VICONLamp.Color=[0 1 0]
app.Lamp_5.Color=[0 1 0]
global checkvicon
checkvicon=1;
             catch
    warning('Error en DOUBLE JERK VICON');
    a = 0;
    uiwait(msgbox('Error en DOUBLE JERK VICON','ERROR','modal'));
end
            checkxsens=0;
try 
FX=find(contains(lower({listing.name}),'xsens'));
datasetaccX = readtable(fullfile(listing(FX).name));


global T
global fV
global ahip %cadera
global althigh %muslo izquierdo
global arthigh % muslo derecho
global alleg %pierna izquierda
global arleg %pierna derecha
global alft %pie izquierdo
global arft %pie derecho
global rahip %cadera
global ralthigh %muslo izquierdo
global rarthigh % muslo derecho
global ralleg %pierna izquierda
global rarleg %pierna derecha
global ralft %pie izquierdo
global rarft %pie derecho

global ahipj %cadera
global althighj %muslo izquierdo
global arthighj % muslo derecho
global allegj %pierna izquierda
global arlegj %pierna derecha
global alftj %pie izquierdo
global arftj %pie derecho
global rahipj %cadera
global ralthighj %muslo izquierdo
global rarthighj % muslo derecho
global rallegj %pierna izquierda
global rarlegj %pierna derecha
global ralftj %pie izquierdo
global rarftj %pie derecho

global ahipjj %cadera
global althighjj %muslo izquierdo
global arthighjj % muslo derecho
global allegjj %pierna izquierda
global arlegjj %pierna derecha
global alftjj %pie izquierdo
global arftjj %pie derecho
global rahipjj %cadera
global ralthighjj %muslo izquierdo
global rarthighjj % muslo derecho
global rallegjj %pierna izquierda
global rarlegjj %pierna derecha
global ralftjj %pie izquierdo
global rarftjj %pie derecho

global ghip %cadera
global glthigh %muslo izquierdo
global grthigh % muslo derecho
global glleg %pierna izquierda
global grleg %pierna derecha
global glft %pie izquierdo
global grft %pie derecho
global rghip %cadera
global rglthigh %muslo izquierdo
global rgrthigh % muslo derecho
global rglleg %pierna izquierda
global rgrleg %pierna derecha
global rglft %pie izquierdo
global rgrft %pie derecho

global ghipj %cadera
global glthighj %muslo izquierdo
global grthighj % muslo derecho
global gllegj %pierna izquierda
global grlegj %pierna derecha
global glftj %pie izquierdo
global grftj %pie derecho
global rghipj %cadera
global rglthighj %muslo izquierdo
global rgrthighj % muslo derecho
global rgllegj %pierna izquierda
global rgrlegj %pierna derecha
global rglftj %pie izquierdo
global rgrftj %pie derecho

global ghipjj %cadera
global glthighjj %muslo izquierdo
global grthighjj % muslo derecho
global gllegjj %pierna izquierda
global grlegjj %pierna derecha
global glftjj %pie izquierdo
global grftjj %pie derecho
global rghipjj %cadera
global rglthighjj %muslo izquierdo
global rgrthighjj % muslo derecho
global rgllegjj %pierna izquierda
global rgrlegjj %pierna derecha
global rglftjj %pie izquierdo
global rgrftjj %pie derecho

%%Carga de datos
%aceleraciones

datasetaccX(:,10)=[];
datasetaccX(:,40)=[];
%Pierna derecha
acc1a=table2array(datasetaccX(:,1:3));
acc1g=table2array(datasetaccX(:,4:6));

%Pie izquierdo
acc2a=table2array(datasetaccX(:,7:9));
acc2g=table2array(datasetaccX(:,10:12));

%Muslo izquierdo
acc3a=table2array(datasetaccX(:,13:15));
acc3g=table2array(datasetaccX(:,16:18));

%Cadera
acc4a=table2array(datasetaccX(:,19:21));
acc4g=table2array(datasetaccX(:,22:24));

%Pierna izquierda
acc5a=table2array(datasetaccX(:,25:27));
acc5g=table2array(datasetaccX(:,28:30));

%Muslo derecho
acc6a=table2array(datasetaccX(:,31:33));
acc6g=table2array(datasetaccX(:,34:36));

%Pie derecho
acc7a=table2array(datasetaccX(:,37:39));
acc7g=table2array(datasetaccX(:,40:42));

acc2g(:,1)=-acc2g(:,1);
acc2a(:,2)=-acc2a(:,2);
acc2g(:,3)=-acc2g(:,3);

acc3g(:,1)=-acc3g(:,1);
acc3a(:,2)=-acc3a(:,2);
acc3g(:,3)=-acc3g(:,3);

acc5g(:,1)=-acc5g(:,1);
acc5a(:,2)=-acc5a(:,2);
acc5g(:,3)=-acc5g(:,3);

catch
    warning('Error en CARGANDO EL XSENS');
    a = 0;
    uiwait(msgbox('Error en CARGANDO EL XSENS','ERROR','modal'));
end  
%% Calculo de frecuencia de muestreo

try
[size_datasetaccX,size_datasetaccX2]=size(datasetaccX);
timet=datasetaccX(:,size_datasetaccX2);
time=table2array(timet);
xa=ischange(time);
e=find(xa==1);
fqmV=diff(e);
fqm=mean(fqmV);

TmV=1./fqmV;
%Tm1=1/fqm1;
Tm=1/fqm;
timeN=time-(time(1,1));
%for i=1:e(1)-1
 %   timeN(i)=timeN(i)+(i-1)*Tm1;
%end
for i=1:prod(size(e))-1
    for k=e(i):e(i+1)-1
        timeN(k)=timeN(k)+(k-e(i))*Tm;
    end
end
for i=e(end):prod(size(timeN))
    timeN(i)=timeN(i)+(i-e(end))*TmV(end);
end

%% tiempos

timestamp=table2array(datasetaccX(:,43));
[sts,ssts]=size(timestamp);

timestamp(:,2)=[1:sts];
timestamp(:,3)=timestamp(:,2)/fqm;

timeN=timestamp(:,3);

timeG=timeN;

global timeG;

trp = max(timeN);
app.TIMESlider.Limits = [0 trp];
            
            
catch
    warning('Error en CALCULANDO LA FRECUENCIA DE MUESTREO');
    a = 0;
    uiwait(msgbox('Error en CALCULANDO LA FRECUENCIA DE MUESTREO','ERROR','modal'));
    end  
    
   try 
%%________________________________________________________________________________________________________________________________________
%% Filtros 
h=fdesign.lowpass('N,F3db',12,0.12566370614);
d1=design(h,'butter');
%butterworth

fc=5;
wn=fc/(fqm/2);
ord=4;
[b,a]=butter(ord,wn);

afacc1=filtfilt(b,a,acc1a);
afacc2=filtfilt(b,a,acc2a);
afacc3=filtfilt(b,a,acc3a);
afacc4=filtfilt(b,a,acc4a);
afacc5=filtfilt(b,a,acc5a);
afacc6=filtfilt(b,a,acc6a);
afacc7=filtfilt(b,a,acc7a);

gfacc1=filtfilt(b,a,acc1g);
gfacc2=filtfilt(b,a,acc2g);
gfacc3=filtfilt(b,a,acc3g);
gfacc4=filtfilt(b,a,acc4g);
gfacc5=filtfilt(b,a,acc5g);
gfacc6=filtfilt(b,a,acc6g);
gfacc7=filtfilt(b,a,acc7g);

%%________________________________________________________________________________________________________________________________________
%% Remuestreo 
afacc1=resample(afacc1,round(fV),round(fqm));
afacc2=resample(afacc2,round(fV),round(fqm));
afacc3=resample(afacc3,round(fV),round(fqm));
afacc4=resample(afacc4,round(fV),round(fqm));
afacc5=resample(afacc5,round(fV),round(fqm));
afacc6=resample(afacc6,round(fV),round(fqm));
afacc7=resample(afacc7,round(fV),round(fqm));

gfacc1=resample(gfacc1,round(fV),round(fqm));
gfacc2=resample(gfacc2,round(fV),round(fqm));
gfacc3=resample(gfacc3,round(fV),round(fqm));
gfacc4=resample(gfacc4,round(fV),round(fqm));
gfacc5=resample(gfacc5,round(fV),round(fqm));
gfacc6=resample(gfacc6,round(fV),round(fqm));
gfacc7=resample(gfacc7,round(fV),round(fqm));

%% eliminiación de tendencias

orden=9;

afacc1=detrend(afacc1,orden);
afacc2=detrend(afacc2,orden);
afacc3=detrend(afacc3,orden);
afacc4=detrend(afacc4,orden);
afacc5=detrend(afacc5,orden);
afacc6=detrend(afacc6,orden);
afacc7=detrend(afacc7,orden);

gfacc1=detrend(gfacc1,orden);
gfacc2=detrend(gfacc2,orden);
gfacc3=detrend(gfacc3,orden);
gfacc4=detrend(gfacc4,orden);
gfacc5=detrend(gfacc5,orden);
gfacc6=detrend(gfacc6,orden);
gfacc7=detrend(gfacc7,orden);

%% 
[ss,rss]=size(timeN);

timeRN = timeN;

global timeRN;

catch
    warning('Error en FILTRANDO LA SEÑAL');
    a = 0;
    uiwait(msgbox('Error en FILTRANDO LA SEÑAL','ERROR','modal'));
    end  
try
%%________________________________________________________________________________________________________________________________________
%%________________________________________________________________________________________________________________________________________
%%________________________________________________________________________________________________________________________________________
%%________________________________________________________________________________________________________________________________________
%%________________________________________________________________________________________________________________________________________
ahip=afacc4; %cadera
althigh=afacc3; %muslo izquierdo
arthigh=afacc6; % muslo derecho
alleg=afacc5; %pierna izquierda
arleg=afacc1; %pierna derecha
alft=afacc2; %pie izquierdo
arft=afacc7; %pie derecho

ghip=gfacc4; %cadera
glthigh=gfacc3; %muslo izquierdo
grthigh=gfacc6; % muslo derecho
glleg=gfacc5; %pierna izquierda
grleg=gfacc1; %pierna derecha
glft=gfacc2; %pie izquierdo
grft=gfacc7; %pie derecho
%%________________________________________________________________________________________________________________________________________
%%________________________________________________________________________________________________________________________________________
%%________________________________________________________________________________________________________________________________________
%%________________________________________________________________________________________________________________________________________
%%________________________________________________________________________________________________________________________________________


%%________________________________________________________________________________________________________________________________________
%%________________________________________________________________________________________________________________________________________

%% LINEAL
accx = ahip(:,1) ;   
accy = ahip(:,2) ;   
accz = ahip(:,3) ;  
rahip = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = althigh(:,1) ;   
accy = althigh(:,2) ;   
accz = althigh(:,3) ;  
ralthigh = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = arthigh(:,1) ;   
accy = arthigh(:,2) ;   
accz = arthigh(:,3) ;  
rarthigh = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = alleg(:,1) ;   
accy = alleg(:,2) ;   
accz = alleg(:,3) ;  
ralleg = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = arleg(:,1) ;   
accy = arleg(:,2) ;   
accz = arleg(:,3) ;  
rarleg = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = alft(:,1) ;   
accy = alft(:,2) ;   
accz = alft(:,3) ;  
ralft = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = arft(:,1) ;   
accy = arft(:,2) ;   
accz = arft(:,3) ;  
rarft = sqrt(accx.^2+accy.^2+accz.^2);
clear accx
clear accy
clear accz

global timeN
timeN=(0:length(arft)-1)*T;

            x=1;
            plot(app.PXS1,timeN,rahip(:,x),'k')
            title(app.PXS1,'RESULTANT')
            xlim(app.PXS1,[0 timeN(end)]);
            ylim(app.PXS1,[min(rahip(:,x))*1.3 max(rahip(:,x))*1.3]); 
            
            plot(app.PXS2,timeN,ralthigh(:,x),'b')
            xlim(app.PXS2,[0 timeN(end)]);
            ylim(app.PXS2,[min(ralthigh(:,x))*1.3 max(ralthigh(:,x))*1.3]); 
            
            plot(app.PXS3,timeN,rarthigh(:,x),'r')
            xlim(app.PXS3,[0 timeN(end)]);
            ylim(app.PXS3,[min(rarthigh(:,x))*1.3 max(rarthigh(:,x))*1.3]); 
            
            plot(app.PXS4,timeN,ralleg(:,x),'b')
            xlim(app.PXS4,[0 timeN(end)]);
            ylim(app.PXS4,[min(ralleg(:,x))*1.3 max(ralleg(:,x))*1.3]); 
            
            plot(app.PXS5,timeN,rarleg(:,x),'r')
            xlim(app.PXS5,[0 timeN(end)]);
            ylim(app.PXS5,[min(rarleg(:,x))*1.3 max(rarleg(:,x))*1.3]); 
            
            plot(app.PXS6,timeN,ralft(:,x),'b')
            xlim(app.PXS6,[0 timeN(end)]);
            ylim(app.PXS6,[min(ralft(:,x))*1.3 max(ralft(:,x))*1.3]); 
            
            plot(app.PXS7,timeN,rarft(:,x),'r')
            xlim(app.PXS7,[0 timeN(end)]);
            ylim(app.PXS7,[min(rarft(:,x))*1.3 max(rarft(:,x))*1.3]); 
 
accx = ghip(:,1) ;   
accy = ghip(:,2) ;   
accz = ghip(:,3) ;  
rghip = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = glthigh(:,1) ;   
accy = glthigh(:,2) ;   
accz = glthigh(:,3) ;  
rglthigh = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = grthigh(:,1) ;   
accy = grthigh(:,2) ;   
accz = grthigh(:,3) ;  
rgrthigh = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = glleg(:,1) ;   
accy = glleg(:,2) ;   
accz = glleg(:,3) ;  
rglleg = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = grleg(:,1) ;   
accy = grleg(:,2) ;   
accz = grleg(:,3) ;  
rgrleg = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = glft(:,1) ;   
accy = glft(:,2) ;   
accz = glft(:,3) ;  
rglft = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = grft(:,1) ;   
accy = grft(:,2) ;   
accz = grft(:,3) ;  
rgrft = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz                 
            
    
            catch
    warning('Error en ACC XSENS');
    a = 0;
    uiwait(msgbox('Error en ACC XSENS','ERROR','modal'));
    end 
           
     try
           
%%Carga de datos
%aceleraciones

% jerk
acc1aj=diff(acc1a);
acc1gj=diff(acc1g);

acc2aj=diff(acc2a);
acc2gj=diff(acc2g);

acc3aj=diff(acc3a);
acc3gj=diff(acc3g);

acc4aj=diff(acc4a);
acc4gj=diff(acc4g);

acc5aj=diff(acc5a);
acc5gj=diff(acc5g);

acc6aj=diff(acc6a);
acc6gj=diff(acc6g);

acc7aj=diff(acc7a);
acc7gj=diff(acc7g);


%% Calculo de frecuencia de muestreo

[size_datasetaccX,size_datasetaccX2]=size(datasetaccX);
timet=datasetaccX(:,size_datasetaccX2);
time=table2array(timet);
xa=ischange(time);
e=find(xa==1);
fqmV=diff(e);
fqm=mean(fqmV);

TmV=1./fqmV;
%Tm1=1/fqm1;
Tm=1/fqm;
timeN=time-(time(1,1));
%for i=1:e(1)-1
 %   timeN(i)=timeN(i)+(i-1)*Tm1;
%end
for i=1:prod(size(e))-1
    for k=e(i):e(i+1)-1
        timeN(k)=timeN(k)+(k-e(i))*Tm;
    end
end
for i=e(end):prod(size(timeN))
    timeN(i)=timeN(i)+(i-e(end))*TmV(end);
end
%% tiempos

timestamp=table2array(datasetaccX(:,43));
[sts,ssts]=size(timestamp);

timestamp(:,2)=[1:sts];
timestamp(:,3)=timestamp(:,2)/fqm;

global timeNj
timeNj=timestamp(2:end,3);

%% Filtros 
h=fdesign.lowpass('N,F3db',12,0.12566370614);
d1=design(h,'butter');
%butterworth

fc=5;
wn=fc/(fqm/2);
ord=4;
[b,a]=butter(ord,wn);

afacc1j=filtfilt(b,a,acc1aj);
afacc2j=filtfilt(b,a,acc2aj);
afacc3j=filtfilt(b,a,acc3aj);
afacc4j=filtfilt(b,a,acc4aj);
afacc5j=filtfilt(b,a,acc5aj);
afacc6j=filtfilt(b,a,acc6aj);
afacc7j=filtfilt(b,a,acc7aj);

gfacc1j=filtfilt(b,a,acc1gj);
gfacc2j=filtfilt(b,a,acc2gj);
gfacc3j=filtfilt(b,a,acc3gj);
gfacc4j=filtfilt(b,a,acc4gj);
gfacc5j=filtfilt(b,a,acc5gj);
gfacc6j=filtfilt(b,a,acc6gj);
gfacc7j=filtfilt(b,a,acc7gj);

%% eliminiación de tendencias

t=timeN;
orden=9;

afacc1j=detrend(afacc1j,orden);
afacc2j=detrend(afacc2j,orden);
afacc3j=detrend(afacc3j,orden);
afacc4j=detrend(afacc4j,orden);
afacc5j=detrend(afacc5j,orden);
afacc6j=detrend(afacc6j,orden);
afacc7j=detrend(afacc7j,orden);

gfacc1j=detrend(gfacc1j,orden);
gfacc2j=detrend(gfacc2j,orden);
gfacc3j=detrend(gfacc3j,orden);
gfacc4j=detrend(gfacc4j,orden);
gfacc5j=detrend(gfacc5j,orden);
gfacc6j=detrend(gfacc6j,orden);
gfacc7j=detrend(gfacc7j,orden);


ahipj=afacc4j; %cadera
althighj=afacc3j; %muslo izquierdo
arthighj=afacc6j; % muslo derecho
allegj=afacc5j; %pierna izquierda
arlegj=afacc1j; %pierna derecha
alftj=afacc2j; %pie izquierdo
arftj=afacc7j; %pie derecho

ghipj=gfacc4j; %cadera
glthighj=gfacc3j; %muslo izquierdo
grthighj=gfacc6j; % muslo derecho
gllegj=gfacc5j; %pierna izquierda
grlegj=gfacc1j; %pierna derecha
glftj=gfacc2j; %pie izquierdo
grftj=gfacc7j; %pie derecho

%% 
[ssj,rss]=size(timeNj);

%% LINEAL
accx = ahipj(:,1) ;   
accy = ahipj(:,2) ;   
accz = ahipj(:,3) ;  
rahipj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = althighj(:,1) ;   
accy = althighj(:,2) ;   
accz = althighj(:,3) ;  
ralthighj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = arthighj(:,1) ;   
accy = arthighj(:,2) ;   
accz = arthighj(:,3) ;  
rarthighj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = allegj(:,1) ;   
accy = allegj(:,2) ;   
accz = allegj(:,3) ;  
rallegj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = arlegj(:,1) ;   
accy = arlegj(:,2) ;   
accz = arlegj(:,3) ;  
rarlegj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = alftj(:,1) ;   
accy = alftj(:,2) ;   
accz = alftj(:,3) ;  
ralftj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = arftj(:,1) ;   
accy = arftj(:,2) ;   
accz = arftj(:,3) ;  
rarftj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

%% ANGULAR
accx = ghipj(:,1) ;   
accy = ghipj(:,2) ;   
accz = ghipj(:,3) ;  
rghipj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = glthighj(:,1) ;   
accy = glthighj(:,2) ;   
accz = glthighj(:,3) ;  
rglthighj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = grthighj(:,1) ;   
accy = grthighj(:,2) ;   
accz = grthighj(:,3) ;  
rgrthighj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = gllegj(:,1) ;   
accy = gllegj(:,2) ;   
accz = gllegj(:,3) ;  
rgllegj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = grlegj(:,1) ;   
accy = grlegj(:,2) ;   
accz = grlegj(:,3) ;  
rgrlegj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = glftj(:,1) ;   
accy = glftj(:,2) ;   
accz = glftj(:,3) ;  
rglftj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = grftj(:,1) ;   
accy = grftj(:,2) ;   
accz = grftj(:,3) ;  
rgrftj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz


catch
    warning('Error en JERK XSENS');
    a = 0;
    uiwait(msgbox('Error en JERK XSENS','ERROR','modal'));
    end  


try
%%Carga de datos
%aceleraciones

% double jerk
acc1ajj=diff(diff(acc1a));
acc1gjj=diff(diff(acc1g));

acc2ajj=diff(diff(acc2a));
acc2gjj=diff(diff(acc2g));

acc3ajj=diff(diff(acc3a));
acc3gjj=diff(diff(acc3g));

acc4ajj=diff(diff(acc4a));
acc4gjj=diff(diff(acc4g));

acc5ajj=diff(diff(acc5a));
acc5gjj=diff(diff(acc5g));

acc6ajj=diff(diff(acc6a));
acc6gjj=diff(diff(acc6g));

acc7ajj=diff(diff(acc7a));
acc7gjj=diff(diff(acc7g));

%% Calculo de frecuencia de muestreo

[size_datasetaccX,size_datasetaccX2]=size(datasetaccX);
timet=datasetaccX(:,size_datasetaccX2);
time=table2array(timet);
xa=ischange(time);
e=find(xa==1);
fqmV=diff(e);
fqm=mean(fqmV);

TmV=1./fqmV;
%Tm1=1/fqm1;
Tm=1/fqm;
timeN=time-(time(1,1));
%for i=1:e(1)-1
 %   timeN(i)=timeN(i)+(i-1)*Tm1;
%end
for i=1:prod(size(e))-1
    for k=e(i):e(i+1)-1
        timeN(k)=timeN(k)+(k-e(i))*Tm;
    end
end
for i=e(end):prod(size(timeN))
    timeN(i)=timeN(i)+(i-e(end))*TmV(end);
end
%% tiempos

timestamp=table2array(datasetaccX(:,43));
[sts,ssts]=size(timestamp);

timestamp(:,2)=[1:sts];
timestamp(:,3)=timestamp(:,2)/fqm;

global timeNjj
timeNjj=timestamp(3:end,3);

%% Filtros 
h=fdesign.lowpass('N,F3db',12,0.12566370614);
d1=design(h,'butter');
%butterworth

fc=5;
wn=fc/(fqm/2);
ord=4;
[b,a]=butter(ord,wn);

afacc1jj=filtfilt(b,a,acc1ajj);
afacc2jj=filtfilt(b,a,acc2ajj);
afacc3jj=filtfilt(b,a,acc3ajj);
afacc4jj=filtfilt(b,a,acc4ajj);
afacc5jj=filtfilt(b,a,acc5ajj);
afacc6jj=filtfilt(b,a,acc6ajj);
afacc7jj=filtfilt(b,a,acc7ajj);

gfacc1jj=filtfilt(b,a,acc1gjj);
gfacc2jj=filtfilt(b,a,acc2gjj);
gfacc3jj=filtfilt(b,a,acc3gjj);
gfacc4jj=filtfilt(b,a,acc4gjj);
gfacc5jj=filtfilt(b,a,acc5gjj);
gfacc6jj=filtfilt(b,a,acc6gjj);
gfacc7jj=filtfilt(b,a,acc7gjj);

%% eliminiación de tendencias

t=timeN;
orden=9;

afacc1jj=detrend(afacc1jj,orden);
afacc2jj=detrend(afacc2jj,orden);
afacc3jj=detrend(afacc3jj,orden);
afacc4jj=detrend(afacc4jj,orden);
afacc5jj=detrend(afacc5jj,orden);
afacc6jj=detrend(afacc6jj,orden);
afacc7jj=detrend(afacc7jj,orden);

gfacc1jj=detrend(gfacc1jj,orden);
gfacc2jj=detrend(gfacc2jj,orden);
gfacc3jj=detrend(gfacc3jj,orden);
gfacc4jj=detrend(gfacc4jj,orden);
gfacc5jj=detrend(gfacc5jj,orden);
gfacc6jj=detrend(gfacc6jj,orden);
gfacc7jj=detrend(gfacc7jj,orden);

ahipjj=afacc4jj; %cadera
althighjj=afacc3jj; %muslo izquierdo
arthighjj=afacc6jj; % muslo derecho
allegjj=afacc5jj; %pierna izquierda
arlegjj=afacc1jj; %pierna derecha
alftjj=afacc2jj; %pie izquierdo
arftjj=afacc7jj; %pie derecho

ghipjj=gfacc4jj; %cadera
glthighjj=gfacc3jj; %muslo izquierdo
grthighjj=gfacc6jj; % muslo derecho
gllegjj=gfacc5jj; %pierna izquierda
grlegjj=gfacc1jj; %pierna derecha
glftjj=gfacc2jj; %pie izquierdo
grftjj=gfacc7jj; %pie derecho


%% 
[ss,rss]=size(timeN);       
 
accx = ahipjj(:,1) ;   
accy = ahipjj(:,2) ;   
accz = ahipjj(:,3) ;  
rahipjj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = althighjj(:,1) ;   
accy = althighjj(:,2) ;   
accz = althighjj(:,3) ;  
ralthighjj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = arthighjj(:,1) ;   
accy = arthighjj(:,2) ;   
accz = arthighjj(:,3) ;  
rarthighjj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = allegjj(:,1) ;   
accy = allegjj(:,2) ;   
accz = allegjj(:,3) ;  
rallegjj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = arlegjj(:,1) ;   
accy = arlegjj(:,2) ;   
accz = arlegjj(:,3) ;  
rarlegjj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = alftjj(:,1) ;   
accy = alftjj(:,2) ;   
accz = alftjj(:,3) ;  
ralftjj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = arftjj(:,1) ;   
accy = arftjj(:,2) ;   
accz = arftjj(:,3) ;  
rarftjj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

%% ANGULAR
accx = ghipjj(:,1) ;   
accy = ghipjj(:,2) ;   
accz = ghipjj(:,3) ;  
rghipjj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = glthighjj(:,1) ;   
accy = glthighjj(:,2) ;   
accz = glthighjj(:,3) ;  
rglthighjj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = grthighjj(:,1) ;   
accy = grthighjj(:,2) ;   
accz = grthighjj(:,3) ;  
rgrthighjj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = gllegjj(:,1) ;   
accy = gllegjj(:,2) ;   
accz = gllegjj(:,3) ;  
rgllegjj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = grlegjj(:,1) ;   
accy = grlegjj(:,2) ;   
accz = grlegjj(:,3) ;  
rgrlegjj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = glftjj(:,1) ;   
accy = glftjj(:,2) ;   
accz = glftjj(:,3) ;  
rglftjj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

accx = grftjj(:,1) ;   
accy = grftjj(:,2) ;   
accz = grftjj(:,3) ;  
rgrftjj = sqrt(accx.^2+accy.^2+accz.^2) ;
clear accx
clear accy
clear accz

app.XSENSLamp.Color=[0 1 0]
global checkxsens    
checkxsens=1;
app.Lamp_6.Color=[0 1 0]
  
catch
    warning('Error en DOUBLE JERK XSENS');
    a = 0;
    uiwait(msgbox('Error en DOUBLE JERK XSENS','ERROR','modal'));
    end  

%%________________________________________________________________________________________________________________________________________
%%________________________________________________________________________________________________________________________________________
%%________________________________________________________________________________________________________________________________________
global oname
cd(oname(1).folder)           
global timeaa
global fV
global rfoota
global lfoota
global cutI
global cutR
global cutI2
global cutR2
global cutI2o
global cutR2o
global cutIo
global cutRo      

        end

        % Button pushed function: RESETALLButton_2
        function RESETALLButton_2Pushed(app, event)
            app.Lamp_4.Color='r';
            app.Lamp_5.Color='r';
            app.Lamp_6.Color='r';
            
            app.VICONLamp.Color='r';
            app.XSENSLamp.Color='r';
            
            app.DIFFERENCEINSECONDS.Value="0";
            app.PERCENTAGEERRORLEFTFOOTEditField.Value='';
            app.PERCENTAGEERRORRIGTHFOOTEditField.Value='';
            
            cla(app.HOME1)
            cla(app.HOME2)
            cla(app.HOME3)
            cla(app.HOME4)
            cla(app.HOME5)
            cla(app.HOME6)
            
            cla(app.BUGSLEFT)
            cla(app.BUGSRIGHT)
            cla(app.BUGST)
            cla(app.BUGSH)
            
            app.TA.Data = {};
            cla(app.PAR1)
            cla(app.PAR2)
            cla(app.PAR3)
            cla(app.PAR4)
            cla(app.PAR5)
            cla(app.PAR6)
            
            cla(app.ACC1)
            cla(app.ACC2)
            cla(app.ACC3)
            cla(app.ACC4)
            cla(app.ACC5)
            cla(app.ACC6)
            
            cla(app.ACC1a)
            cla(app.ACC2a)
            cla(app.ACC3a)
            cla(app.ACC4a)
            cla(app.ACC5a)
            cla(app.ACC6a)
            
            cla(app.JERK1)
            cla(app.JERK2)
            cla(app.JERK3)
            cla(app.JERK4)
            cla(app.JERK5)
            cla(app.JERK6)
            
            cla(app.JERK1a)
            cla(app.JERK2a)
            cla(app.JERK3a)
            cla(app.JERK4a)
            cla(app.JERK5a)
            cla(app.JERK6a)
            
            cla(app.DJ1)
            cla(app.DJ2)
            cla(app.DJ3)
            cla(app.DJ4)
            cla(app.DJ5)
            cla(app.DJ6)
            
            cla(app.DJ1a)
            cla(app.DJ2a)
            cla(app.DJ3a)
            cla(app.DJ4a)
            cla(app.DJ5a)
            cla(app.DJ6a)
            
            cla(app.PXS1)
            cla(app.PXS2)
            cla(app.PXS3)
            cla(app.PXS4)
            cla(app.PXS5)
            cla(app.PXS6)
            cla(app.PXS7)
            
            cla(app.VS1)
            cla(app.VS2)
            cla(app.VS3)
            cla(app.VS4)
            
            cla(app.UIAV)
            cla(app.UILA)
            
            cla(app.ResultRigth)
            cla(app.ResultLeft)
            
            clear all global
            clear all
            clear
            clc
        end

        % Button pushed function: COMPAREButton
        function COMPAREButtonPushed(app, event)
            global rarft
            global ralft
            global timeNN
            global timeaa
            global desf
            global cutRa
            global cutIa
            global cutRoa
            global cutIoa
            global cutRX
            global cutIX
            global cutRXo
            global cutIXo
           %% Cálculo de error
           while cutRoa(1)>cutRa(1)
        cutRa=cutRa(2:end);
    end
    while length(cutRoa)>length(cutRa)
        cutRoa(end)=[];
    end
    while length(cutRa)>length(cutRoa)
        cutRa(end)=[];
    end
    for i=2:length(cutRoa)-1
        if cutRoa(i)<cutRa(i-1)
            cutRoa(i-1)=[];
        end
    end
    while length(cutRoa)>length(cutRa)
        cutRoa(end)=[];
    end
    while length(cutRa)>length(cutRoa)
        cutRa(end)=[];
    end
    while cutIoa(1)>cutIa(1)
        cutIa=cutIa(2:end);
    end
    while length(cutIoa)>length(cutIa)
        cutIoa(end)=[];
    end
    while length(cutIa)>length(cutIoa)
        cutIa(end)=[];
    end
    for i=2:length(cutIoa)-1
        if cutIoa(i)<cutIa(i-1)
            cutIoa(i-1)=[];
        end
    end
    while length(cutIoa)>length(cutIa)
        cutIoa(end)=[];
    end
    while length(cutIa)>length(cutIoa)
        cutIa(end)=[];
    end
    
 for i=1:length(cutRa)
    [M dR(i)]=min(abs(timeNN(cutRX)-(timeaa(cutRa(i))+desf)));
end
cutRX2=cutRX(dR);
for i=1:length(cutRoa)
    [M dRo(i)]=min(abs(timeNN(cutRXo)-(timeaa(cutRoa(i))+desf)));
end
cutRXo2=cutRXo(dRo);
for i=1:length(cutIa)
    [M dI(i)]=min(abs(timeNN(cutIX)-(timeaa(cutIa(i))+desf)));
end
cutIX2=cutIX(dI);
for i=1:length(cutIoa)
    [M dIo(i)]=min(abs(timeNN(cutIXo)-(timeaa(cutIoa(i))+desf)));
end
cutIXo2=cutIXo(dIo);   


            xtimeNN=sort(cat(1,cutRXo2,cutRX2));
            xtimeaa=sort(cat(1,cutRoa,cutRa));
            xt=(timeaa(xtimeaa)+desf+timeNN(xtimeNN)')/2;
            ErR=mean(abs(timeaa(xtimeaa)+desf-timeNN(xtimeNN)')*100./(timeaa(xtimeaa)+desf));
            app.PERCENTAGEERRORRIGTHFOOTEditField.Value=num2str(ErR)+"%";
            for i=1:length(xt)
                b=mod(i,2);
                if b==0
                    c{i}='FS';
                else
                    c{i}='TO';
                end
            end
            plot(app.ResultRigth,timeNN,rarft)
            for i=1:length(cutRX2)
                xline(app.ResultRigth,timeNN(cutRX2(i)),'b')
                xline(app.ResultRigth,timeNN(cutRXo2(i)),'r')
            end
            for i=1:length(cutRa)
                xline(app.ResultRigth,timeaa(cutRa(i))+desf,'b--')
            end
            for i=1:length(cutRoa)
                xline(app.ResultRigth,timeaa(cutRoa(i))+desf,'r--')
            end
            xlim(app.ResultRigth,[timeaa(1)+desf timeaa(end)+desf])
            xticks(app.ResultRigth,xt)
            xticklabels(app.ResultRigth,c)
            xtimeNN=sort(cat(1,cutIXo2,cutIX2));
            xtimeaa=sort(cat(1,cutIoa,cutIa));
            xt=(timeaa(xtimeaa)+desf+timeNN(xtimeNN)')/2;
            ErL=mean(abs(timeaa(xtimeaa)+desf-timeNN(xtimeNN)')*100./(timeaa(xtimeaa)+desf));
            for i=1:length(xt)
                b=mod(i,2);
                if b==0
                    c{i}='FS';
                else
                    c{i}='TO';
                end
            end
            plot(app.ResultLeft,timeNN,ralft)
            for i=1:length(cutIX2)
                xline(app.ResultLeft,timeNN(cutIX2(i)),'b')
                xline(app.ResultLeft,timeNN(cutIXo2(i)),'r')
            end
            for i=1:length(cutIa)
                xline(app.ResultLeft,timeaa(cutIa(i))+desf,'b--')
            end
            for i=1:length(cutIoa)
                xline(app.ResultLeft,timeaa(cutIoa(i))+desf,'r--')
            end
            xlim(app.ResultLeft,[timeaa(1)+desf timeaa(end)+desf])
            xticks(app.ResultLeft,xt)
            xticklabels(app.ResultLeft,c)
            
            app.PERCENTAGEERRORLEFTFOOTEditField.Value=num2str(ErL)+"%";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LOADFILESTab_2 = uitab(app.TabGroup);
            app.LOADFILESTab_2.Title = 'LOAD FILES';
            app.GridLayout32_2 = uigridlayout(app.LOADFILESTab_2);
            app.LOADFILESButton = uibutton(app.GridLayout32_2, 'state');
            app.LOADFILESButton.ValueChangedFcn = createCallbackFcn(app, @LOADFILESButtonValueChanged, true);
            app.LOADFILESButton.Text = 'LOAD FILES ';
            app.Lamp_4 = uilamp(app.GridLayout32_2);
            app.Lamp_5 = uilamp(app.GridLayout32_2);
            app.Lamp_6 = uilamp(app.GridLayout32_2);
            app.RESETALLButton_2 = uibutton(app.GridLayout32_2, 'push');
            app.RESETALLButton_2.ButtonPushedFcn = createCallbackFcn(app, @RESETALLButton_2Pushed, true);
            app.RESETALLButton_2.Text = 'RESET ALL';
            app.VICONTab = uitab(app.TabGroup);
            app.VICONTab.Title = 'VICON';
            app.GridLayout = uigridlayout(app.VICONTab);
            app.MATLABALGORITHMLabel = uilabel(app.GridLayout);
            app.MATLABALGORITHMLabel.Text = 'MATLAB ALGORITHM';
            app.LEGEND1 = uilabel(app.GridLayout);
            app.LEGEND1.Text = '';
            app.VICONNEXUSLabel = uilabel(app.GridLayout);
            app.VICONNEXUSLabel.Text = 'VICON NEXUS';
            app.HOME2 = uiaxes(app.GridLayout);
            title(app.HOME2, '')
            xlabel(app.HOME2, '')
            ylabel(app.HOME2, '')
            app.HOME2.XTick = [];
            app.HOME2.YTick = [];
            app.HOME3 = uiaxes(app.GridLayout);
            title(app.HOME3, '')
            xlabel(app.HOME3, '')
            ylabel(app.HOME3, '')
            app.HOME3.XTick = [];
            app.HOME3.YTick = [];
            app.HOME4 = uiaxes(app.GridLayout);
            title(app.HOME4, '')
            xlabel(app.HOME4, '')
            ylabel(app.HOME4, '')
            app.HOME4.XTick = [];
            app.HOME4.YTick = [];
            app.HOME5 = uiaxes(app.GridLayout);
            title(app.HOME5, '')
            xlabel(app.HOME5, '')
            ylabel(app.HOME5, '')
            app.HOME5.XTick = [];
            app.HOME5.YTick = [];
            app.HOME6 = uiaxes(app.GridLayout);
            title(app.HOME6, '')
            xlabel(app.HOME6, '')
            ylabel(app.HOME6, '')
            app.HOME6.XTick = [];
            app.HOME6.YTick = [];
            app.HOME1 = uiaxes(app.GridLayout);
            title(app.HOME1, '')
            xlabel(app.HOME1, '')
            ylabel(app.HOME1, '')
            app.HOME1.XTick = [];
            app.HOME1.YTick = [];
            app.BUGSTab = uitab(app.TabGroup);
            app.BUGSTab.Title = 'BUGS';
            app.GridLayout42 = uigridlayout(app.BUGSTab);
            app.GridLayout3 = uigridlayout(app.GridLayout42);
            app.BUGSLEFT = uiaxes(app.GridLayout3);
            title(app.BUGSLEFT, '')
            xlabel(app.BUGSLEFT, '')
            ylabel(app.BUGSLEFT, '')
            app.BUGSLEFT.XTick = [];
            app.BUGSLEFT.YTick = [];
            app.BUGSRIGHT = uiaxes(app.GridLayout3);
            title(app.BUGSRIGHT, '')
            xlabel(app.BUGSRIGHT, '')
            ylabel(app.BUGSRIGHT, '')
            app.BUGSRIGHT.XTick = [];
            app.BUGSRIGHT.YTick = [];
            app.BUGST = uiaxes(app.GridLayout3);
            title(app.BUGST, '')
            xlabel(app.BUGST, '')
            ylabel(app.BUGST, '')
            app.BUGST.XTick = [];
            app.BUGST.YTick = [];
            app.BUGSH = uiaxes(app.GridLayout3);
            title(app.BUGSH, '')
            xlabel(app.BUGSH, '')
            ylabel(app.BUGSH, '')
            app.BUGSH.XTick = [];
            app.BUGSH.YTick = [];
            app.PARAMETERSTab = uitab(app.TabGroup);
            app.PARAMETERSTab.Title = 'PARAMETERS';
            app.PARAM = uigridlayout(app.PARAMETERSTab);
            app.TA = uitable(app.PARAM);
            app.TA.ColumnName = {'BILLATERAL PARAMETERS'; 'LEFT SIDE'; 'RIGHT SIDE'};
            app.TA.RowName = {};
            app.TA.DisplayDataChangedFcn = createCallbackFcn(app, @TADisplayDataChanged, true);
            app.PAR1 = uiaxes(app.PARAM);
            title(app.PAR1, '')
            xlabel(app.PAR1, '')
            ylabel(app.PAR1, '')
            app.PAR1.XTick = [];
            app.PAR1.YTick = [];
            app.PAR2 = uiaxes(app.PARAM);
            title(app.PAR2, '')
            xlabel(app.PAR2, '')
            ylabel(app.PAR2, '')
            app.PAR2.XTick = [];
            app.PAR2.YTick = [];
            app.PAR3 = uiaxes(app.PARAM);
            title(app.PAR3, '')
            xlabel(app.PAR3, '')
            ylabel(app.PAR3, '')
            app.PAR3.XTick = [];
            app.PAR3.YTick = [];
            app.PAR4 = uiaxes(app.PARAM);
            title(app.PAR4, '')
            xlabel(app.PAR4, '')
            ylabel(app.PAR4, '')
            app.PAR4.XTick = [];
            app.PAR4.YTick = [];
            app.PAR5 = uiaxes(app.PARAM);
            title(app.PAR5, '')
            xlabel(app.PAR5, '')
            ylabel(app.PAR5, '')
            app.PAR5.XTick = [];
            app.PAR5.YTick = [];
            app.PAR6 = uiaxes(app.PARAM);
            title(app.PAR6, '')
            xlabel(app.PAR6, '')
            ylabel(app.PAR6, '')
            app.PAR6.XTick = [];
            app.PAR6.YTick = [];
            app.ACCVICONTab = uitab(app.TabGroup);
            app.ACCVICONTab.Title = 'ACC VICON';
            app.GridLayout2 = uigridlayout(app.ACCVICONTab);
            app.TabGroup2 = uitabgroup(app.GridLayout2);
            app.ACCELERATIONTab = uitab(app.TabGroup2);
            app.ACCELERATIONTab.Title = 'ACCELERATION';
            app.GridLayout18 = uigridlayout(app.ACCELERATIONTab);
            app.TabGroup5 = uitabgroup(app.GridLayout18);
            app.LINEALACCELERATIONTab = uitab(app.TabGroup5);
            app.LINEALACCELERATIONTab.Title = 'LINEAL ACCELERATION';
            app.GridLayout20 = uigridlayout(app.LINEALACCELERATIONTab);
            app.ACC1 = uiaxes(app.GridLayout20);
            title(app.ACC1, '')
            xlabel(app.ACC1, '')
            ylabel(app.ACC1, '')
            app.ACC1.XTick = [];
            app.ACC1.YTick = [];
            app.ACC2 = uiaxes(app.GridLayout20);
            title(app.ACC2, '')
            xlabel(app.ACC2, '')
            ylabel(app.ACC2, '')
            app.ACC2.XTick = [];
            app.ACC2.YTick = [];
            app.ACC3 = uiaxes(app.GridLayout20);
            title(app.ACC3, '')
            xlabel(app.ACC3, '')
            ylabel(app.ACC3, '')
            app.ACC3.XTick = [];
            app.ACC3.YTick = [];
            app.ACC4 = uiaxes(app.GridLayout20);
            title(app.ACC4, '')
            xlabel(app.ACC4, '')
            ylabel(app.ACC4, '')
            app.ACC4.XTick = [];
            app.ACC4.YTick = [];
            app.ACC5 = uiaxes(app.GridLayout20);
            title(app.ACC5, '')
            xlabel(app.ACC5, '')
            ylabel(app.ACC5, '')
            app.ACC5.XTick = [];
            app.ACC5.YTick = [];
            app.ACC6 = uiaxes(app.GridLayout20);
            title(app.ACC6, '')
            xlabel(app.ACC6, '')
            ylabel(app.ACC6, '')
            app.ACC6.XTick = [];
            app.ACC6.YTick = [];
            app.ANGULARACCELERATIONTab = uitab(app.TabGroup5);
            app.ANGULARACCELERATIONTab.Title = 'ANGULAR ACCELERATION';
            app.GridLayout19 = uigridlayout(app.ANGULARACCELERATIONTab);
            app.ACC1a = uiaxes(app.GridLayout19);
            title(app.ACC1a, '')
            xlabel(app.ACC1a, '')
            ylabel(app.ACC1a, '')
            app.ACC1a.XTick = [];
            app.ACC1a.YTick = [];
            app.ACC2a = uiaxes(app.GridLayout19);
            title(app.ACC2a, '')
            xlabel(app.ACC2a, '')
            ylabel(app.ACC2a, '')
            app.ACC2a.XTick = [];
            app.ACC2a.YTick = [];
            app.ACC3a = uiaxes(app.GridLayout19);
            title(app.ACC3a, '')
            xlabel(app.ACC3a, '')
            ylabel(app.ACC3a, '')
            app.ACC3a.XTick = [];
            app.ACC3a.YTick = [];
            app.ACC4a = uiaxes(app.GridLayout19);
            title(app.ACC4a, '')
            xlabel(app.ACC4a, '')
            ylabel(app.ACC4a, '')
            app.ACC4a.XTick = [];
            app.ACC4a.YTick = [];
            app.ACC5a = uiaxes(app.GridLayout19);
            title(app.ACC5a, '')
            xlabel(app.ACC5a, '')
            ylabel(app.ACC5a, '')
            app.ACC5a.XTick = [];
            app.ACC5a.YTick = [];
            app.ACC6a = uiaxes(app.GridLayout19);
            title(app.ACC6a, '')
            xlabel(app.ACC6a, '')
            ylabel(app.ACC6a, '')
            app.ACC6a.XTick = [];
            app.ACC6a.YTick = [];
            app.JERKTab_3 = uitab(app.TabGroup2);
            app.JERKTab_3.Title = 'JERK';
            app.GridLayout22 = uigridlayout(app.JERKTab_3);
            app.TabGroup5_2 = uitabgroup(app.GridLayout22);
            app.LINEALACCELERATIONTab_2 = uitab(app.TabGroup5_2);
            app.LINEALACCELERATIONTab_2.Title = 'LINEAL ACCELERATION';
            app.GridLayout20_2 = uigridlayout(app.LINEALACCELERATIONTab_2);
            app.JERK1 = uiaxes(app.GridLayout20_2);
            title(app.JERK1, '')
            xlabel(app.JERK1, '')
            ylabel(app.JERK1, '')
            app.JERK1.XTick = [];
            app.JERK1.YTick = [];
            app.JERK2 = uiaxes(app.GridLayout20_2);
            title(app.JERK2, '')
            xlabel(app.JERK2, '')
            ylabel(app.JERK2, '')
            app.JERK2.XTick = [];
            app.JERK2.YTick = [];
            app.JERK3 = uiaxes(app.GridLayout20_2);
            title(app.JERK3, '')
            xlabel(app.JERK3, '')
            ylabel(app.JERK3, '')
            app.JERK3.XTick = [];
            app.JERK3.YTick = [];
            app.JERK4 = uiaxes(app.GridLayout20_2);
            title(app.JERK4, '')
            xlabel(app.JERK4, '')
            ylabel(app.JERK4, '')
            app.JERK4.XTick = [];
            app.JERK4.YTick = [];
            app.JERK5 = uiaxes(app.GridLayout20_2);
            title(app.JERK5, '')
            xlabel(app.JERK5, '')
            ylabel(app.JERK5, '')
            app.JERK5.XTick = [];
            app.JERK5.YTick = [];
            app.JERK6 = uiaxes(app.GridLayout20_2);
            title(app.JERK6, '')
            xlabel(app.JERK6, '')
            ylabel(app.JERK6, '')
            app.JERK6.XTick = [];
            app.JERK6.YTick = [];
            app.ANGULARACCELERATIONTab_2 = uitab(app.TabGroup5_2);
            app.ANGULARACCELERATIONTab_2.Title = 'ANGULAR ACCELERATION';
            app.GridLayout19_2 = uigridlayout(app.ANGULARACCELERATIONTab_2);
            app.JERK1a = uiaxes(app.GridLayout19_2);
            title(app.JERK1a, '')
            xlabel(app.JERK1a, '')
            ylabel(app.JERK1a, '')
            app.JERK1a.XTick = [];
            app.JERK1a.YTick = [];
            app.JERK2a = uiaxes(app.GridLayout19_2);
            title(app.JERK2a, '')
            xlabel(app.JERK2a, '')
            ylabel(app.JERK2a, '')
            app.JERK2a.XTick = [];
            app.JERK2a.YTick = [];
            app.JERK3a = uiaxes(app.GridLayout19_2);
            title(app.JERK3a, '')
            xlabel(app.JERK3a, '')
            ylabel(app.JERK3a, '')
            app.JERK3a.XTick = [];
            app.JERK3a.YTick = [];
            app.JERK4a = uiaxes(app.GridLayout19_2);
            title(app.JERK4a, '')
            xlabel(app.JERK4a, '')
            ylabel(app.JERK4a, '')
            app.JERK4a.XTick = [];
            app.JERK4a.YTick = [];
            app.JERK5a = uiaxes(app.GridLayout19_2);
            title(app.JERK5a, '')
            xlabel(app.JERK5a, '')
            ylabel(app.JERK5a, '')
            app.JERK5a.XTick = [];
            app.JERK5a.YTick = [];
            app.JERK6a = uiaxes(app.GridLayout19_2);
            title(app.JERK6a, '')
            xlabel(app.JERK6a, '')
            ylabel(app.JERK6a, '')
            app.JERK6a.XTick = [];
            app.JERK6a.YTick = [];
            app.DOUBLEJERKTab_3 = uitab(app.TabGroup2);
            app.DOUBLEJERKTab_3.Title = 'DOUBLE JERK';
            app.GridLayout21 = uigridlayout(app.DOUBLEJERKTab_3);
            app.TabGroup5_3 = uitabgroup(app.GridLayout21);
            app.LINEALACCELERATIONTab_3 = uitab(app.TabGroup5_3);
            app.LINEALACCELERATIONTab_3.Title = 'LINEAL ACCELERATION';
            app.GridLayout20_3 = uigridlayout(app.LINEALACCELERATIONTab_3);
            app.DJ1 = uiaxes(app.GridLayout20_3);
            title(app.DJ1, '')
            xlabel(app.DJ1, '')
            ylabel(app.DJ1, '')
            app.DJ1.XTick = [];
            app.DJ1.YTick = [];
            app.DJ2 = uiaxes(app.GridLayout20_3);
            title(app.DJ2, '')
            xlabel(app.DJ2, '')
            ylabel(app.DJ2, '')
            app.DJ2.XTick = [];
            app.DJ2.YTick = [];
            app.DJ3 = uiaxes(app.GridLayout20_3);
            title(app.DJ3, '')
            xlabel(app.DJ3, '')
            ylabel(app.DJ3, '')
            app.DJ3.XTick = [];
            app.DJ3.YTick = [];
            app.DJ4 = uiaxes(app.GridLayout20_3);
            title(app.DJ4, '')
            xlabel(app.DJ4, '')
            ylabel(app.DJ4, '')
            app.DJ4.XTick = [];
            app.DJ4.YTick = [];
            app.DJ5 = uiaxes(app.GridLayout20_3);
            title(app.DJ5, '')
            xlabel(app.DJ5, '')
            ylabel(app.DJ5, '')
            app.DJ5.XTick = [];
            app.DJ5.YTick = [];
            app.DJ6 = uiaxes(app.GridLayout20_3);
            title(app.DJ6, '')
            xlabel(app.DJ6, '')
            ylabel(app.DJ6, '')
            app.DJ6.XTick = [];
            app.DJ6.YTick = [];
            app.ANGULARACCELERATIONTab_3 = uitab(app.TabGroup5_3);
            app.ANGULARACCELERATIONTab_3.Title = 'ANGULAR ACCELERATION';
            app.GridLayout19_3 = uigridlayout(app.ANGULARACCELERATIONTab_3);
            app.DJ1a = uiaxes(app.GridLayout19_3);
            title(app.DJ1a, '')
            xlabel(app.DJ1a, '')
            ylabel(app.DJ1a, '')
            app.DJ1a.XTick = [];
            app.DJ1a.YTick = [];
            app.DJ2a = uiaxes(app.GridLayout19_3);
            title(app.DJ2a, '')
            xlabel(app.DJ2a, '')
            ylabel(app.DJ2a, '')
            app.DJ2a.XTick = [];
            app.DJ2a.YTick = [];
            app.DJ3a = uiaxes(app.GridLayout19_3);
            title(app.DJ3a, '')
            xlabel(app.DJ3a, '')
            ylabel(app.DJ3a, '')
            app.DJ3a.XTick = [];
            app.DJ3a.YTick = [];
            app.DJ5a = uiaxes(app.GridLayout19_3);
            title(app.DJ5a, '')
            xlabel(app.DJ5a, '')
            ylabel(app.DJ5a, '')
            app.DJ5a.XTick = [];
            app.DJ5a.YTick = [];
            app.DJ4a = uiaxes(app.GridLayout19_3);
            title(app.DJ4a, '')
            xlabel(app.DJ4a, '')
            ylabel(app.DJ4a, '')
            app.DJ4a.XTick = [];
            app.DJ4a.YTick = [];
            app.DJ6a = uiaxes(app.GridLayout19_3);
            title(app.DJ6a, '')
            xlabel(app.DJ6a, '')
            ylabel(app.DJ6a, '')
            app.DJ6a.XTick = [];
            app.DJ6a.YTick = [];
            app.VICONSXSENSTab = uitab(app.TabGroup);
            app.VICONSXSENSTab.Title = 'VICONS - XSENS';
            app.GridLayout13 = uigridlayout(app.VICONSXSENSTab);
            app.GridLayout33 = uigridlayout(app.GridLayout13);
            app.GridLayout16 = uigridlayout(app.GridLayout33);
            app.VICONLampLabel = uilabel(app.GridLayout16);
            app.VICONLampLabel.Text = 'VICON';
            app.VICONLamp = uilamp(app.GridLayout16);
            app.XSENSLampLabel = uilabel(app.GridLayout16);
            app.XSENSLampLabel.Text = 'XSENS';
            app.XSENSLamp = uilamp(app.GridLayout16);
            app.GridLayout31 = uigridlayout(app.GridLayout33);
            app.GRAPHButton = uibutton(app.GridLayout31, 'push');
            app.GRAPHButton.ButtonPushedFcn = createCallbackFcn(app, @GRAPHButtonPushed, true);
            app.GRAPHButton.Text = 'GRAPH';
            app.DIFFERENCEINSECONDSEditFieldLabel = uilabel(app.GridLayout31);
            app.DIFFERENCEINSECONDSEditFieldLabel.Text = 'DIFFERENCE IN SECONDS';
            app.DIFFERENCEINSECONDS = uieditfield(app.GridLayout31, 'text');
            app.DIFFERENCEINSECONDS.Value = '0';
            app.COMPARETHETWOSIGNALSButton = uibutton(app.GridLayout31, 'push');
            app.COMPARETHETWOSIGNALSButton.ButtonPushedFcn = createCallbackFcn(app, @COMPARETHETWOSIGNALSButtonPushed, true);
            app.COMPARETHETWOSIGNALSButton.Text = 'COMPARE THE TWO SIGNALS';
            app.GridLayout40 = uigridlayout(app.GridLayout33);
            app.GridLayout41 = uigridlayout(app.GridLayout40);
            app.ACCELERATIONButtonGroup_2 = uibuttongroup(app.GridLayout41);
            app.ACCELERATIONButtonGroup_2.Title = 'ACCELERATION';
            app.LINEALB = uiradiobutton(app.ACCELERATIONButtonGroup_2);
            app.LINEALB.Text = 'LINEAL';
            app.LINEALB.Value = true;
            app.ANGULARB = uiradiobutton(app.ACCELERATIONButtonGroup_2);
            app.ANGULARB.Text = 'ANGULAR';
            app.AXISButtonGroup_2 = uibuttongroup(app.GridLayout41);
            app.AXISButtonGroup_2.Title = 'AXIS';
            app.RESULTANTB = uiradiobutton(app.AXISButtonGroup_2);
            app.RESULTANTB.Text = 'RESULTANT';
            app.RESULTANTB.Value = true;
            app.XB = uiradiobutton(app.AXISButtonGroup_2);
            app.XB.Text = 'X';
            app.YB = uiradiobutton(app.AXISButtonGroup_2);
            app.YB.Text = 'Y';
            app.ZB = uiradiobutton(app.AXISButtonGroup_2);
            app.ZB.Text = 'Z';
            app.VS1 = uiaxes(app.GridLayout40);
            title(app.VS1, '')
            xlabel(app.VS1, '')
            ylabel(app.VS1, '')
            app.VS1.XTick = [];
            app.VS1.YTick = [];
            app.VS2 = uiaxes(app.GridLayout40);
            title(app.VS2, '')
            xlabel(app.VS2, '')
            ylabel(app.VS2, '')
            app.VS2.XTick = [];
            app.VS2.YTick = [];
            app.VS3 = uiaxes(app.GridLayout40);
            title(app.VS3, '')
            xlabel(app.VS3, '')
            ylabel(app.VS3, '')
            app.VS3.XTick = [];
            app.VS3.YTick = [];
            app.VS4 = uiaxes(app.GridLayout40);
            title(app.VS4, '')
            xlabel(app.VS4, '')
            ylabel(app.VS4, '')
            app.VS4.XTick = [];
            app.VS4.YTick = [];
            app.XSENSTab = uitab(app.TabGroup);
            app.XSENSTab.Title = 'XSENS';
            app.GridLayout37 = uigridlayout(app.XSENSTab);
            app.GridLayout38 = uigridlayout(app.GridLayout37);
            app.TIMESliderLabel = uilabel(app.GridLayout38);
            app.TIMESliderLabel.Text = 'TIME';
            app.TIMESlider = uislider(app.GridLayout38);
            app.TIMESlider.Limits = [0 10];
            app.TIMESlider.ValueChangedFcn = createCallbackFcn(app, @TIMESliderValueChanged2, true);
            app.TIMESlider.ValueChangingFcn = createCallbackFcn(app, @TIMESliderValueChanged2, true);
            app.EditField = uieditfield(app.GridLayout38, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged2, true);
            app.EVENTSVICONButtonGroup = uibuttongroup(app.GridLayout38);
            app.EVENTSVICONButtonGroup.Title = 'EVENTS VICON';
            app.YESButton = uiradiobutton(app.EVENTSVICONButtonGroup);
            app.YESButton.Text = 'Y';
            app.NOButton = uiradiobutton(app.EVENTSVICONButtonGroup);
            app.NOButton.Text = 'N';
            app.NOButton.Value = true;
            app.GridLayout39 = uigridlayout(app.GridLayout37);
            app.PXS1 = uiaxes(app.GridLayout39);
            title(app.PXS1, '')
            xlabel(app.PXS1, '')
            ylabel(app.PXS1, '')
            app.PXS1.XTick = [];
            app.PXS1.YTick = [];
            app.PXS2 = uiaxes(app.GridLayout39);
            title(app.PXS2, '')
            xlabel(app.PXS2, '')
            ylabel(app.PXS2, '')
            app.PXS2.XTick = [];
            app.PXS2.YTick = [];
            app.PXS3 = uiaxes(app.GridLayout39);
            title(app.PXS3, '')
            xlabel(app.PXS3, '')
            ylabel(app.PXS3, '')
            app.PXS3.XTick = [];
            app.PXS3.YTick = [];
            app.PXS4 = uiaxes(app.GridLayout39);
            title(app.PXS4, '')
            xlabel(app.PXS4, '')
            ylabel(app.PXS4, '')
            app.PXS4.XTick = [];
            app.PXS4.YTick = [];
            app.PXS5 = uiaxes(app.GridLayout39);
            title(app.PXS5, '')
            xlabel(app.PXS5, '')
            ylabel(app.PXS5, '')
            app.PXS5.XTick = [];
            app.PXS5.YTick = [];
            app.PXS6 = uiaxes(app.GridLayout39);
            title(app.PXS6, '')
            xlabel(app.PXS6, '')
            ylabel(app.PXS6, '')
            app.PXS6.XTick = [];
            app.PXS6.YTick = [];
            app.PXS7 = uiaxes(app.GridLayout39);
            title(app.PXS7, '')
            xlabel(app.PXS7, '')
            ylabel(app.PXS7, '')
            app.PXS7.XTick = [];
            app.PXS7.YTick = [];
            app.ACCELERATIONButtonGroup = uibuttongroup(app.GridLayout39);
            app.ACCELERATIONButtonGroup.Title = 'ACCELERATION';
            app.LINEALButton = uiradiobutton(app.ACCELERATIONButtonGroup);
            app.LINEALButton.Text = 'LINEAL';
            app.LINEALButton.Value = true;
            app.ANGULARButton = uiradiobutton(app.ACCELERATIONButtonGroup);
            app.ANGULARButton.Text = 'ANGULAR';
            app.DEVButtonGroup = uibuttongroup(app.GridLayout39);
            app.DEVButtonGroup.Title = 'DEV';
            app.NORMALButton = uiradiobutton(app.DEVButtonGroup);
            app.NORMALButton.Text = 'NORMAL';
            app.NORMALButton.Value = true;
            app.JERKButton = uiradiobutton(app.DEVButtonGroup);
            app.JERKButton.Text = 'JERK';
            app.JERK2Button = uiradiobutton(app.DEVButtonGroup);
            app.JERK2Button.Text = '2 JERK';
            app.AXISButtonGroup = uibuttongroup(app.GridLayout39);
            app.AXISButtonGroup.Title = 'AXIS';
            app.XButton = uiradiobutton(app.AXISButtonGroup);
            app.XButton.Text = 'X';
            app.YButton = uiradiobutton(app.AXISButtonGroup);
            app.YButton.Text = 'Y';
            app.ZButton = uiradiobutton(app.AXISButtonGroup);
            app.ZButton.Text = 'Z';
            app.RESULTANTButton = uiradiobutton(app.AXISButtonGroup);
            app.RESULTANTButton.Text = 'RESULTANT';
            app.RESULTANTButton.Value = true;
            app.GRAPH = uibutton(app.GridLayout39, 'push');
            app.GRAPH.ButtonPushedFcn = createCallbackFcn(app, @GRAPHButtonPushed2, true);
            app.GRAPH.Text = 'GRAPH';
            app.EVENTSXSENSTab = uitab(app.TabGroup);
            app.EVENTSXSENSTab.Title = 'EVENTS XSENS';
            app.GridLayout44 = uigridlayout(app.EVENTSXSENSTab);
            app.UIAV = uiaxes(app.GridLayout44);
            title(app.UIAV, 'ANGULAR VELOCITY')
            xlabel(app.UIAV, 'Time')
            ylabel(app.UIAV, 'Velocity')
            app.UIAV.XTick = [];
            app.UIAV.YTick = [];
            app.GRAPHICButton = uibutton(app.GridLayout44, 'push');
            app.GRAPHICButton.ButtonPushedFcn = createCallbackFcn(app, @GRAPHICButtonPushed, true);
            app.GRAPHICButton.Text = 'GRAPHIC';
            app.UILA = uiaxes(app.GridLayout44);
            title(app.UILA, 'LINEAL ACCELERATION ')
            xlabel(app.UILA, 'Time')
            ylabel(app.UILA, 'Velocity')
            app.UILA.XTick = [];
            app.UILA.YTick = [];
            app.FOOTListBoxLabel = uilabel(app.GridLayout44);
            app.FOOTListBoxLabel.Text = 'FOOT';
            app.FOOTListBox = uilistbox(app.GridLayout44);
            app.FOOTListBox.Items = {'RIGTH', 'LEFT'};
            app.FOOTListBox.Value = 'RIGTH';
            app.COMPARTATIONTab = uitab(app.TabGroup);
            app.COMPARTATIONTab.Title = 'COMPARTATION';
            app.GridLayout45 = uigridlayout(app.COMPARTATIONTab);
            app.ResultRigth = uiaxes(app.GridLayout45);
            title(app.ResultRigth, 'RIGTH FOOT')
            xlabel(app.ResultRigth, 'TIME')
            ylabel(app.ResultRigth, 'ACCELERATION')
            app.ResultRigth.XTick = [];
            app.ResultRigth.YTick = [];
            app.COMPAREButton = uibutton(app.GridLayout45, 'push');
            app.COMPAREButton.ButtonPushedFcn = createCallbackFcn(app, @COMPAREButtonPushed, true);
            app.COMPAREButton.Text = 'COMPARE';
            app.ResultLeft = uiaxes(app.GridLayout45);
            title(app.ResultLeft, 'LEFT FOOT')
            xlabel(app.ResultLeft, 'TIME')
            ylabel(app.ResultLeft, 'ACCELERATION')
            app.ResultLeft.XTick = [];
            app.ResultLeft.YTick = [];
            app.PERCENTAGEERRORRIGTHFOOTEditFieldLabel = uilabel(app.GridLayout45);
            app.PERCENTAGEERRORRIGTHFOOTEditFieldLabel.Text = {'PERCENTAGE ERROR'; 'RIGTH FOOT'};
            app.PERCENTAGEERRORRIGTHFOOTEditField = uieditfield(app.GridLayout45, 'text');
            app.PERCENTAGEERRORLEFTFOOTEditFieldLabel = uilabel(app.GridLayout45);
            app.PERCENTAGEERRORLEFTFOOTEditFieldLabel.Text = {'PERCENTAGE ERROR'; 'LEFT FOOT'};
            app.PERCENTAGEERRORLEFTFOOTEditField = uieditfield(app.GridLayout45, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GAITANV3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end